classdef guiForPrime < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        guiForPrimeUIFigure             matlab.ui.Figure
        LoadMenu                        matlab.ui.container.Menu
        MovieMenu                       matlab.ui.container.Menu
        CascadeMenu_2                   matlab.ui.container.Menu
        EvolveMenu_2                    matlab.ui.container.Menu
        PrimeMenu_2                     matlab.ui.container.Menu
        TwinPrimeMenu_2                 matlab.ui.container.Menu
        SettingsMenu                    matlab.ui.container.Menu
        AppSettingsMenu                 matlab.ui.container.Menu
        ContrastSettingsMenu            matlab.ui.container.Menu
        LastMappingMenu                 matlab.ui.container.Menu
        SpotsFoundMenu                  matlab.ui.container.Menu
        Save                            matlab.ui.container.Menu
        MovieAsaviMenu                  matlab.ui.container.Menu
        FrameasjpgMenu                  matlab.ui.container.Menu
        AppSettingsMenu_2               matlab.ui.container.Menu
        AllAppSettingsMenu              matlab.ui.container.Menu
        SettingsForThisMovieMenu        matlab.ui.container.Menu
        MappingMenu                     matlab.ui.container.Menu
        ContrastSettingsMenu_2          matlab.ui.container.Menu
        DisplayMenu                     matlab.ui.container.Menu
        ColormapMenu_2                  matlab.ui.container.Menu
        GrayMenu                        matlab.ui.container.Menu
        BoneMenu                        matlab.ui.container.Menu
        PinkMenu                        matlab.ui.container.Menu
        WinterMenu                      matlab.ui.container.Menu
        AutumnMenu                      matlab.ui.container.Menu
        HotMenu                         matlab.ui.container.Menu
        CoolMenu                        matlab.ui.container.Menu
        hsvMenu_2                       matlab.ui.container.Menu
        JetMenu                         matlab.ui.container.Menu
        ParulaMenu                      matlab.ui.container.Menu
        LinesMenu                       matlab.ui.container.Menu
        ColorcubeMenu                   matlab.ui.container.Menu
        PrismMenu                       matlab.ui.container.Menu
        FlagMenu                        matlab.ui.container.Menu
        turboMenu                       matlab.ui.container.Menu
        SpotColorsMenu_2                matlab.ui.container.Menu
        DonorMenu_2                     matlab.ui.container.Menu
        AcceptorMenu_2                  matlab.ui.container.Menu
        ImageSizeMenu                   matlab.ui.container.Menu
        RealsizeMenu                    matlab.ui.container.Menu
        FixedAspectRatioMenu            matlab.ui.container.Menu
        GuiForPrimeGridLayout           matlab.ui.container.GridLayout
        AnalyzeTabGroup                 matlab.ui.container.TabGroup
        SpotsTab                        matlab.ui.container.Tab
        SpotsTabGridLayout              matlab.ui.container.GridLayout
        SpotSettingsPanel               matlab.ui.container.Panel
        WhichSideDropDownLabel          matlab.ui.control.Label
        WhichSideDropDown               matlab.ui.control.DropDown
        SpotTypeDropDownLabel           matlab.ui.control.Label
        SpotTypeDropDown                matlab.ui.control.DropDown
        FindSpotsButton                 matlab.ui.control.Button
        UpButton                        matlab.ui.control.Button
        DownButton                      matlab.ui.control.Button
        ThresholdEditFieldLabel         matlab.ui.control.Label
        ThresholdEditField              matlab.ui.control.NumericEditField
        RedGreenAltModeCheckBox         matlab.ui.control.CheckBox
        RedWhiteGreenAltModeCheckBox    matlab.ui.control.CheckBox
        GreenStartFrameEditFieldLabel   matlab.ui.control.Label
        GreenStartFrameEditField        matlab.ui.control.NumericEditField
        GreenEndFrameEditFieldLabel     matlab.ui.control.Label
        GreenEndFrameEditField          matlab.ui.control.NumericEditField
        RachelsBigSpotsModeCheckBox     matlab.ui.control.CheckBox
        InvertSwitch                    matlab.ui.control.Switch
        LiveCellModeCheckBox            matlab.ui.control.CheckBox
        HOLDCheckBox                    matlab.ui.control.CheckBox
        AnalysisSettingsTabGroup        matlab.ui.container.TabGroup
        AnalyzeTab                      matlab.ui.container.Tab
        ViewTracesWithMovieButton       matlab.ui.control.Button
        ViewTracesButton                matlab.ui.control.Button
        ViewTPMButton                   matlab.ui.control.Button
        ViewFRETTPMButton               matlab.ui.control.Button
        AnalysisPanel                   matlab.ui.container.Panel
        AnalysisTypeDropDownLabel       matlab.ui.control.Label
        AnalysisTypeDropDown            matlab.ui.control.DropDown
        AnalyzeButton                   matlab.ui.control.Button
        BatchAnalysisButton             matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        LiveCellModeTab                 matlab.ui.container.Tab
        clearedgesButton                matlab.ui.control.Button
        MinAreaofCellEditFieldLabel     matlab.ui.control.Label
        MinAreaofCellEditField          matlab.ui.control.NumericEditField
        DetectCellEdgesButton           matlab.ui.control.StateButton
        LiveCellNewFigureButton         matlab.ui.control.Button
        NumFramesToAverageForLiveCellImageEditFieldLabel  matlab.ui.control.Label
        NumFramesToAverageForLiveCellImageEditField  matlab.ui.control.NumericEditField
        ThickerEdgesCheckBox            matlab.ui.control.CheckBox
        ThinnerEdgesCheckBox            matlab.ui.control.CheckBox
        InvertCheckBox                  matlab.ui.control.CheckBox
        CellBgSubtractionDropDownLabel  matlab.ui.control.Label
        CellBgSubtractionDropDown       matlab.ui.control.DropDown
        ThicknessSpinnerLabel           matlab.ui.control.Label
        ThicknessSpinner                matlab.ui.control.Spinner
        BigSpotsTab                     matlab.ui.container.Tab
        TailLengthpixelsSpinnerLabel    matlab.ui.control.Label
        TailLengthpixelsSpinner         matlab.ui.control.Spinner
        RedWhiteGreenAltModeTab         matlab.ui.container.Tab
        GreenLaserLabel                 matlab.ui.control.Label
        FramesonLabel                   matlab.ui.control.Label
        GreenLaserNumFramesOnEditField  matlab.ui.control.NumericEditField
        FramesoffEditFieldLabel         matlab.ui.control.Label
        GreenLaserNumFramesOffEditField  matlab.ui.control.NumericEditField
        StartFrameEditField_2Label      matlab.ui.control.Label
        GreenLaserStartFrameForRGWEditField  matlab.ui.control.NumericEditField
        WhiteLightNumFramesOnEditField  matlab.ui.control.NumericEditField
        WhiteLightNumFramesOffEditField  matlab.ui.control.NumericEditField
        WhiteLightStartFrameForRGWEditField  matlab.ui.control.NumericEditField
        WhiteLightLabel                 matlab.ui.control.Label
        AltModeEndFrameEditFieldLabel   matlab.ui.control.Label
        AltModeEndFrameEditField        matlab.ui.control.NumericEditField
        SplitgreenwhitemoviesCheckBox   matlab.ui.control.CheckBox
        AdvancedSettingsTab             matlab.ui.container.Tab
        AdvancedSettingsGridLayout      matlab.ui.container.GridLayout
        AdvancedSettingsTabGroup2       matlab.ui.container.TabGroup
        SettingsTab                     matlab.ui.container.Tab
        ForBackgroundCalculationLabel   matlab.ui.control.Label
        ForFindingSpotsLabel            matlab.ui.control.Label
        ForAnalysisLabel                matlab.ui.control.Label
        TPMAnalysisLabel                matlab.ui.control.Label
        NearestNeighborEditFieldLabel   matlab.ui.control.Label
        NearestNeighborEditField        matlab.ui.control.NumericEditField
        BlockSizeEditFieldLabel         matlab.ui.control.Label
        BlockSizeEditField              matlab.ui.control.EditField
        DistanceAwayFromEdgesEditFieldLabel  matlab.ui.control.Label
        DistanceAwayFromEdgesEditField  matlab.ui.control.NumericEditField
        SquareSizeAroundEachSpotEditFieldLabel  matlab.ui.control.Label
        SquareSizeAroundEachSpotEditField  matlab.ui.control.NumericEditField
        NumPixelsForSumIntensityEditFieldLabel  matlab.ui.control.Label
        NumPixelsForSumIntensityEditField  matlab.ui.control.NumericEditField
        AreatoSearchforLocalMaximumsSpinnerLabel  matlab.ui.control.Label
        AreatoSearchforLocalMaximumsSpinner  matlab.ui.control.Spinner
        ImageforFittingSizeSpinnerLabel  matlab.ui.control.Label
        ImageforFittingSizeSpinner      matlab.ui.control.Spinner
        OffsetLimitSpinnerLabel         matlab.ui.control.Label
        OffsetLimitSpinner              matlab.ui.control.Spinner
        FramenumbersTab                 matlab.ui.container.Tab
        SpotfindingImageLabel           matlab.ui.control.Label
        NumFramesToAvgSpotfindingBg     matlab.ui.control.Spinner
        NumFramesToAvgSpotfindingImage  matlab.ui.control.Spinner
        StartFrameSpotfindingImage      matlab.ui.control.NumericEditField
        StartFrameSpotfindingBg         matlab.ui.control.NumericEditField
        StartFrameForGreenLaserBackgroundEditField  matlab.ui.control.NumericEditField
        NumFramesToAverageForGreenLaserBackgroundSpinner  matlab.ui.control.Spinner
        autoStartFrameSpotsCheckbox     matlab.ui.control.CheckBox
        autoNumFramesSpotsCheckbox      matlab.ui.control.CheckBox
        autoStartFrameBgCheckbox        matlab.ui.control.CheckBox
        autoNumFramesBgCheckbox         matlab.ui.control.CheckBox
        autoStartFrameGreenCheckbox     matlab.ui.control.CheckBox
        autoNumFramesGreenCheckbox      matlab.ui.control.CheckBox
        AutoLabel                       matlab.ui.control.Label
        StartFrameForWhiteLightBg       matlab.ui.control.NumericEditField
        NumFramesToAvgForWhiteLightBg   matlab.ui.control.Spinner
        SpotfindingBackgroundLabel      matlab.ui.control.Label
        GreenLaserBackgroundLabel       matlab.ui.control.Label
        StartFrameLabel                 matlab.ui.control.Label
        NumberofFramestoAverageLabel    matlab.ui.control.Label
        WhiteLightBackgroundLabel       matlab.ui.control.Label
        autoNumFramesToAvgWhiteLightBg  matlab.ui.control.CheckBox
        autoStartFrameForWhiteLightBg   matlab.ui.control.CheckBox
        MappingTab                      matlab.ui.container.Tab
        CreatemappingbyselectingpointstouseLabel  matlab.ui.control.Label
        SelectPointsButton              matlab.ui.control.StateButton
        RachelSpots                     matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        RachelSpotPanel                 matlab.ui.container.Panel
        Panel                           matlab.ui.container.Panel
        InstructionsForRoi              matlab.ui.control.TextArea
        maxEditFieldLabel               matlab.ui.control.Label
        maxEditField                    matlab.ui.control.NumericEditField
        minEditFieldLabel               matlab.ui.control.Label
        minEditField                    matlab.ui.control.NumericEditField
        BigSpotIntensityEditField       matlab.ui.control.NumericEditField
        roiaroundbiggestspotButton      matlab.ui.control.Button
        InfoAndMappingPanel             matlab.ui.container.Panel
        UseCurrentMappingCheckBox       matlab.ui.control.CheckBox
        InfoTextArea                    matlab.ui.control.TextArea
        SaveMappingButton               matlab.ui.control.Button
        LoadLastMappingButton           matlab.ui.control.Button
        moviePanel_outer                matlab.ui.container.Panel
        moviePanel                      matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        PlaybackSettingsTab             matlab.ui.container.Tab
        PlayMovieButton                 matlab.ui.control.StateButton
        PlaybackSpeedCheckBox           matlab.ui.control.CheckBox
        PlaybackSpeedsframeLabel        matlab.ui.control.Label
        PlaybackSpeedEditField          matlab.ui.control.NumericEditField
        PlayonLoopCheckBox              matlab.ui.control.CheckBox
        StartEditFieldLabel             matlab.ui.control.Label
        playbackStartFrame              matlab.ui.control.NumericEditField
        EndEditFieldLabel               matlab.ui.control.Label
        playbackEndFrame                matlab.ui.control.NumericEditField
        ContrastSettingsTab             matlab.ui.container.Tab
        ToAdjustContrastclickhighorlowbuttonscrollwithmousewheelLabel  matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        SensitivitySpinnerLabel         matlab.ui.control.Label
        SensitivitySpinner              matlab.ui.control.Spinner
        HighContrastButton              matlab.ui.control.StateButton
        LowContrastButton               matlab.ui.control.StateButton
        LeftButton                      matlab.ui.control.StateButton
        RightButton                     matlab.ui.control.StateButton
        SaveContrastSettingsButton      matlab.ui.control.Button
        LoadContrastSettingsButton      matlab.ui.control.Button
        ContrastTextArea                matlab.ui.control.TextArea
        WhiteFramesCheckBox             matlab.ui.control.CheckBox
        GreenFramesCheckBox             matlab.ui.control.CheckBox
        OtherViewSettingsTab            matlab.ui.container.Tab
        ImageViewLabel                  matlab.ui.control.Label
        DropDown_imagedisplay           matlab.ui.control.DropDown
        OverlayImagesButton             matlab.ui.control.StateButton
        SmileButton                     matlab.ui.control.Button
        TransparencySpinnerLabel        matlab.ui.control.Label
        TransparencySpinner             matlab.ui.control.Spinner
        OnlykeepspotsinsidecellsCheckBox  matlab.ui.control.CheckBox
        FindSpotsUsingAllFramesCheckBox  matlab.ui.control.CheckBox
        NumPixelsAwayFromSpotToSubtractCellBackgroundLabel  matlab.ui.control.Label
        NumPixelsAwayFromSpotToSubtractCellBackgroundSpinner  matlab.ui.control.Spinner
        CheckforDoubleFramesButton      matlab.ui.control.Button
        MaxTravelDistanceSpinnerLabel   matlab.ui.control.Label
        MaxTravelDistanceSpinner        matlab.ui.control.Spinner
        FramestouseforSpotfindingLabel  matlab.ui.control.Label
        GreenCheckBox                   matlab.ui.control.CheckBox
        RedCheckBox                     matlab.ui.control.CheckBox
        WhiteCheckBox                   matlab.ui.control.CheckBox
        FrameNumberEditField            matlab.ui.control.EditField
        PixelInfoRightside              matlab.ui.control.EditField
        PixelInfoLeftside               matlab.ui.control.EditField
        InstructionsTextArea            matlab.ui.control.EditField
        GridLayout2                     matlab.ui.container.GridLayout
        Frameslider                     matlab.ui.control.Slider
        BackwardButton                  matlab.ui.control.Button
        ForwardButton                   matlab.ui.control.Button
    end

  
  properties (Access = public)
    
    FileID
    filename
    fileprefix
    cameratype
    numCameras %1 if both sides are in one frame, 2 if each side is a new frame (only TwinPrime for now)
    
    fileExtension
    path
    
    
    NumFrames

    imagesizex
    imagesizey

    
    blocksize
    
    manualNearestNeighborMode = false;
    manualBlocksize = false;


    % images and framenumbers used for spotfinding & analysis
    spotfindingImage_rightside
    spotfindingImage_leftside
    
    spotfindingBackground_rightside
    spotfindingBackground_leftside

    
    analysisBackground_greenFrames_rightside
    analysisBackground_greenFrames_leftside
    
    % mode specifies which framenumbers to use for which backgrounds
    % modes: "RGalt";"RWGalt";"liveCell";"bigSpots";"regular";
    % setting is empty before loading a movie
    analysisMode = "regular";


    % for red white green alt mode:
    white_background_rightside
    white_background_leftside
    redLaserFrameIdx
    greenLaserFrameIdx
    whiteLightFrameIdx
    alternatingFramesIdx
    redLaserTemporalFramenumbers
    greenLaserTemporalFramenumbers
    whiteLightTemporalFramenumbers
    frameIndexForFRETanalysis
    temporalFramenumbersForFRETanalysis
    
%         % analysis
%     numgreenlaserframeson
%     numwhitelightframeson
%     numgreenlaserframesoff
%     numwhitelightframesoff
%     greenlaserstartframe
%     whitelightstartframe
%     
%     altframes_white
%     altframes_green
%     realframes_white
%     realframes_green


    current_point
    current_frame = 1;
    current_rightside
    current_leftside
    
    
    % for displaying the movie
    movieLayout
    movieAxisToolbar
    movieAxisLeftside
    movieAxisRightside
    movieAxisToolbarRightside
    movieAxisToolbarLeftside
    colorMapForImage = 'gray';
    leftspotcolor = 'red';
    rightspotcolor = 'magenta';


    % spot finding
    x_spots_leftside
    x_spots_rightside
    y_spots_leftside
    y_spots_rightside
    
    xoffset
    yoffset
    
    
    f_map
    f_map_forDisplayInTextArea
    pointsSelectedForMapping
    
    num_spots
    keep_spots_displayed
    whichside
    % check these to see if they are being used:
    squarespotimage
    squarespotcoordinfo
    FramewiseTPMdata


    batchAnalysisMode = "off";
    batchnumber = 1;
    batchtotal = 1;
    batchpercent = 1;
    
    % secondary applications
    %ViewTracesApp % to view FRET traces
    %ViewTPMApp % to view TPM data
    %ViewFRETandTPMApp % for prime camera, if both fret and tpm were done


    % for redWhiteGreen alt mode viewing in new app
    lastsavedtrace
    lastsavedTPM
    
    %%% for live cell imaging
    liveCellImage


    cellMap_leftside
    cellMap_rightside
    
    regionProps_rightside 
    regionProps_leftside
    
    LiveCellSelectionMode = false;
    
    edgeMask_leftside
    edgeMask_rightside
    
    edgeDetectionObjLeftside
    edgeDetectionObjRightside
    
    numSpotsNotInACellText
    numSpotsInCellText
    newSpotsText
    enhancedImage_rightside
    enhancedImage_leftside
    
    labeledImage_leftside
    labeledImage_rightside
    
    % for Rachel's Big Spots
    % currently not in use
    bigSpotLayout
    bigSpotAxis
    BigSpotAnalysisInfo


  end


  methods (Access = public)
    %% Starting Up Functions/ Loading new data functions/ Updating display functions
    function [movieframeimage_rightside, movieframeimage_leftside] = get_avg_frames(app,framenumber,numframes)
      % read in the frame
      % move file location pointer to correct frame location in file
      % fseek(fileId,offset,origin)
      % fileID is a number referencing to a file gotten from fopen
      % offset is the num of bytes you want to skip before you start reading
      % all numbers are 2 bytes
      % origin is your starting location in the file.
      % -1 = begining, 0 = current position, 1 = end of file
      
      % open pma or pmc file, which is stored in the following format:
      % (x-dimension) (y-dimension) (framenumber) (xdimension*ydimension intensity values) (framenumber) etc.
      %             fseek(app.FileID, (4+app.framesizex*app.framesizey*(framenumber-1)*4+(framenumber-1)*4),-1);
      % 4 = (first two numbers in file (sizex & sizey))
      % (imagesizey*imagesizex) = one frame
      % *(framenumber - 1) = so if you want the first frame it =0
      % *2 because 2 bytes per number
      % * numCameras bc for twinprime it is one frame for each camera
      % + (framenumber-1) bc it reads the framenumber before each frame
      % *2 for 2 bytes per number
      % *numCameras because it reads framenumber twice if it's twinP
      % so for frame1, offset = 4
      
      % move file reader to correct starting location
      fseek(app.FileID, (4+app.imagesizex*app.imagesizey*(framenumber-1)*2*app.numCameras+(framenumber-1)*2*app.numCameras),-1);
      % determine how to read the movie. 
      if app.numCameras == 2
        readMethod = 2;
        if app.RedWhiteGreenAltModeCheckBox.Value == true
          disp('in get_avg_frames function, code not yet written for two cameras with red white gren alt mode, might still work-ish though.')
        end
      elseif app.RedWhiteGreenAltModeCheckBox.Value == true
        frameIndicesToRead = framenumber:(framenumber + numframes - 1);
        if all(ismember(frameIndicesToRead, app.alternatingFramesIdx))
          readMethod = 2;
        elseif all(ismember(frameIndicesToRead, app.redLaserFrameIdx))
          readMethod = 1;
        else
          error("Attempting to read frames from red laser and from alternating white and green frames in the same call.")
        end
      else
        readMethod = 1;
      end
      % read the frames based on which readMethod was chosen.
      switch readMethod
        case 1
          if numframes == 0
            movieframeimage_rightside = zeros(app.imagesizey, app.imagesizex/2);
            movieframeimage_leftside = zeros(app.imagesizey, app.imagesizex/2);
            return
          end
          fullimage = zeros([app.imagesizex, app.imagesizey]);
          fread(app.FileID,1, 'uint16'); % first thing it will read will be frame number
          for i = 1:numframes
            temp = fread(app.FileID,[app.imagesizex, app.imagesizey], 'uint16');
            fread(app.FileID,1, 'uint16'); % first thing it will read will be frame number
            temp = temp';
            fullimage = fullimage + temp;
          end
          fullimage = fullimage./numframes;
          movieframeimage_leftside = fullimage(:,1:app.imagesizex/2);
          movieframeimage_rightside = fullimage(:,(1+app.imagesizex/2):app.imagesizex);
        case 2
          if numframes == 0
            movieframeimage_rightside = zeros(app.imagesizey, app.imagesizex);
            movieframeimage_leftside = zeros(app.imagesizey, app.imagesizex);
            return
          end
          % if in red, white, green alt mode, rightside is white movie
          % frame, left side is white movie frame. Assuming the first green
          % laser frame is before the first white light frame
          rightOrGreen = zeros([app.imagesizex, app.imagesizey]);
          leftOrWhite = zeros([app.imagesizex, app.imagesizey]);
          for i = 1:numframes
            fread(app.FileID,1, 'uint16');% first thing it will read will be frame number
            tempR = fread(app.FileID,[app.imagesizex, app.imagesizey], 'uint16');
            tempR = tempR';
            rightOrGreen = rightOrGreen+tempR;
            fread(app.FileID,1, 'uint16'); %frame number again
            % if odd number of green and white alt frames, need to test for
            % end of file indicator
            if ~feof(app.FileID)
              tempL = fread(app.FileID,[app.imagesizex, app.imagesizey], 'uint16');
              tempL = tempL';
              if app.numCameras == 2
                tempL = flip(tempL,2);
              end
              leftOrWhite = leftOrWhite+tempL;
            end
          end
          if ~feof(app.FileID)
            movieframeimage_rightside = rightOrGreen./numframes;
            movieframeimage_leftside = leftOrWhite./numframes;
          else
            movieframeimage_rightside = rightOrGreen./numframes;
            movieframeimage_leftside = leftOrWhite./(numframes-1);
          end
      end
    end
    
    function [background_rightside, background_leftside] = calculateBackground(app, blocksize, averagedFrames)
      temp = blkproc(averagedFrames , blocksize,'median(x(:))');
      fullimage = imresize(temp,[app.imagesizex app.imagesizey],'bicubic');
      background_leftside = fullimage(:,1:app.imagesizex/2);
      background_rightside = fullimage(:,(1+app.imagesizex/2):end);
    end
    
    function OpenMovie(app,datafile)
      % open pma or pmc file, which is stored in the following format:
      % (x-dimension) (y-dimension) (framenumber) (xdimension*ydimension intensity values) (framenumber) etc.
      % all numbers are 2 bytes
      app.FileID = fopen(datafile,'r'); %%file id
      app.filename = datafile;
      
      %extracts and stores dimensions of movie
      app.imagesizex = fread(app.FileID,1,'uint16'); %%%pixels in x direction
      app.imagesizey = fread(app.FileID,1,'uint16'); %%%pixels in y direction
      
      %calculate number of frames
      datafile = string(datafile); %% not sure why I did this
      s=dir(datafile);
      app.NumFrames=(s.bytes-4)/(app.imagesizex*app.imagesizey*2*app.numCameras+2*app.numCameras);
      

      autoCheckboxes = findobj(app.FramenumbersTab, 'Type', 'uicheckbox');

      % if movie is shorter than 10 frames
      if app.NumFrames <= 10
        halfOfTheFrames = floor(app.NumFrames/2);
        if halfOfTheFrames == 0
          halfOfTheFrames = 1;
        end
        
        % need to change this to do them individually in case they aren't
        % all on auto.
        if all(cell2mat(get(autoCheckboxes, 'Value'))) == true
          
        app.NumFramesToAvgSpotfindingImage.Value = halfOfTheFrames;
        app.NumFramesToAvgSpotfindingBg.Value = halfOfTheFrames;
        app.StartFrameSpotfindingImage.Value = 1;
        app.StartFrameSpotfindingBg.Value = app.NumFrames-1 - halfOfTheFrames;
        numFramesToAverageForCurrentDisplay = halfOfTheFrames;
        end
      else
          for i = 1:size(autoCheckboxes,1)
            if autoCheckboxes(i).Value == true
            e.Value = true; e.Source = autoCheckboxes(i);
            autoFrameSelectionCheckboxValueChanged(app,e);
            end
          end
        numFramesToAverageForCurrentDisplay = 10;
      end
      

      %average first 10 frames for current display
      [imageRight, imageLeft] = get_avg_frames(app,1,numFramesToAverageForCurrentDisplay);
      app.current_rightside = imageRight;
      app.current_leftside = imageLeft;
      
      % set start and end play selection limits
      app.playbackStartFrame.Limits = [1 app.NumFrames];
      app.playbackEndFrame.Limits = [1 app.NumFrames];
      
      
      app.DropDown_imagedisplay.Value = "Original";
      
      
      % set parameters for frame number slider
      app.Frameslider.Limits = [1 app.NumFrames];
      app.Frameslider.Value = 1;
      app.current_frame = 1;
      
    end
    
    function setUpMovieAxis(app)
      
      app.movieLayout = tiledlayout(app.moviePanel,1,2,'TileSpacing','none', "Padding","compact");%"Padding","none");
      % computer is faster if you do one side at a time
      app.movieAxisLeftside = nexttile(app.movieLayout);
      axis(app.movieAxisLeftside, 'image');
      image(app.movieAxisLeftside,app.current_leftside,"CDataMapping","scaled","Tag","Left_Image");
      app.movieAxisLeftside.XTickMode = "manual";
      app.movieAxisLeftside.YTickMode = "manual";
      app.movieAxisLeftside.XTick = [];
      app.movieAxisLeftside.YTick = [];
      app.movieAxisLeftside.XLimMode = "manual";
      app.movieAxisLeftside.YLimMode = "manual";
      app.movieAxisLeftside.YDir = "normal";
      app.movieAxisLeftside.XLim = [0.5, (app.imagesizex/2 + 0.5)];
      app.movieAxisLeftside.YLim = [0.5, (app.imagesizey + 0.5)];
      app.movieAxisLeftside.PlotBoxAspectRatio = [1 1 1];
      app.movieAxisLeftside.DataAspectRatio = [1 1 1];
      colormap(app.movieAxisLeftside,app.colorMapForImage);
      app.movieAxisLeftside.Tag = "Left_Image";
      app.movieAxisLeftside.Box = "off";
      
      app.movieAxisRightside = nexttile(app.movieLayout);
      axis(app.movieAxisRightside, 'image');
      image(app.movieAxisRightside,app.current_rightside,"CDataMapping","scaled","Tag","Right_Image");
      app.movieAxisRightside.XTickMode = "manual";
      app.movieAxisRightside.YTickMode = "manual";
      app.movieAxisRightside.XTick = [];
      app.movieAxisRightside.YTick = [];
      app.movieAxisRightside.XLimMode = "manual";
      app.movieAxisRightside.YLimMode = "manual";
      app.movieAxisRightside.YDir = "normal";
      app.movieAxisRightside.PlotBoxAspectRatio = [1 1 1];
      app.movieAxisRightside.DataAspectRatio = [1 1 1];
      % Note, app.movieAxisLeftside.XData = [1 256];
      % Two-element vector — Use the first element as the location for the center of C(1,1) 
      % and the second element as the location for the center of C(m,n), where [m,n] = size(C). 
      % Evenly distribute the centers of the remaining elements of C between those two points.
      % Center of the pixel is at 1, so limits need to start at 0.5
      app.movieAxisRightside.XLim = [0.5, (app.imagesizex/2 + 0.5)];
      app.movieAxisRightside.YLim = [0.5, (app.imagesizey + 0.5)];
      colormap(app.movieAxisRightside,app.colorMapForImage);
      app.movieAxisRightside.Tag = "Right_Image";
      app.movieAxisRightside.Box = "off";

      
      % update contrast info
      left_lims = app.movieAxisLeftside.CLim;
      right_lims = app.movieAxisRightside.CLim;
      leftContrastText = "Left Contrast: [ " + num2str(left_lims(1)) + ", " + num2str(left_lims(2)) + " ] ";
      rightContrastText = "Right Contrast: [ " + num2str(right_lims(1)) + ", " + num2str(right_lims(2)) + " ]";
      app.ContrastTextArea.Value = [leftContrastText; rightContrastText];
      
      % assign button down functions
      %app.movieAxisLeftside.ButtonDownFcn = @(s,d)callback_imageClicked(app,s,d);
      %app.movieAxisRightside.ButtonDownFcn = @(s,d)callback_imageClicked(app,s,d);
      
      % assign mouse pointer behavior
      pb.enterFcn = []; %@(s,c)callback_mouseMovementChangesCurrentAxes(app,s,c,'right');
      pb.traverseFcn = @(s,c)callback_mouseMovesOverAxes(app,s,c,'right');
      pb.exitFcn = []; %@(s,c)callback_mouseMovesOverAxes(app,s,c,'right');
      iptSetPointerBehavior(app.movieAxisRightside,pb)
      pb.traverseFcn = @(s,c)callback_mouseMovesOverAxes(app,s,c,'left');
      iptSetPointerBehavior(app.movieAxisLeftside,pb)
      
            % make movie toolbar
      app.movieAxisToolbar = axtoolbar(app.movieLayout,{'pan'});
      % restore view button
      btn = axtoolbarbtn(app.movieAxisToolbar,'push');
      btn.Icon = 'restoreview';
      btn.ButtonPushedFcn = @(s,d)callback_restoreView(app,s,d);
      % zoom in button
      btn = axtoolbarbtn(app.movieAxisToolbar,'state');
      btn.Icon = 'zoomin';
      btn.Value = false;
      btn.ValueChangedFcn = @(source, event)callback_zoomToolbarButtonPressed(app, source, event);
      % square zoom button
      squareIcon = makeSquareIcon(app);
      btn = axtoolbarbtn(app.movieAxisToolbar,'state');
      btn.Icon = squareIcon;
      btn.Tag = "square";
      btn.Value = true;
      btn.ValueChangedFcn = @(s,d)callback_squareButtonChanged(app,s,d);
      %       dbstop if error % something is wrong here but not sure what
            %       smileyFace = makeASmile(app);
      %       btn = axtoolbarbtn(app.movieAxisToolbarRightside,'state');
      %       btn.Icon = smileyFace;
      %       btn.Tag = "smileyFace";
      %       btn.Value = false;
      %       btn.ValueChangedFcn = @(s,d)callback_smileButtonPushed(app,s,d);
      
      % put movie panel in the middle
           % put movie panel and spot panel in correct positions
      app.moviePanel_outer.AutoResizeChildren = 'off';
      app.moviePanel.Position(1) = 1;
      app.moviePanel.Position(2) = 1;
      containerWidth = app.moviePanel_outer.InnerPosition(3);
      containerHeight = app.moviePanel_outer.InnerPosition(4);
      app.moviePanel.Position(3) = containerWidth ;
      app.moviePanel.Position(4) = containerHeight;
      ratio = app.imagesizex/app.imagesizey;
      containerRatio = containerWidth/containerHeight;
      if ratio == 1
        if containerRatio > ratio
          %container is too wide
          extraSpace = (containerWidth - containerHeight)/2;
          app.moviePanel.Position(3) = app.moviePanel.Position(3) - extraSpace;
          app.moviePanel.Position(1) = extraSpace;
        elseif containerRatio < ratio
          % container is too tall
          extraSpace = (containerHeight - containerWidth)/2;
          app.moviePanel.Position(4) = app.moviePanel.Position(4) - extraSpace;
          app.moviePanel.Position(2) = extraSpace;
        end
      else
        disp('in setUpMovieAxis, setting movie panel size, need to add code here')
      end
      app.moviePanel.BorderType = "none";


    end
    
    function setUpMovieAxisForRWGAltMode(app)
      app.movieLayout = tiledlayout(app.moviePanel,1,4,'TileSpacing','none', "Padding","compact");%"Padding","none");
      axisNames = ["movieAxisOuterLeftside", "movieAxisLeftside", "movieAxisRightside", "movieAxisOuterRightside"];
      for i = 1:4
        app.(axisNames(i)) = nexttile(app.movieLayout, i);
        axis(app.(axisNames(i)), 'image');
        image(app.(axisNames(i)),app.current_leftside,"CDataMapping","scaled","Tag","Left_Image");
        app.(axisNames(i)).XTickMode = "manual";
        app.(axisNames(i)).YTickMode = "manual";
        app.(axisNames(i)).XTick = [];
        app.(axisNames(i)).YTick = [];
        app.(axisNames(i)).XLimMode = "manual";
        app.(axisNames(i)).YLimMode = "manual";
        app.(axisNames(i)).YDir = "normal";
        app.(axisNames(i)).XLim = [0.5, (app.imagesizex/2 + 0.5)];
        app.(axisNames(i)).YLim = [0.5, (app.imagesizey + 0.5)];
        colormap(app.(axisNames(i)),app.colorMapForImage);
        app.(axisNames(i)).Tag = "Left_Image";
        app.(axisNames(i)).Box = "off";
      end
      
      app.movieAxisLeftside = nexttile(app.movieLayout, 2);
      axis(app.movieAxisLeftside, 'image');
      image(app.movieAxisLeftside,app.current_leftside,"CDataMapping","scaled","Tag","Left_Image");
      app.movieAxisLeftside.XTickMode = "manual";
      app.movieAxisLeftside.YTickMode = "manual";
      app.movieAxisLeftside.XTick = [];
      app.movieAxisLeftside.YTick = [];
      app.movieAxisLeftside.XLimMode = "manual";
      app.movieAxisLeftside.YLimMode = "manual";
      app.movieAxisLeftside.YDir = "normal";
      app.movieAxisLeftside.XLim = [0.5, (app.imagesizex/2 + 0.5)];
      app.movieAxisLeftside.YLim = [0.5, (app.imagesizey + 0.5)];
      colormap(app.movieAxisLeftside,app.colorMapForImage);
      app.movieAxisLeftside.Tag = "Left_Image";
      app.movieAxisLeftside.Box = "off";
      
      app.movieAxisRightside = nexttile(app.movieLayout, 3);
      axis(app.movieAxisRightside, 'image');
      image(app.movieAxisRightside,app.current_rightside,"CDataMapping","scaled","Tag","Right_Image");
      app.movieAxisRightside.XTickMode = "manual";
      app.movieAxisRightside.YTickMode = "manual";
      app.movieAxisRightside.XTick = [];
      app.movieAxisRightside.YTick = [];
      app.movieAxisRightside.XLimMode = "manual";
      app.movieAxisRightside.YLimMode = "manual";
      app.movieAxisRightside.YDir = "normal";
      % Note, app.movieAxisLeftside.XData = [1 256];
      % Two-element vector — Use the first element as the location for the center of C(1,1)
      % and the second element as the location for the center of C(m,n), where [m,n] = size(C).
      % Evenly distribute the centers of the remaining elements of C between those two points.
      % Center of the pixel is at 1, so limits need to start at 0.5
      app.movieAxisRightside.XLim = [0.5, (app.imagesizex/2 + 0.5)];
      app.movieAxisRightside.YLim = [0.5, (app.imagesizey + 0.5)];
      colormap(app.movieAxisRightside,app.colorMapForImage);
      app.movieAxisRightside.Tag = "Right_Image";
      app.movieAxisRightside.Box = "off";
      
      
      % update contrast info
      left_lims = app.movieAxisLeftside.CLim;
      right_lims = app.movieAxisRightside.CLim;
      leftContrastText = "Left Contrast: [ " + num2str(left_lims(1)) + ", " + num2str(left_lims(2)) + " ] ";
      rightContrastText = "Right Contrast: [ " + num2str(right_lims(1)) + ", " + num2str(right_lims(2)) + " ]";
      app.ContrastTextArea.Value = [leftContrastText; rightContrastText];
      
      % assign button down functions
      %app.movieAxisLeftside.ButtonDownFcn = @(s,d)callback_imageClicked(app,s,d);
      %app.movieAxisRightside.ButtonDownFcn = @(s,d)callback_imageClicked(app,s,d);
      
      % assign mouse pointer behavior
      pb.enterFcn = [];
      pb.traverseFcn = @(s,c)callback_mouseMovesOverAxes(app,s,c,'right');
      pb.exitFcn = [];
      iptSetPointerBehavior(app.movieAxisRightside,pb)
      pb.traverseFcn = @(s,c)callback_mouseMovesOverAxes(app,s,c,'left');
      iptSetPointerBehavior(app.movieAxisLeftside,pb)
      
      % make movie toolbar
      app.movieAxisToolbar = axtoolbar(app.movieLayout,{'pan'});
      % restore view button
      btn = axtoolbarbtn(app.movieAxisToolbar,'push');
      btn.Icon = 'restoreview';
      btn.ButtonPushedFcn = @(s,d)callback_restoreView(app,s,d);
      % zoom in button
      btn = axtoolbarbtn(app.movieAxisToolbar,'push');
      btn.Icon = 'zoomin';
      %btn.Value = false;
      btn.ButtonPushedFcn = @(source, event)callback_zoomIn(app, source, event);
      % square zoom button
      squareIcon = makeSquareIcon(app);
      btn = axtoolbarbtn(app.movieAxisToolbar,'state');
      btn.Icon = squareIcon;
      btn.Tag = "square";
      btn.Value = true;
      btn.ValueChangedFcn = @(s,d)callback_squareButtonChanged(app,s,d);

    end
    
    function setFramenumbersForRWGaltMode(app)
      if app.numCameras == 2
        disp("code not yet written for separateGreenAndWhiteFrameNumbers function with 2 cameras");
        return
      end
      %separate white and green frames
      numFramesGreenOn = app.GreenLaserNumFramesOnEditField.Value; %located in rwg alt mode tab
      numFramesWhiteOn = app.WhiteLightNumFramesOnEditField.Value;
      if app.GreenLaserStartFrameForRGWEditField.Value < app.WhiteLightStartFrameForRGWEditField.Value
        firstGreenFrameIdx = app.GreenLaserStartFrameForRGWEditField.Value + 1; %it's the 11th frame but reads as frame 16 in fread
        firstWhiteFrameIdx = app.GreenLaserStartFrameForRGWEditField.Value + 1 + numFramesGreenOn;
      else
        firstWhiteFrameIdx = app.WhiteLightStartFrameForRGWEditField.Value + 1;
        firstGreenFrameIdx = app.WhiteLightStartFrameForRGWEditField.Value + 1 + numFramesWhiteOn;
      end
      firstAlternatingFrame = min(firstGreenFrameIdx, firstWhiteFrameIdx);
      lastAlternatingFrame = min(app.NumFrames, app.AltModeEndFrameEditField.Value);
      app.greenLaserFrameIdx = firstGreenFrameIdx:(numFramesGreenOn + numFramesWhiteOn):lastAlternatingFrame;
      app.whiteLightFrameIdx = firstWhiteFrameIdx:(numFramesGreenOn + numFramesWhiteOn):lastAlternatingFrame;
      app.alternatingFramesIdx = firstAlternatingFrame:lastAlternatingFrame;
      numFramesGreenOff = app.GreenLaserNumFramesOffEditField.Value;
      numFramesWhiteOff = app.WhiteLightNumFramesOffEditField.Value;
      greenStartFrame = app.GreenLaserStartFrameForRGWEditField.Value;
      whiteStartFrame = app.WhiteLightStartFrameForRGWEditField.Value;
      app.greenLaserTemporalFramenumbers = (greenStartFrame + numFramesGreenOff +1):(2*abs(greenStartFrame - whiteStartFrame)):(greenStartFrame+(numFramesGreenOn + numFramesGreenOff)*length(app.greenLaserFrameIdx));
      app.whiteLightTemporalFramenumbers = (whiteStartFrame + numFramesWhiteOff +1):(2*abs(greenStartFrame - whiteStartFrame)):(whiteStartFrame+(numFramesWhiteOn + numFramesWhiteOff)*length(app.whiteLightFrameIdx));
      app.redLaserFrameIdx = 1:(firstAlternatingFrame-1);
      app.redLaserTemporalFramenumbers = 1:(firstAlternatingFrame-1);
      app.frameIndexForFRETanalysis = [app.redLaserFrameIdx, app.greenLaserFrameIdx];
      app.temporalFramenumbersForFRETanalysis = [app.redLaserTemporalFramenumbers, app.greenLaserTemporalFramenumbers];
      if app.AltModeEndFrameEditField.Value ~= inf
        app.redLaserFrameIdx = [app.redLaserFrameIdx, (lastAlternatingFrame + 1):app.NumFrames];
        app.frameIndexForFRETanalysis = [app.frameIndexForFRETanalysis, (lastAlternatingFrame + 1):app.NumFrames];
        % not sure how many frames happen before red laser is turned back
        % on. For now, assuming the same: (abs(greenStartFrame - whiteStartFrame))
        numFramesBeforeRedLaserTurnsOn = abs(greenStartFrame - whiteStartFrame);
        nextTemporalFramenumberAfterAltFrames = firstAlternatingFrame + abs(greenStartFrame - whiteStartFrame)*(length(app.alternatingFramesIdx)+1) + numFramesBeforeRedLaserTurnsOn;
        lengthOfRedLaserAtEnd = app.NumFrames - (firstAlternatingFrame -1) - length(app.alternatingFramesIdx);
        redLaserTemporalFramenumbersAtEnd = nextTemporalFramenumberAfterAltFrames:(nextTemporalFramenumberAfterAltFrames + lengthOfRedLaserAtEnd -1);
        app.redLaserTemporalFramenumbers = [1:(firstAlternatingFrame-1), redLaserTemporalFramenumbersAtEnd];
        app.temporalFramenumbersForFRETanalysis = [app.temporalFramenumbersForFRETanalysis, redLaserTemporalFramenumbersAtEnd];
      end
      app.GreenStartFrameEditField.Value = app.greenLaserFrameIdx(1);
      app.GreenEndFrameEditField.Value = app.greenLaserFrameIdx(end);
    end
    
    function setImagesForRWGspotfinding(app)
      % written before switch back to app.imagesizex/2 as default
      % set spotfinding image
      [white, green] = get_avg_frames(app, app.StartFrameSpotfindingImage.Value, ...
        app.NumFramesToAvgSpotfindingImage.Value);
      if app.RedCheckBox.Value == true
        app.spotfindingImage_leftside = green;
        app.spotfindingImage_rightside = white;
      elseif app.GreenCheckBox.Value == true
        app.spotfindingImage_leftside = green(:,1:app.imagesizex/2);
        app.spotfindingImage_rightside = green(:,(1+app.imagesizex/2):end);
      elseif app.WhiteCheckBox.Value == true
        app.spotfindingImage_leftside = white(:,1:app.imagesizex/2);
        app.spotfindingImage_rightside = white(:,(1+app.imagesizex/2):end);
      end
      % set background for spotfinding image
      if app.NumFramesToAvgSpotfindingBg.Value == 0
        app.spotfindingBackground_leftside = zeros(size(app.spotfindingImage_leftside));
        app.spotfindingBackground_rightside = zeros(size(app.spotfindingImage_rightside));
      else
        [white, green] = get_avg_frames(app,app.StartFrameSpotfindingBg.Value, ...
          app.NumFramesToAvgSpotfindingBg.Value);
        if app.RedCheckBox.Value == true
          [~, greenBG] = calculateBackground(app, app.blocksize, [green, white]);
          app.spotfindingBackground_leftside = greenBG(:,1:app.imagesizex/2);
          app.spotfindingBackground_rightside = greenBG(:,(1+app.imagesizex/2):end);
        elseif app.GreenCheckBox.Value == true
          [~, greenBG] = calculateBackground(app, app.blocksize, green);
          app.spotfindingBackground_leftside= greenBG(:,1:app.imagesizex/2);
          app.spotfindingBackground_rightside = greenBG(:,(1+app.imagesizex/2):end);
        elseif app.WhiteCheckBox.Value == true
          [~, whiteBG] = calculateBackground(app, app.blocksize, white);
          app.spotfindingBackground_leftside= whiteBG(:,1:app.imagesizex/2);
          app.spotfindingBackground_rightside = whiteBG(:,(1+app.imagesizex/2):end);
        end
      end
    end
    
    function updatedisplay(app)
      %plot current images
      if app.LiveCellModeCheckBox.Value == true && ~isempty(app.edgeMask_leftside)
        % display edges of cells 
        im = app.current_leftside;
        blackColor = 0;
        im(app.edgeMask_leftside) = blackColor;
        app.movieAxisLeftside.Children(end).CData = im;
        im = app.current_rightside;
        im(app.edgeMask_rightside) = blackColor;
        app.movieAxisRightside.Children(end).CData = im;
        
        %%% this works too but makes edges white. needs work. more
        %%% complicated.
        %             app.movieAxisLeftside.Children(end).CData = app.current_leftside;
        %             app.movieAxisRightside.Children(end).CData = app.current_rightside;
        %             alphaDataForEdges = logical(~app.edgeMask_rightside);
        %             app.movieAxisRightside.Children(end).AlphaData = alphaDataForEdges;
        
        
        %             hold(app.movieAxisRightside, "on");
        %             im = zeros(size(app.edgeMask_rightside));
        %             image(app.movieAxisRightside,im);
        %             app.movieAxisRightside.Children(end).AlphaData = alphaDataForEdges;
        %             hold(app.movieAxisRightside, "off");
        % app.movieAxisRightside.Children(end).AlphaData = app.edgeMask_rightside;
        %app.movieAxisRightside.Alphamap = app.edgeMask_rightside;
        

      else
        if app.SplitgreenwhitemoviesCheckBox.Value == true
          % written before switch back to app.imagesizex/2 as default
          if app.movieAxisLeftside.XLim(2) == 0.5+app.imagesizex && app.current_frame < (app.GreenLaserStartFrameForRGWEditField.Value +1)
            app.movieAxisLeftside.XLim(2) = 0.5+app.imagesizex/2;
            app.movieAxisRightside.XLim(2) = 0.5+app.imagesizex/2;
          elseif app.movieAxisLeftside.XLim(2) == 0.5+app.imagesizex/2 && app.current_frame >= (app.GreenLaserStartFrameForRGWEditField.Value +1)
            app.movieAxisLeftside.XLim(2) = 0.5+app.imagesizex;
            app.movieAxisRightside.XLim(2) = 0.5+app.imagesizex;
          end
        end
        app.movieAxisLeftside.Children(end).CData = app.current_leftside;
        app.movieAxisRightside.Children(end).CData = app.current_rightside;
      end

      % Plot spots and display info
      if app.keep_spots_displayed == true
        plotspots(app)
      end
      updateTextInfo(app);
      drawnow;
    end
    
    function updateTextInfo(app)
%       app.InfoTextArea.FontColor = 'k';
%       app.InfoTextArea.FontSize = 12;
      frameNumberStatus = ['Frame: ' num2str(app.current_frame) ' of ' num2str(app.NumFrames)];
      if app.keep_spots_displayed == true
        % update display info
        numOfMoleculesText = ['# of Molecules: ',num2str(app.num_spots)];
        thresholdText = ['Threshold: ' num2str(app.ThresholdEditField.Value)];
        if isempty(app.f_map_forDisplayInTextArea) || app.UseCurrentMappingCheckBox.Value == false
          date = "Date of Last Mapping: " + datestr(now,2);
          side = "Whichside: " + char(app.whichside);
          offset = "Offset (x,y): " + char(num2str(app.xoffset)) + " " + char(num2str(app.yoffset));
          fmaptitle = "Fmap: ";
          formatSpec = '%.4f';
          fmap1 = char(num2str(app.f_map(1,1),formatSpec)) + " " + char(num2str(app.f_map(1,2),formatSpec)) + " " + char(num2str(app.f_map(1,3),formatSpec));
          fmap2 = char(num2str(app.f_map(2,1),formatSpec)) + " " + char(num2str(app.f_map(2,2),formatSpec)) + " " + char(num2str(app.f_map(2,3),formatSpec));
          fmap3 = char(num2str(app.f_map(3,1),formatSpec)) + " " + char(num2str(app.f_map(3,2),formatSpec)) + " " + char(num2str(app.f_map(3,3),formatSpec));
          app.f_map_forDisplayInTextArea = [date , side, offset, fmaptitle, fmap1, fmap2, fmap3];
        end
        if app.LiveCellModeCheckBox.Value == true
          % see if the ~isempty statement is necessary
          %if ~isempty(app.newSpotsText)
          app.InfoTextArea.Value = [app.newSpotsText, app.filename, frameNumberStatus, numOfMoleculesText, app.numSpotsInCellText, ...
            app.numSpotsNotInACellText, thresholdText, app.f_map_forDisplayInTextArea];
          %else
          %app.InfoTextArea.Value = [app.filename, frameNumberStatus, numOfMoleculesText, app.numSpotsNotInACellText, ...
          %thresholdText, app.f_map_forDisplayInTextArea];
          %end
        else
          app.InfoTextArea.Value = [app.filename, frameNumberStatus, numOfMoleculesText, thresholdText, app.f_map_forDisplayInTextArea];
        end
      else
        app.InfoTextArea.Value = [app.filename, frameNumberStatus, app.f_map_forDisplayInTextArea];
      end
    end
    
    function updatecontrastlimits(app)
      
      low1=min(min(app.current_rightside));
      low1 = low1(1);
      high1=max(max(app.current_rightside));
      high1 = high1(1);
      low1=min([40, low1, high1]);
      high1=ceil(high1*2);
      
      low2=min(min(app.current_leftside));
      low2 = low2(1);
      high2=max(max(app.current_leftside));
      high2 = high2(1);
      low2=min([40, low2, high2]);
      high2=ceil(high2*2);
      
      app.movieAxisRightside.CLim = [low1 high1];
      app.movieAxisLeftside.CLim = [low2 high2];
    end
    
    function loadLastMapping(app)
      if isfile("lastmapping.mat")
        % get date and time last mapping was saved
        FileInfo = dir("lastmapping.mat");
        datemappingwasmade = FileInfo.date;
        dateText = "Date of Last Mapping: " + datestr(datemappingwasmade,2);
        % load last mapping
        load lastmapping.mat f x_offset y_offset whatside
        app.xoffset = x_offset;
        app.yoffset = y_offset;
        app.f_map = f;
        app.whichside = whatside;
        % check that this works
        app.WhichSideDropDown.Value = whatside;
        app.UseCurrentMappingCheckBox.Enable = "on";
        side = "Whichside: " + char(whatside);
        offset = "Offset (x,y): " + char(num2str(app.xoffset)) + " " + char(num2str(app.yoffset));
        fmaptitle = "Fmap: ";
        formatSpec = '%.4f';
        fmap1 = char(num2str(app.f_map(1,1),formatSpec)) + " " + char(num2str(app.f_map(1,2),formatSpec)) + " " + char(num2str(app.f_map(1,3),formatSpec));
        fmap2 = char(num2str(app.f_map(2,1),formatSpec)) + " " + char(num2str(app.f_map(2,2),formatSpec)) + " " + char(num2str(app.f_map(2,3),formatSpec));
        fmap3 = char(num2str(app.f_map(3,1),formatSpec)) + " " + char(num2str(app.f_map(3,2),formatSpec)) + " " + char(num2str(app.f_map(3,3),formatSpec));
        app.f_map_forDisplayInTextArea = [dateText, side, offset, fmaptitle, fmap1, fmap2, fmap3];
        app.UseCurrentMappingCheckBox.Value = true;
      else
        app.InfoTextArea.Value = "No previous mapping found";
      end
    end
    
    function plotspots(app)
      % delete old points
      delete(findobj(app.movieAxisLeftside, 'Type', 'line'));
      delete(findobj(app.movieAxisRightside, 'Type', 'line'));
      % plot new points
      line(app.movieAxisLeftside,app.x_spots_leftside,app.y_spots_leftside,'linestyle','none','marker','o','color',app.leftspotcolor,"Tag",'Left');
      line(app.movieAxisRightside,app.x_spots_rightside,app.y_spots_rightside,'linestyle','none','marker','o','color',app.rightspotcolor,"Tag",'Right');
      if app.OverlayImagesButton.Value == 1
        % rewrite this part
        %line(app.ImageAxesOneside,leftx,lefty,'linestyle','none','marker','o','color',app.leftspotcolor,"Tag",'Left');
        %line(app.ImageAxesOneside,rightx,righty,'linestyle','none','marker','o','color',app.rightspotcolor,"Tag",'Right');
      end
    end
    
    function Play_Movie(app)
      
      movie_on = app.PlayMovieButton.Value;
      slowMovie = app.PlaybackSpeedCheckBox.Value;
      speed = app.PlaybackSpeedEditField.Value;
      %           updateIntensityInfo = app.holdClickedSpot;
      %           if updateIntensityInfo == true
      %             x = app.current_point(1);
      %             y = app.current_point(2);
      %           end
      %% uncomment if needed. explanation is further down in this function
      %showspots = app.keep_spots_displayed;
      
      
      if app.LiveCellModeCheckBox.Value == true
        leftEdges = app.edgeMask_leftside;
        rightEdges = app.edgeMask_rightside;
        showEdges = true;
      else
        showEdges = false;
      end
      
      %redo fopen - doesn't appear to be necessary
      %app.FileID = fopen(app.filename);
      
      %play the movie
      idx     = app.current_frame;
      if app.SplitgreenwhitemoviesCheckBox.Value == true
        addToIndex = 2;
      else
        addToIndex = 1;
      end
      nframes = app.NumFrames;
      loopAroundBack = false;
      if app.PlayonLoopCheckBox.Value == false
        lastFrame = app.NumFrames;
        firstFrame = 0;
      else
        lastFrame = app.playbackEndFrame.Value;
        firstFrame = app.playbackStartFrame.Value - 1;
        if firstFrame > lastFrame
          loopAroundBack = true;
        end
      end
      
      
      while movie_on == true
        % to keep track of playback speen
        tStart = tic;
        
        %get the current position of the slider
        k = floor(app.Frameslider.Value);
        
        %if the slider has moved, set the index to the current slider
        %position
        if k ~= idx
          idx = k;
        end
        
        %loop back if we reach nframes
        if loopAroundBack == true
          if idx > nframes-1
            idx = 0;
          end
          if idx > lastFrame-1 && idx < firstFrame
            idx = firstFrame;
          end
        elseif idx > lastFrame-1
          idx = firstFrame;
        end
        
        %increment frames
        idx = idx + addToIndex;
        app.Frameslider.Value = idx;
        app.current_frame = idx;
        % update frame info text area
        app.FrameNumberEditField.Value = "Frame: " + num2str(idx);


        %be sure we're still playing the movie
        movie_on = app.PlayMovieButton.Value;
        
        %get the images data from the file
        [temp_right, temp_left] =  get_avg_frames(app, idx, 1);
        
        % show edges of cells if in live cell mode
        if showEdges == true
          temp_left(leftEdges) = 0;
          temp_right(rightEdges) = 0;
        end
        % if there is only one CData, then the (end) part might
        % mess it up. In that case, use app.movieAxisLeftside.Children.CData = temp_left;
        % seems to work even if there is only one CData though.
        app.movieAxisLeftside.Children(end).CData = temp_left;
        app.movieAxisRightside.Children(end).CData = temp_right;

        if slowMovie == true
          pause(speed)
        else
          app.PlaybackSpeedEditField.Value = toc(tStart);
        end
        
        drawnow; %% this updates images and also processes any pending callbacks
      end % end while movie is on
      app.current_frame = idx;
      app.Frameslider.Value = idx;
      app.FrameNumberEditField.Value = "Frame: " + num2str(app.current_frame);
      [current_right, current_left] = get_avg_frames(app, idx,1);
      app.current_rightside = current_right;
      app.current_leftside = current_left;
    end


    %% Spot Finding Functions
    function find_offset(app)
      %% function copied and pasted from complexingui2.m
      donor = app.spotfindingImage_leftside(20:(end-20),20:(end-20));
      acceptor = app.spotfindingImage_rightside(10:(end-10),10:(end-10));
      cc = normxcorr2(donor,acceptor);
      %    temp=cc(100:200,40:80);
      [max_cc, imax] = max(cc(:));
      [ypeak, xpeak] = ind2sub(size(cc),imax(1));
      %     [max_cc, imax] = max(temp(:));
      %     [ypeak, xpeak] = ind2sub(size(temp),imax(1));
      %     ypeak = ypeak + 99;
      %     xpeak = xpeak + 39;
      y_offset=ypeak-size(donor,1)-10;
      x_offset=xpeak-size(donor,2)-10;
      if abs(x_offset) > app.OffsetLimitSpinner.Value || abs(y_offset) > app.OffsetLimitSpinner.Value
        y_offset=0;
        x_offset=0;
      end
      app.xoffset=x_offset;
      app.yoffset=y_offset;
    end
    
    function [offsetx, offsety, temp_image_originalside, temp_image_movingside] = setoriginalandmovingpoints(app, spotImageLeft, spotImageRight, bgLeft, bgRight)
      % sets original & moving points. Modifies offset for rightside spot finding.
      if string(app.InvertSwitch.Value) == "Invert"
        %inverted image because beads are lower intensity than background
        temp_wholeImage_bg = [bgLeft, bgRight];
        temp_wholeImage = [spotImageLeft, spotImageRight];
        temp_wholeImage = bsxfun(@times, temp_wholeImage_bg, 2) - temp_wholeImage;    %strange way to mult. all elements by constant
        %subtract out the background from the current image
        spotImageLeft = temp_wholeImage(:, 1:app.imagesizex/2);
        spotImageRight = temp_wholeImage(:,(1+app.imagesizex/2):end);
      end
      if app.whichside == "both"
        temp_originalside_bg = [bgLeft, bgRight];
        temp_image_originalside = [spotImageLeft, spotImageRight] - temp_originalside_bg;
        temp_image_movingside = temp_image_originalside;
        offsetx = 0;
        offsety = 0;
        app.xoffset = 0;
        app.yoffset = 0;
      elseif app.whichside == "right"
        temp_image_originalside = spotImageRight - bgRight;
        temp_image_movingside = spotImageLeft - bgLeft;
        offsetx = app.xoffset*-1;
        offsety = app.yoffset*-1;
      elseif app.whichside == "left"
        temp_image_originalside = spotImageLeft - bgLeft;
        temp_image_movingside = spotImageRight - bgRight;
        offsetx = app.xoffset;
        offsety = app.yoffset;
      end
    end
    
    function [original_points, moving_points] = findspots(app, offsetx, offsety, temp_image_originalside, temp_image_movingside)
      
      % get image size
      imageSize = size(temp_image_originalside); % imageSize(1) = num rows, imageSize(2) = num columns
      
      % define threshold
      if app.HOLDCheckBox.Value == true
          threshold = app.ThresholdEditField.Value;
          app.ThresholdEditField.Value = threshold;
      else 
           threshold = abs(mean2(temp_image_originalside)) + 2*std2(temp_image_originalside);
        app.ThresholdEditField.Value = round(threshold,3);

      end
      
 
     %% if threshold == 0
        %%% suggestion: why not just do:
        %% if that doesn't work, uncomment below
        %               if app.SpotTypeDropDown.Value == "Random"
        %                 threshold = 4+7*std2(temp_originalside_bg(1+3:imageSize(1)-3,1+3:imageSize(2)-3));
        %               elseif app.cameratype == "Twin Prime"
        %                 threshold = 40*std2(temp_originalside_bg(1+3:imageSize(1)-3,1+3:imageSize(2)-3));
        %               elseif app.cameratype == "Evolve"
        %                 threshold = 15*std2(temp_originalside_bg(1+3:imageSize(1)-3,1+3:imageSize(2)-3));
        %               else
        %                 threshold = 7*std2(temp_originalside_bg(1+3:imageSize(1)-3,1+3:imageSize(2)-3)); %why not 1+3 = 4?
        %               end
     %%
   %% end
      
      
      % Pick Spots
      % look through all pixels in image and find the ones that are
      % greater than the threshold and are a local max
      areaForLocalMax = floor(app.AreatoSearchforLocalMaximumsSpinner.Value/2); % area for finding local max
      distanceAwayEdges = app.DistanceAwayFromEdgesEditField.Value;
      tempy = zeros(imageSize(1)-distanceAwayEdges,1);
      tempx = zeros(imageSize(2)-distanceAwayEdges,1);
      m_number = 0;
      if app.SpotTypeDropDown.Value == "Single Molecule"
        %Find spots that are greater than threshold and are local max. Store x and y pixel value in tempx and tempy.
        for i=1+distanceAwayEdges:imageSize(1)-distanceAwayEdges   % i goes across all rows
          for j=1+distanceAwayEdges:imageSize(2)-distanceAwayEdges % j goes across columns
            if temp_image_originalside(i,j)>threshold && temp_image_originalside(i,j)...
                == max(max(temp_image_originalside(i-areaForLocalMax:i+areaForLocalMax,j-areaForLocalMax:j+areaForLocalMax)))
              m_number=m_number+1;
              tempx(m_number)=j; %% x location of molecule (column)
              tempy(m_number)=i; %% y location of molecule (row)
            end
            
          end
        end
        if m_number == 0
          app.InfoTextArea.FontColor = 'red';
          app.InfoTextArea.FontSize = 16;
          app.InfoTextArea.Value = ['No spots found.'...
            newline 'Raise or lower threshold and try again.'];
          original_points = [];
          moving_points = [];
          return
        end
        tempy(tempy(:) == 0) = [];
        tempx(tempx(:) == 0) = [];
      elseif app.SpotTypeDropDown.Value == "Random"
        [tempy, tempx, m_number] = pick_random_spots(app,imageSize,temp_image_originalside,threshold, areaForLocalMax, [], distanceAwayEdges);
      end
      
      
      % find spots that are too close together
      if app.SpotTypeDropDown.Value ~= "Random"
      nn = app.NearestNeighborEditField.Value;
      overlap=zeros(1,m_number);
      for i=1:m_number
        for j=i+1:m_number
          if tempy(j)-tempy(i) > nn   %nn defined at start
            break;
          end
          if (tempx(i)-tempx(j))^2+(tempy(i)-tempy(j))^2 < nn^2
            %if too close, pick the brighter spot
            %                       if temp_image_originalside(tempy(i),tempx(i)) > temp_image_originalside(tempy(j),tempx(j))
            %                         overlap(j)=1;
            %                       else
            %                         overlap(i)=1;
            %                       end
            % if too close, get rid of both
            overlap(i)=1;
            overlap(j)=1;
          end
        end
      end
      % remove the overlapping spots
      j = 0;
      for i=1:m_number %loop on all molecues
        if overlap(i)==0  %if not overlapping
          j=j+1;          %j counts good molecules
          original_points(j,1) = tempx(i); % x(2*j-1)=tempx(i);
          original_points(j,2) = tempy(i); % y(2*j-1)=tempy(i);
          %apply offset
          moving_points(j,1) = tempx(i)+ offsetx;  % x(2*j)=tempx(i)+result(2)/2+handles.xoffset;
          moving_points(j,2) = tempy(i)+ offsety; % y(2*j)=tempy(i)+handles.yoffset
          %find local maximum
          temp = temp_image_movingside(max(1,moving_points(j,2)-1):min((moving_points(j,2)+1),imageSize(1)), ...
            max(1,(moving_points(j,1)-1)):min(imageSize(2),(moving_points(j,1)+1))); % temp=image(y(2*j)-1:y(2*j)+1,x(2*j)-1:x(2*j)+1);
          [maxy, maxx] = find(temp == max(max(temp)),1);
%           maxy = maxy(1);
%           maxx = maxx(1); % just in case two values are the same %
%           changed by adding ,1 to the find function above
          moving_points(j,1) = moving_points(j,1)+maxx-2;  % x(2*j)=x(2*j)+maxx-2;
          moving_points(j,2) = moving_points(j,2)+maxy-2;  % y(2*j)=y(2*j)+maxy-2;
        end
      end
      % store num spots
      app.num_spots = j;
      else
        original_points(:,1) = tempx; % x(2*j-1)=tempx(i);
        original_points(:,2) = tempy; % y(2*j-1)=tempy(i);
        moving_points(:,1) = tempx+ offsetx;  % x(2*j)=tempx(i)+result(2)/2+handles.xoffset;
        moving_points(:,2) = tempy+ offsety;
        % store num spots
      app.num_spots = m_number; % if picking random spots, skip the overlap steps
        
      end
      
      
      % Determine mapping function
      if app.UseCurrentMappingCheckBox.Value == true
        f = app.f_map;
      elseif app.whichside == "both"
        f = 1;
      elseif j > 3
        tform = cp2tform(moving_points,original_points,'affine');  % tform = cp2tform(acceptor_points,donor_points,'affine');
        f = tform.tdata.Tinv;
        app.SaveMappingButton.Enable = true;
        app.UseCurrentMappingCheckBox.Enable = true;
      elseif j <= 3 % cp2tform won't work if j< 3
        app.InfoTextArea.FontColor = 'red';
        app.InfoTextArea.FontSize = 16;
        app.InfoTextArea.Value = ['Warning:' newline 'No spots found.'...
          newline 'Change threshold and try again.'];
        return
      end
      app.InfoTextArea.FontColor = 'k';
      app.InfoTextArea.FontSize = 12;
      app.f_map = f;
      
      
      % Apply mapping funciton
      for i=1:app.num_spots
        % map moving points from original points using fmap
        points = [original_points(i,1), original_points(i,2), 1]*app.f_map;  % points=[x(2*i-1) y(2*i-1) 1]*handles.f;
        points = round(points);
        moving_points(i,:) = [points(1) points(2)];
      end
      
      % keep spots displayed until new movie is loaded
      app.keep_spots_displayed = true;
      
    end
    
    function storespotsbasedonwhichside(app,original_points,moving_points)
      if app.whichside == "both" %then original points = moving points
        x_points = original_points(:,1);
        y_points = original_points(:,2);
        app.x_spots_leftside = x_points(x_points(:) <= app.imagesizex/2);
        app.x_spots_rightside = x_points(x_points(:) > app.imagesizex/2) - app.imagesizex/2;
        app.y_spots_leftside = y_points(x_points(:) <= app.imagesizex/2);
        app.y_spots_rightside = y_points(x_points(:) > app.imagesizex/2);
      elseif app.whichside == "right"
        app.x_spots_leftside = moving_points(:,1);
        app.x_spots_rightside = original_points(:,1);
        app.y_spots_leftside = moving_points(:,2);
        app.y_spots_rightside = original_points(:,2);
        if app.RedWhiteGreenAltModeCheckBox.Value == true
          
        end
      elseif app.whichside == "left"
        app.x_spots_leftside = original_points(:,1);
        app.x_spots_rightside = moving_points(:,1);
        app.y_spots_leftside = original_points(:,2);
        app.y_spots_rightside = moving_points(:,2);
      end
    end
    
    function [tempy, tempx, m_number ] = pick_random_spots(~, imageSize, temp_image_originalside, threshold, areaForLocalMax, nspots, distanceAwayEdges)
      if isempty(nspots)
        prompt = {'Number of Random Spots to pick:'};
        dlgtitle = 'Number of Random Spots';
        dims = [1 35];
        definput = {'100'};
        answer = inputdlg(prompt,dlgtitle,dims,definput);
        nspots = str2double(answer{1});
      end
      tempy = zeros(nspots,1);
      tempx = zeros(nspots,1);
      m_number=0;
      for i=1:nspots
%         c=rand(1,2);
%         y = round((imageSize(1)-areaForLocalMax-(1+areaForLocalMax))*c(1)/(1-0)+(1+areaForLocalMax));
%         x = round((imageSize(2)-areaForLocalMax-(1+areaForLocalMax))*c(2)/(1-0)+(1+areaForLocalMax));
%         
%         yinterval = (areaForLocalMax+1):(imageSize(1) - areaForLocalMax - 1);
%         xinterval = (areaForLocalMax+1):(imageSize(2) - areaForLocalMax - 1);
        
        y = randi([(distanceAwayEdges+1),(imageSize(1) - distanceAwayEdges - 1)],1);
        x = randi([(distanceAwayEdges+1),(imageSize(2) - distanceAwayEdges - 1)],1);
        
     
        %why not just do this?:
        %y=round((imageSize(1)-7)*c(1)+4);
        %x=round((imageSize(2)-7)*c(2)+4);
        % picks points that are not single molecule spots
        if max(max(temp_image_originalside(y-areaForLocalMax:y+areaForLocalMax,x-areaForLocalMax:x+areaForLocalMax))) < threshold
          m_number = m_number + 1;
          tempy(m_number) = y;
          tempx(m_number) = x;
        end
      end
      tempy(tempy(:) == 0) = [];
      tempx(tempx(:) == 0) = [];
    end
    
   
    function betterImage = enhanceImageForFindingCells(app,actualImage)
%       if app.InvertCheckBox.Value == true
%       actualImage = actualImage*-1;
%             minImage = min(min(actualImage));
%       actualImage = actualImage./minImage;
%       actualImage =  exp(actualImage);
%             se = strel('disk',2);
%       imB2 = imtophat(actualImage,se);
%       se = strel('disk',3);
%       imB = imtophat(actualImage,se);
%       se = strel('disk',4);
%       imB4 = imtophat(actualImage,se);
%       betterImage = imB2 + imB + imB4;
%       
%       end
%       actualImage = actualImage.^3;

%       avgImage = mean2(actualImage);
%       stdImage = std2(actualImage);
%       actualImage = (actualImage - avgImage)./stdImage;

%             if app.InvertCheckBox.Value == true
%                                   minImage = min(min(actualImage));
%       actualImage = actualImage./minImage;
%       actualImage =  exp(actualImage);
% %actualImage = actualImage*-1;
%             se = strel('disk',2);
%       imB2 = imtophat(actualImage,se);
%       se = strel('disk',3);
%       imB = imtophat(actualImage,se);%imtophat = actualImage - imopen; imopen = erosion, dilation
%       se = strel('disk',4);
%       imB4 = imtophat(actualImage,se);
%       betterImage = imB2 + imB + imB4;
%       figure
%       imshow(betterImage)
%       impixelinfo
%       
%             else
testing = false;
%testing = true;
if testing == false
      if app.InvertCheckBox.Value == true
        actualImage = actualImage*-1;
      end
      minImage = min(min(actualImage));
      actualImage = actualImage./minImage;
      actualImage =  exp(actualImage);
      se = strel('disk',2);
      imB2 = imbothat(actualImage,se);%imbohat = actualImage - imclose; imclose = dilation, erosion
      se = strel('disk',3);
      imB = imbothat(actualImage,se);
      se = strel('disk',4);
      imB4 = imbothat(actualImage,se);
      betterImage = imB2 + imB + imB4;
% 
%             end
else

      originalImage = actualImage;
      if app.InvertCheckBox.Value == true
        actualImage = actualImage*-1;
      end

%       minImage = min(min(actualImage));
%       actualImage = actualImage./minImage;
%       actualImage =  exp(actualImage);
%       figure
%       image(actualImage, 'CDataMapping',"scaled");
%       impixelinfo;
      % remove the outer edges - they have high intensities and mess up the
      % rest of edge finding
      edgeDistance = app.DistanceAwayFromEdgesEditField.Value;
      imageEdges = zeros(size(actualImage), 'logical');
      imageEdges(1:edgeDistance,:) = 1; imageEdges(:, 1:edgeDistance) = 1;
      imageEdges((end-edgeDistance):end,:) = 1; imageEdges(:, (end-edgeDistance):end) = 1;
      % modify bad edges contd. 
      %actualImage(imageEdges) = meanImage;
%       edgePoints = originalImage(imageEdges);
%       % plot edges
%       figure
%       h2 = histogram(edgePoints);
%       legend;  
%       hold on
%       pointsWithoutEdges = originalImage(~imageEdges);
%       pointsWithoutEdges = reshape(pointsWithoutEdges, 1, []);
%       h3 = histogram(pointsWithoutEdges);
%       h2.BinWidth = h3.BinWidth;
%       h3.FaceColor = 'red'; h3.FaceAlpha = 0.3;
%       h2.FaceColor = 'blue'; h2.FaceAlpha = 0.3;
      
%       % instead of averaging, try adding..
%       im = zeros(app.imagesizey, app.imagesizex/2);
%       for i = 1:15
%         [imageRight, imageLeft] = get_avg_frames(app,i,1);
%         im = im + imageLeft;
%       end
%       originalImage = im; %no difference


      originalImage2 = originalImage(edgeDistance:(end-edgeDistance), edgeDistance:(end-edgeDistance));
      topLim = mean2(originalImage2) + 5*std2(originalImage2);
      botLim = mean2(originalImage2) - 5*std2(originalImage2);
      
      newIm = findEdges(originalImage2);
      
      
      p = [102,609,495,724];
      figure("Position", p); p(1) = p(1) + p(3);
      im = image(originalImage2, 'CDataMapping', 'scaled');
      im.Parent.DataAspectRatio = [1,1,1];
      impixelinfo;

      
%       % to get rid of the weird spots on the camera lense
%       bw = zeros(size(originalImage2), 'logical');
%       for i = 1:size(bw,1)
%         for j = 1:size(bw,2)
%           if originalImage2(i,j) > topLim || originalImage2(i,j) < botLim
%           bw(i,j) = true;
%           else
%             bw(i,j) = false;
%           end
%           
%         end
%       end
%       figure;
%       imshow(bw);
      
      % no difference
%       medianFillForSpotsOnCamera = median(originalImage2,'all');
%       originalImage2(bw) = medianFillForSpotsOnCamera;
%       figure("Position", p); p(1) = p(1) + p(3);
%       im = image(originalImage2, 'CDataMapping', 'scaled');
%       im.Parent.DataAspectRatio = [1,1,1];
%       impixelinfo;


%       4523.266666666666,7032.533333333334
%       
%       0,2369.333333333333


    se =  offsetstrel('ball',8,8);
     %se = strel('disk',8);
     background = imopen(originalImage2,se);
     figure("Position", p); p(1) = p(1) + p(3);
      im = image(background, 'CDataMapping', 'scaled');
      im.Parent.DataAspectRatio = [1,1,1];
      impixelinfo;
      
      % try just subtracting the median of the image as the bg.
      
      bgSubtractedImage = originalImage2 - background;
      figure("Position", p); p(1) = p(1) + p(3);
      im = image(bgSubtractedImage, 'CDataMapping', 'scaled');
      im.Parent.DataAspectRatio = [1,1,1];
      impixelinfo;
      
      BW_logEdges = edge(bgSubtractedImage, 'log');
      BW_onlyKeepMajority = bwmorph(BW_logEdges,'majority',3);
      figure;
      montage({BW_logEdges,BW_onlyKeepMajority});
      
      BW_cannyEdges = edge(bgSubtractedImage, 'Canny');
      BW_S = edge(bgSubtractedImage, 'Sobel');
      BW_P = edge(bgSubtractedImage, 'Prewitt');
      BW_R = edge(bgSubtractedImage, 'Roberts');
      figure;
      montage({BW_cannyEdges, BW_S, BW_P, BW_R})
      
app.DetectCellEdgesButton.Value = false;

% windowsize = 2;
% for i = 1:size(originalImage2,1)
%   for j = 1:size(originalImage2,2)
%     
%   end
% end


      nhood = diag(ones(2,1));
      nhood2 = [0,0,1;0,1,0;0,0,0];
      nhood3 = [0,0,1;0,1,0;inf, inf, inf];
      a = offsetstrel('ball',2,2,0);
      q = a.Offset; q(3,3) = 4;
      se = {strel(nhood), strel(nhood2), offsetstrel('ball',1,3,2), offsetstrel('ball',1,2,2), offsetstrel('ball',2,2,0), ...
        offsetstrel(q), offsetstrel('ball',1,2,0), offsetstrel('ball',2,4,0), offsetstrel(nhood), offsetstrel(nhood3)};
      imDialate = cell(length(se),1);
      imErode = cell(length(se),1);
      imOpen = cell(length(se),1);
      imClose = cell(length(se),1);
      maximDialate = zeros(length(se),2);
      maximErode = zeros(length(se),2);
      maximOpen = zeros(length(se),2);
      maximClose = zeros(length(se),2);
      figure("Name", "se's");
      montage(se);
      for i = 1:length(se)
        % dialate then erode = imclose
        imDialate{i} = imdilate(actualImage,se{1,i}); % dialate = pixel = max pixel in nhood
        imClose{i} = imerode(imDialate{i},se{1,i});
        %imlog{i} = edge(imClose{i}, 'log');

        
        % erode then dialate = imopen
        imErode{i} = imerode(actualImage,se{1,i}); % erode:min pixel in nhood
        imOpen{i} = imdilate(imErode{i},se{1,i});
        %imlog2{i} = edge(imOpen{i}, 'log');
        
        %maximDialate(i,1) = max(max(max(imDialate{i+length(v)})),max(max(imDialate{i}))); maximDialate(i,2) = min(min(min(imDialate{i})),min(min(imDialate{i+length(v)})));
        maximDialate(i,1) = max(max(imDialate{i})); maximDialate(i,2) = min(min(imDialate{i}));
        maximErode(i,1) = max(max(imErode{i})); maximErode(i,2) = min(min(imErode{i}));
        maximOpen(i,1) = max(max(imOpen{i})); maximOpen(i,2) = min(min(imOpen{i}));
        maximClose(i,1) = max(max(imClose{i})); maximClose(i,2) = min(min(imClose{i}));
        %maximClose(i,1) = max(max(max(imClose{i+length(v)})),max(max(imClose{i}))); maximClose(i,2) = min(min(min(imClose{i})),min(min(imClose{i+length(v)})));
        
      end
      a = 4;
      imExtras = cell(a,1);
%       imExtras{1} = imDialate{1} + imDialate{2};
%       imExtras{2} = imErode{1} + imErode{2};
%       %imExtras{3} = imDialate{1} + imErode{1}; %no
%       %imExtras{4} = imDialate{1} + imErode{2}; %no
%       
% %       imExtras{3} = imdilate(imDialate{1},se(2));
% %       imExtras{4} = imerode(imErode{1},se(2));
%       
%       %imExtras{5} = imdilate(imErode{1},se{1,1}); %no
%       imExtras{6} = imerode(imDialate{1},se{1,1});
%       %imExtras{7} = imdilate(imErode{1},se{1,2}); %no
%       %imExtras{8} = imerode(imDialate{1},se{1,2}); %no
%       imExtras{9} = imOpen{1} + imClose{1};
      
      imExtras{1} = imDialate{4} + imDialate{5};
      imExtras{2} = imErode{4} + imErode{5};
      imExtras{3} = imClose{4} + imClose{5};
      imExtras{4} = imExtras{3}./2;
      
      % to normalize the montage colors for debugging
      maxExtras = zeros(a,1); minExtras = zeros(a,1);
      for i = 1:a
        maxExtras(i) = max(max(imExtras{i}));
        minExtras(i) = min(min(imExtras{i}));
        imExtras2{i}  = (imExtras{i} - min(min(imExtras{i})))./(max(max(imExtras{i})) - min(min(imExtras{i})));
      end
      % to normalize the montage colors for debugging
      for i = 1:length(se)
        imDialate2{i}  = (imDialate{i} - min(min(imDialate{i})))./(max(max(imDialate{i})) - min(min(imDialate{i})));
        imErode2{i}  = (imErode{i} - min(min(imErode{i})))./(max(max(imErode{i})) - min(min(imErode{i})));
        imOpen2{i}  = (imOpen{i} - min(min(imOpen{i})))./(max(max(imOpen{i})) - min(min(imOpen{i})));
        imClose2{i}  = (imClose{i} - min(min(imClose{i})))./(max(max(imClose{i})) - min(min(imClose{i})));
      end
        
      figure("Name", "dialate: max pixel in nhood");
      w = montage(imDialate2);
      colormap('parula');
      %w.Parent.CLim = [min(maximDialate(:,2)), max(maximDialate(:,1))];
      impixelinfo; colorbar;
            figure("Name", "erode: min pixel in nhood");
      w = montage(imErode2);
      colormap('parula');
      %w.Parent.CLim = [min(maximErode(:,2)), max(maximErode(:,1))];
      impixelinfo; colorbar;
            figure("Name", "imclose = dilation, erosion");
      w = montage(imClose2);
      colormap('parula');
     %w.Parent.CLim = [min(maximClose(:,2)), max(maximClose(:,1))];
      impixelinfo; colorbar;
            figure("Name", "imopen = erosion, dilation");
      w = montage(imOpen2);
      colormap('parula');
      %w.Parent.CLim = [min(maximOpen(:,2)), max(maximOpen(:,1))];
      impixelinfo; colorbar;
      figure("Name", "extras");
      w = montage(imExtras2);
      colormap('parula');
      %w.Parent.CLim = [min(minExtras), max(maxExtras)];
      impixelinfo; colorbar;
      
      betterImage = imExtras{4};
      
%       imlog2 = cell(4,1);  maxC = 1; minC = 10;
%       for i = 1:length(im)
% 
%         minim = min(min(im{i}));
%         im{i} = im{i}./minim;
%         
%         im{i} = exp(im{i});
%         imlog2{i} = edge(im{i}, 'log');
%         
%         maxim = max(max(im{i}));
%         maxC = max(maxC,maxim);
%         minim = min(min(im{i}));
%         minC = min(minC, minim);
%       end
%       figure
%       w = montage(im);
%       colormap('parula');
%       w.Parent.CLim = [minC, maxC];
%       impixelinfo; colorbar;
      
%       figure("Name", "imLog on imClose");
%       montage(imlog);
%       impixelinfo; colorbar;
%       figure("Name", "imLog on imOpen");
%       montage(imlog2);
%       impixelinfo; colorbar;
      app.DetectCellEdgesButton.Value = false;
      disp('need to do montage instead to see images better')
      
      %possible good ones: offsetstrel('ball',2,4,0)
      % mostly, imclose seems better than imopen
      
      % to check if it works, type this into command line
       [logEdgesResult, LabeledImage] = findEdgesOfCells(app, enhanceImage, minArea)
       
       imagesToCheck = {imDialate{8}, };
       n = size(imagesToCheck,1); logEdgesOfImages = cell(n,1); 
       for i = 1:n
         [logEdgesResult, LabeledImage] = findEdgesOfCells(app, imagesToCheck{i}, minArea);
         logEdgesOfImages{i} = logEdgesResult;
       end
       figure("Name", "logEdgesResults")
       montage(logEdgesOfImages);

end

    end
    
    function [cellmap, LabeledImage, cellInfo] = findEdgesOfCells(app, enhanceImage, minArea)
      %%% filters to find the edges
      BW_logEdges = edge(enhanceImage, 'log');

      if app.ThickerEdgesCheckBox.Value == true
        BW_bridgedGaps = bwmorph(BW_logEdges, 'bridge');
        BW_filledHoles = imfill(BW_bridgedGaps, 'holes');
        BW_onlyKeepMajority = bwmorph(BW_filledHoles,'majority',3);
        switch app.ThicknessSpinner.Value
          case 0
            BW_thickenCells = BW_onlyKeepMajority;
          otherwise
            BW_thickenCells = bwmorph(BW_onlyKeepMajority, 'thicken',app.ThicknessSpinner.Value);
        end

%         figure
%         montage({enhanceImage, BW_logEdges, BW_bridgedGaps, BW_filledHoles, BW_onlyKeepMajority, BW_thickenCells});
%         impixelinfo
      elseif app.ThinnerEdgesCheckBox.Value == true
        % alternative, way to find edges. They end up looking less thick
        [~,Label,~,~] = bwboundaries(BW_logEdges);
        BW_logical = logical(Label);
        BW_onlyKeepMajority = bwmorph(BW_logical,'majority', 2);
        BW_thickenCells = bwmorph(BW_onlyKeepMajority, 'thicken',app.ThicknessSpinner.Value);
%         figure
%         montage({enhanceImage, BW_logEdges, Label, BW_logical, BW_onlyKeepMajority, BW_thickenCells});
%         impixelinfo
      end
      ConnectedComponents = bwconncomp(BW_thickenCells);
      L = labelmatrix(ConnectedComponents);
      %s = regionprops('table', L,'Area', 'PixelList', 'BoundingBox', 'Image', 'SubarrayIdx');
      s = regionprops('table', L,'Area', 'MajorAxisLength', 'MinorAxisLength',...
       'Eccentricity', 'Orientation', 'Centroid', 'BoundingBox', 'PixelList',...
       'Image', 'SubarrayIdx', 'Extrema');
      % bounding box: 
      % Position and size of the smallest box containing the region, 
      % returned as a 1-by-(2*Q) vector. The first Q elements are the 
      % coordinates of the minimum corner of the box. The second Q elements
      % are the size of the box along each dimension. For example, a 2-D 
      % bounding box with value [5.5 8.5 11 14] indicates that the (x,y) 
      % coordinate of the top-left corner of the box is (5.5, 8.5), 
      % the horizontal width of the box is 11 pixels, and the vertical 
      % height of the box is 14 pixels.
      
      %s = regionprops('table', app.cellMap_leftside,'Area', 'PixelList', 'BoundingBox', 'Circularity', 'Eccentricity', 'MajorAxisLength', 'MinorAxisLength', 'Perimeter', 'SubarrayIdx');
      % 'Circularity' % roundness of objects computed as
      % (4*Area*pi)/(Perimeter2). perfect circle = 1
      % 'Eccentricity' % the ratio of the distance between the foci of the ellipse and its major axis length
      % 'FilledArea' Number of on pixels in FilledImage, returned as a
      % scalar. (Not sure what the difference is by this and area)
      % 'MajorAxisLength'
      % 'MinorAxisLength'
      % 'Perimeter'
      % 'SubarrayIdx' - 	Elements of L inside the object bounding box, returned as a cell array that contains 
      % indices such that L(idx{:}) extracts the elements.
      % remove places with areas less than 40
      
      i = 1;
      while i <= size(s,1)
        if s.Area(i) <= minArea
          pixelLocations = cell2mat(s.PixelList(i));
          for j = 1:size(pixelLocations,1)
            L(pixelLocations(j,2),pixelLocations(j,1)) = 0;
          end
          s(i,:) = [];
          i = i-1;
        end
        i = i+1;
      end
      cellInfo = s;
      cellmap = L; %cellmap = BW_thickenCells;
      LabeledImage = labeloverlay(enhanceImage,L,'Transparency',0.3);
      LabeledImage = im2double(LabeledImage);
    end
    
    function [mask_otherside, labeledImage_otherside, cellMapOtherside, cellInfoOtherside] = createCellMaskOfOtherSide(app, imageToUse, multiplierForOffset, enhancedImageOtherside)
      
      cellMapOtherside = imageToUse;
      maxRowLength = size(cellMapOtherside,1);
      maxColumnLength = size(cellMapOtherside,2);
      BW_otherside = logical(cellMapOtherside);
      [row, col] = find(BW_otherside == true); % col = y, row = x

      
      pointsTransformed = [col, row, ones(length(row),1)]*app.f_map;
      BW_transformed = zeros(size(BW_otherside), 'logical');
      rows = round(pointsTransformed(:,2));
      columns = round(pointsTransformed(:,1));
      removeTheseNegativeRows = find(rows(:) <= 0 );
      if ~isempty(removeTheseNegativeRows) 
        rows(removeTheseNegativeRows) = [];
        columns(removeTheseNegativeRows) = [];
      end
      removeTheseTooBigRows = find(rows(:) > maxRowLength);
      if ~isempty(removeTheseTooBigRows)
        rows(removeTheseTooBigRows) = [];
        columns(removeTheseTooBigRows) = [];
      end
      removeTheseNegativeColumns = find(columns(:) <= 0 );
      if ~isempty(removeTheseNegativeColumns) 
        rows(removeTheseNegativeColumns) = [];
        columns(removeTheseNegativeColumns) = [];
      end
      removeTheseTooBigColumns = find(columns(:) > maxColumnLength);
      if ~isempty(removeTheseTooBigColumns)
        rows(removeTheseTooBigColumns) = [];
        columns(removeTheseTooBigColumns) = [];
      end
      for i = 1:length(rows)
        BW_transformed(rows(i), columns(i)) = true;
      end
      ConnectedComponents = bwconncomp(BW_transformed);
      L = labelmatrix(ConnectedComponents);
      %cellInfoOtherside = regionprops('table', L,'Area', 'PixelList', 'BoundingBox', 'Image', 'SubarrayIdx');
      cellInfoOtherside = regionprops('table',L,'Area', 'MajorAxisLength', 'MinorAxisLength',...
       'Eccentricity', 'Orientation', 'Centroid', 'BoundingBox', 'PixelList',...
       'Image', 'SubarrayIdx', 'Extrema');
      
      cellMapOtherside = L;
      BW = logical(cellMapOtherside);
      mask_otherside = edge(BW);
      
      % update labeledImage
      labeledImage_otherside = labeloverlay(enhancedImageOtherside, cellMapOtherside,'Transparency',0.3);
      labeledImage_otherside = im2double(labeledImage_otherside);
    end


    function bg_median = backgroundInCell_MedianMean(app, varargin) % regionForThisSpot, imageToUse, cellLocations)
      regionForThisSpot = varargin{1};
      imageToUse = varargin{2};
      cellLocations = varargin{3};
      % possibly add in some way to not use pixels that may be part of
      % another spot
      NumPixels = app.NumPixelsForSumIntensityEditField.Value; %% num pixels chosen for calculating intensity
      wholeImageButJustThisSpot = ones(size(cellLocations),'logical');
      for n = 1:NumPixels
        col = regionForThisSpot(n,2);
        row = regionForThisSpot(n,1);
        % set the pixels we chose for the spot to zero
        wholeImageButJustThisSpot(row,col) = 0;
        wholeImageButJustThisSpot(row,col) = 0;
      end
      boundary = bwperim(wholeImageButJustThisSpot,8); %returns a logical array that contains only the perimeter pixels
      % of objects in the input image BW. A pixel is part of the perimeter if it is
      % nonzero and it is connected to at least one zero-valued pixel
      % it also finds the perimeter of the border so we need to change
      % that back each time
      border = zeros(size(boundary),'logical');
      border(2:end-1,2:end-1) = 1;
      boundary = and(boundary,border);
      
      %% add in an option for the width of the boundary too.
      numPixelsAwayFromSpot = app.NumPixelsAwayFromSpotToSubtractCellBackgroundSpinner.Value;
      q = 0;
      while q < numPixelsAwayFromSpot
        wholeImageButJustThisSpot(boundary) = 0;
        % should return the perimeter around the pixels used for the spot
        boundary = bwperim(wholeImageButJustThisSpot);
        boundary = and(boundary,border);
        q = q + 1;
      end
      % set the parts that are not in the cell to zero
      cellLocations = logical(cellLocations);
      %%% double check this part. one might actually need to be ~
      pixelIndexToUseForCellBg = and(cellLocations,boundary);
      pixelValuesForCellBg = imageToUse(pixelIndexToUseForCellBg);
      bg_mean = mean(pixelValuesForCellBg);
      bg_median = median(pixelValuesForCellBg );
    end
    
    function backgroundAroundSpotInCell = backgroundInCell_SquareMethod(app, varargin) %regionForThisSpot, imageToUse)
      regionForThisSpot = varargin{1,1}{1,1};
      imageToUse = varargin{1,1}{1,2};
      NumPixels = app.NumPixelsForSumIntensityEditField.Value; %% num pixels chosen for calculating intensity
      % find spots we chose to use for calculating intensity and make a
      % boundary around them
      col = zeros(NumPixels, 1);
      row = zeros(NumPixels, 1);
      for n = 1:NumPixels
        col(n) = regionForThisSpot(n,2);
        row(n) = regionForThisSpot(n,1);
      end
      numPixelsAwayFromSpot = app.NumPixelsAwayFromSpotToSubtractCellBackgroundSpinner.Value;
      % if numPixelsAwayFromSpot = 0, then min_row would be at the
      % pixel that was chosen for calculating intensity. So
      % numPixelsAwayFromSpot has to be > 0
      row_min = min(row) - numPixelsAwayFromSpot;
      row_max = max(row) + numPixelsAwayFromSpot;
      col_min = min(col) - numPixelsAwayFromSpot;
      col_max = max(col) + numPixelsAwayFromSpot;
      squareAroundSpot = imageToUse(row_min:row_max, col_min:col_max);
      borderPixels = zeros(size(squareAroundSpot,1)*4-4,1);
      for i = 1:length(row_min:row_max)
        borderPixels(i*2) = squareAroundSpot(i,1);
        borderPixels(i*2-1) = squareAroundSpot(i,end);
      end
      offset = 2*length(row_min:row_max);
      for i = 2:length(row_min:row_max)-1
        borderPixels((i-1)*2 + offset) = squareAroundSpot(1,i);
        borderPixels((i-1)*2-1 + offset) = squareAroundSpot(end,i);
      end
      backgroundAroundSpotInCell = mean(borderPixels);
    end
    function pixelLocationsWithHighestIntensities = locatePixelsWithHighestIntensity(~, yspotCoord, xspotCoord,spotfindingImage, squarewidth, NumPixels)
        %% create a (squarewidth)x(squarewidth) square around each spot
        rows = max(1,(yspotCoord-floor(squarewidth/2))):min((yspotCoord+floor(squarewidth/2)),size(spotfindingImage,1));
        cols = max(1,(xspotCoord-floor(squarewidth/2))):min((xspotCoord+floor(squarewidth/2)),size(spotfindingImage,2));
        peak=spotfindingImage(rows,cols);
        %% reshape "peak" into a 1-dimensional vector
        center=reshape(peak,[],1);
        %% sort so intensity values are ordered largest to smallest
        center=sort(center,'descend');
        %% find the specified number of pixels with the highest intensities
        [y,x] = find(ismember(peak,center(1:NumPixels)), NumPixels);
        %% translate the row and columns back to being relative to the whole image
        pixelLocationsWithHighestIntensities = [y+rows(1)-1,x+cols(1)-1];
    end
    function f = plotImagesUsed(app,f,  leftImage, rightImage, leftSpots, rightSpots, doubleSpots)
      xSpotsLeft = leftSpots(:,1);
      ySpotsLeft = leftSpots(:,2);
      xSpotsRight = rightSpots(:,1);
      ySpotsRight = rightSpots(:,2);
      if isempty(f)
        fig = figure; f.fig = fig;
        f.tL = tiledlayout(fig,1,2); %, 'TileSpacing',"compact", 'Padding',"none");
        f.ax1 = nexttile(f.tL); %,'title',"Normal Spotfinding BG subtracted");
        axis(f.ax1, "image");
        f.ax2 = nexttile(f.tL);%, "closer frame BG subtracted");
        axis(f.ax2, "image");
        xratio = (app.imagesizex/2)/app.imagesizey;
        yratio = (app.imagesizex)/app.imagesizey;
        image(f.ax1,leftImage,"CDataMapping","scaled");
        image(f.ax2,rightImage,"CDataMapping","scaled");
        cb = colorbar;
        %cb.Location = 'eastoutside';
        p = impixelinfo;
        %p.Layout.Tile = 'south';
        f.ax1.YDir = "normal";
        f.ax1.XTickMode = "manual";
        f.ax1.YTickMode = "manual";
        f.ax1.XTick = [];
        f.ax1.YTick = [];
        f.ax1.XLimMode = "manual";
        f.ax1.YLimMode = "manual";
        f.ax1.Title.String = ["Left"];
        f.ax1.DataAspectRatio = [1 1 1];
        f.ax1.DataAspectRatioMode = 'manual';
        f.ax1.PlotBoxAspectRatio = [xratio yratio 1];
        f.ax1.PlotBoxAspectRatioMode = "manual";
        f.ax1.CLimMode = "manual";
        
        f.ax2.YDir = "normal";
        f.ax2.XTickMode = "manual";
        f.ax2.YTickMode = "manual";
        f.ax2.XTick = [];
        f.ax2.YTick = [];
        f.ax2.XLimMode = "manual";
        f.ax2.YLimMode = "manual";
        f.ax2.Title.String = ["Right"];
        f.ax2.DataAspectRatio = [1 1 1];
        f.ax2.DataAspectRatioMode = 'manual';
        f.ax2.PlotBoxAspectRatio = [xratio yratio 1];
        f.ax2.PlotBoxAspectRatioMode = "manual";
        f.ax2.CLimMode = "manual";
        
        % plot new points
        
        
        if doubleSpots == 1
          halfL = length(xSpotsLeft)/2; halfR = length(xSpotsRight)/2;
          line(f.ax1,xSpotsLeft(1:halfL),ySpotsLeft(1:halfL),'linestyle','none','marker','o','color','r');
          line(f.ax2,xSpotsRight(1:halfR),ySpotsRight(1:halfR),'linestyle','none','marker','o','color','r');
          line(f.ax1,xSpotsLeft(1+halfL:end),ySpotsLeft(1+halfL:end),'linestyle','none','marker','p','color','y');
          line(f.ax2,xSpotsRight(1+halfR:end),ySpotsRight(1+halfR:end),'linestyle','none','marker','p','color','y');
        else
          line(f.ax1,xSpotsLeft,ySpotsLeft,'linestyle','none','marker','o','color','r');
          line(f.ax2,xSpotsRight,ySpotsRight,'linestyle','none','marker','o','color','r');
        end
        
      else
        figure(f.fig);
        im = findobj(f.ax1.Children, 'Type', 'image');
        im.CData = leftImage;
        im = findobj(f.ax2.Children, 'Type', 'image');
        im.CData = rightImage;
%         f.ax1.Children.CData = leftImage;
%         f.ax2.Children.CData = rightImage;
        delete(findobj(f.ax1.Children, 'Type', 'line'));
        delete(findobj(f.ax2.Children, 'Type', 'line'));
        % plot new points
        if doubleSpots == 1
          halfL = length(xSpotsLeft)/2; halfR = length(xSpotsRight)/2;
          line(f.ax1,xSpotsLeft(1:halfL),ySpotsLeft(1:halfL),'linestyle','none','marker','o','color','r');
          line(f.ax2,xSpotsRight(1:halfR),ySpotsRight(1:halfR),'linestyle','none','marker','o','color','r');
          line(f.ax1,xSpotsLeft(1+halfL:end),ySpotsLeft(1+halfL:end),'linestyle','none','marker','p','color','y');
          line(f.ax2,xSpotsRight(1+halfR:end),ySpotsRight(1+halfR:end),'linestyle','none','marker','p','color','y');
        else
          line(f.ax1,xSpotsLeft,ySpotsLeft,'linestyle','none','marker','o','color','r');
          line(f.ax2,xSpotsRight,ySpotsRight,'linestyle','none','marker','o','color','r');
        end
      end
      
    end
    function arrayOfSpots = findLiveCellSpotTraces(app, cellMaps) %, tempLeftAllFrames, tempRightAllFrames)
      % set some initial values
      startFrame = app.GreenStartFrameEditField.Value;
      endFrame = app.GreenEndFrameEditField.Value;
      distanceAwayEdges = app.DistanceAwayFromEdgesEditField.Value;
      nn = app.NearestNeighborEditField.Value;
      areaForLocalMax = app.AreatoSearchforLocalMaximumsSpinner.Value; % area for finding local max during spotfinding
      threshold = app.ThresholdEditField.Value;
      
      % create progress bar
      progressBar = uiprogressdlg(app.guiForPrimeUIFigure,'Title','Please Wait',...
        'Message','Analyzing Live Cells','Cancelable','on');
      progressBar.Value = (startFrame + 1)/endFrame;
      
      % get spotfinding images for the first frame
      [tempRight, tempLeft] = get_avg_frames(app,startFrame,1);
      % subtract background if background subtraction was used for original
      % findspots btn pushed. If no background was used then it just
      % subtracts an array of zeros
      tempLeft = tempLeft - app.spotfindingBackground_leftside;
      tempRight = tempRight - app.spotfindingBackground_rightside;
%       frameFieldname = "frame" + num2str(startFrame);
%       tempLeft = tempLeftAllFrames.(frameFieldname) - app.spotfindingBackground_leftside;
%       tempRight = tempRightAllFrames.(frameFieldname) - app.spotfindingBackground_rightside;
      imageSize = size(tempLeft);
      stdsAwayFromThreshLeft = std2(tempLeft);
      stdsAwayFromThreshRight = std2(tempRight);
      
      % initialize arrayOfSpots object.
      % arrayOfSpots object calculates all necessary spot info for each spot
      % and uses the spots found from the findspots button as the first
      % set of spotss
      
      images.left = tempLeft; images.right = tempRight;
      arrayOfSpots = liveCell_arrayOfSpots(app, images, cellMaps);
      %arrayOfSpots = allSpotsLiveCell(app, tempLeft, tempRight);
       
      % loop through rest of frames. First link the spots already found to
      % the next frame, then look for new spots
      for n = (startFrame + 1):endFrame
        % update progress bar 
        progressBar.Value = n/endFrame;
        if progressBar.CancelRequested
            break
        end
        % get images for this frame
        [tempRight, tempLeft] = get_avg_frames(app,n,1);
        tempLeft = tempLeft - app.spotfindingBackground_leftside;
        tempRight = tempRight - app.spotfindingBackground_rightside;
%               frameFieldname = "frame" + num2str(n);
%       tempLeft = tempLeftAllFrames.(frameFieldname) - app.spotfindingBackground_leftside;
%       tempRight = tempRightAllFrames.(frameFieldname) - app.spotfindingBackground_rightside;
        tempImages.left = tempLeft;
        tempImages.right = tempRight;
        % start by tracking the spots found last frame to this frame
        for i = 1:arrayOfSpots.numSpots
          spotObj = arrayOfSpots.spotArray{i};
          [nextFrameCoords, nextFrameIntensities] = spotObj.findSpotInNextFrame(n, tempImages);
          if nextFrameIntensities.left > threshold - 2*stdsAwayFromThreshLeft
            spotObj.updateSpotData(nextFrameCoords.left, n, tempImages, cellMaps, "both", "refine");
          elseif nextFrameIntensities.right > threshold - 2*stdsAwayFromThreshRight
            spotObj.updateSpotData(nextFrameCoords.right, n, tempImages, cellMaps, "right");
          end
          clear spotObj
        end
        % loop through spot array to check to see if any spots need
        % to be merged
        arrayOfSpots.checkForAndMergeRepeatSpots(n);
%         listOfLatestSpotCoords = arrayOfSpots.getListOfMostRecentSpotCoords;
%         d = pdist2(listOfLatestSpotCoords, listOfLatestSpotCoords);
%         for i = 1:arrayOfSpots.numSpots
%           d2 = find(d(i,:) == 0);
%           if length(d2) > 1
%             if length(d2) > 2
%               disp('add someting for htis. shouldnt happen tho. This means two spots have the same coords')
%             end
%             disp('we need to merge them')
%             arrayOfSpots.mergeSpots(n, d2(1), d2(2));
%           end
%         end
        % now run findspots on this frame, but only keep the spots are at
        % least nn distance away from the spots already found
        rows = (1+distanceAwayEdges):(imageSize(1)-distanceAwayEdges);
        cols = (1+distanceAwayEdges):(imageSize(2)-distanceAwayEdges);
        listOfSpotCoordsAlreadyFound = arrayOfSpots.getListOfMostRecentSpotCoords;
        for i = rows
          for j = cols
            tempSquareArea = tempLeft(i-areaForLocalMax:i+areaForLocalMax,j-areaForLocalMax:j+areaForLocalMax);
            if tempLeft(i,j) >= threshold && tempLeft(i,j) == max(max(tempSquareArea))
              d = pdist2(listOfSpotCoordsAlreadyFound,[j,i]);
              if ~any(d < nn)
                arrayOfSpots.addNewSpot(app, [j,i], n, tempImages, cellMaps);
              end
            end
          end
        end


      end % end of loop through n frames
      % update info:
      app.num_spots = arrayOfSpots.numSpots;
      close(progressBar);
    end
    

    function [valuesLeft, valuesRight] = findLiveCellSpotTraces_old(app)
     

      spotCoordsLeft = []; regionsLeft = []; subPixelCoordsLeft = [];
      spotCoordsRight = []; regionsRight = []; subPixelCoordsRight = [];
      % user input variables
      maxTravelLength = app.MaxTravelDistanceSpinner.Value;
      
      areaForLocalMax = app.AreatoSearchforLocalMaximumsSpinner.Value; % area for finding local max during spotfinding
      %maxTravelLength = areaForLocalMax;
      distanceAwayEdges = app.DistanceAwayFromEdgesEditField.Value;
      squarewidth = app.SquareSizeAroundEachSpotEditField.Value; %% size of square matrix around each spot
      NumPixels = app.NumPixelsForSumIntensityEditField.Value; %% number of pixels to use for trace
      ImageForFittingSize = app.ImageforFittingSizeSpinner.Value; % this is the size in pixels around the spot (+1) (needs to be odd) for sub-pixel coords
      startFrame = app.GreenStartFrameEditField.Value;
      endFrame = app.GreenEndFrameEditField.Value;
      % set correct cell background function

      if app.CellBgSubtractionDropDown.Value == "square"
        calculateCellBackground = @(varargin) backgroundInCell_SquareMethod(app,varargin); %varargin = regionsLeft.(firstFrame)(:,:,m), tempLeft
        %calculateCellBackground = @(regions,image) backgroundInCell_SquareMethod(app,regions,image);
      elseif app.CellBgSubtractionDropDown.Value == "cell edges"
        
        calculateCellBackground = @(varargin) backgroundInCell_MedianMean(app,varargin);%varargin = app.cellMap_leftside, regions_leftside(:,:,m), temp_leftside
      elseif app.CellBgSubtractionDropDown.Value == "none"
        calculateCellBackground = @(varargin) 0;
      end
      % create progress bar
%       progressBar = uiprogressdlg(app.guiForPrimeUIFigure,'Title','Please Wait',...
%         'Message','Analyzing Live Cells');
%       progressBar.Value = (startFrame + 1)/endFrame;
      % 1st frame spots found from findspots button
      firstFrame = "frame" + num2str(startFrame);
      spotCoordsLeft.(firstFrame) = [app.x_spots_leftside, app.y_spots_leftside];
      spotCoordsRight.(firstFrame) = [app.x_spots_rightside, app.y_spots_rightside]; % consider refining them first
      subPixelCoordsLeft.(firstFrame) = [app.x_spots_leftside, app.y_spots_leftside];
      subPixelCoordsRight.(firstFrame) = [app.x_spots_rightside, app.y_spots_rightside];
      firstCoordsEachSpotLeft = [app.x_spots_leftside, app.y_spots_leftside, ones(app.num_spots,1)*startFrame];
      firstCoordsEachSpotRight = [app.x_spots_rightside, app.y_spots_rightside, ones(app.num_spots,1)*startFrame];
      % set up regions and traces arrays
      regionsLeft.(firstFrame) = zeros(NumPixels, 2, app.num_spots);
      regionsRight.(firstFrame) = zeros(NumPixels, 2, app.num_spots);
      tracesLeft = NaN(app.num_spots, app.NumFrames);
      tracesRight = NaN(app.num_spots, app.NumFrames);
      
      % find regions and traces for 1st frame
      [tempRight, tempLeft] = get_avg_frames(app,startFrame,1);
      % subtract background if background subtraction was used for original
      % findspots btn pushed. If no background was used then it just
      % subtracts an array of zeros
      tempLeft = tempLeft - app.spotfindingBackground_leftside;
      tempRight = tempRight - app.spotfindingBackground_rightside;
      if app.num_spots ~= 0
        for m = 1:app.num_spots
          % get sub-pixel coords for spot
          %         rows = max((spotCoordsLeft.(thisFrame)(m,2)-floor(ImageForFittingSize/2)),1):min((spotCoordsLeft.(thisFrame)(m,2)+floor(ImageForFittingSize/2)),imageSize(1));
          %         cols = max((spotCoordsLeft.(thisFrame)(m,1)-floor(ImageForFittingSize/2)),1):min((spotCoordsLeft.(thisFrame)(m,1)+floor(ImageForFittingSize/2)),imageSize(2));
          % get sub-pixel coords, regions, and traces. Leftside first
          rows = spotCoordsLeft.(firstFrame)(m,2)-floor(ImageForFittingSize/2):spotCoordsLeft.(firstFrame)(m,2)+floor(ImageForFittingSize/2);
          cols = spotCoordsLeft.(firstFrame)(m,1)-floor(ImageForFittingSize/2):spotCoordsLeft.(firstFrame)(m,1)+floor(ImageForFittingSize/2);
          tempSpot = tempLeft(rows,cols);
          [xc, yc] = radialcenter(app,tempSpot); % Return output relative to upper left coordinate
          subPixelCoordsLeft.(firstFrame)(m,:) = [(cols(1) + yc -1), (rows(1) + xc - 1)];
          rows = spotCoordsRight.(firstFrame)(m,2)-floor(ImageForFittingSize/2):spotCoordsRight.(firstFrame)(m,2)+floor(ImageForFittingSize/2);
          cols = spotCoordsRight.(firstFrame)(m,1)-floor(ImageForFittingSize/2):spotCoordsRight.(firstFrame)(m,1)+floor(ImageForFittingSize/2);
          tempSpot = tempRight(rows,cols);
          [xc, yc] = radialcenter(app,tempSpot); % Return output relative to upper left coordinate
          subPixelCoordsRight.(firstFrame)(m,:) = [(cols(1) + yc -1), (rows(1) + xc - 1)];
          regionsLeft.(firstFrame)(:,:,m) = locatePixelsWithHighestIntensity(app, spotCoordsLeft.(firstFrame)(m,2),...
            spotCoordsLeft.(firstFrame)(m,1), tempLeft, squarewidth, NumPixels);
          regionsRight.(firstFrame)(:,:,m) = locatePixelsWithHighestIntensity(app, spotCoordsRight.(firstFrame)(m,2),...
            spotCoordsRight.(firstFrame)(m,1), tempRight, squarewidth, NumPixels);
          tracesLeft(m, startFrame) = trace(tempLeft(regionsLeft.(firstFrame)(:,1,m),regionsLeft.(firstFrame)(:,2,m)));
          tracesRight(m, startFrame) = trace(tempRight(regionsRight.(firstFrame)(:,1,m),regionsRight.(firstFrame)(:,2,m)));
          % subtract cell background
          tracesLeft(m, startFrame) = tracesLeft(m, startFrame) - calculateCellBackground(regionsLeft.(firstFrame)(:,:,m), tempLeft, app.cellMap_leftside);
          tracesRight(m, startFrame) = tracesRight(m, startFrame) - calculateCellBackground(regionsRight.(firstFrame)(:,:,m), tempRight, app.cellMap_rightside);
        end
      end
     arrayOfSpots = allSpotsLiveCell(app, tempLeft, tempRight);
      spots = mappedSpots(app, [app.x_spots_leftside, app.y_spots_leftside], tempLeft, tempRight);
  
      nf = endFrame - startFrame + 1;
      tempMeansStdsLeft = zeros(nf, 2);
      tempMeansStdsRight = zeros(nf, 2);
      tempMeansStdsLeft(1,1) = mean2(tempLeft);  tempMeansStdsLeft(1,2) = std2(tempLeft);
      tempMeansStdsRight(1,1) = mean2(tempRight); tempMeansStdsRight(1,2) = std2(tempRight);
      % loop through rest of frames
      threshold = app.ThresholdEditField.Value;
      stdsAwayFromThreshLeft = std2(tempLeft);
      stdsAwayFromThreshRight = std2(tempRight);
      imageSize = size(tempLeft);
      leftCoordsForSpotAreasFound = zeros(imageSize);
      %f = figure;
      for n = (startFrame + 1):endFrame
        if n == 61
          disp('lskdjf')
        end
        [tempRight, tempLeft] = get_avg_frames(app,n,1);
        tempLeft = tempLeft - app.spotfindingBackground_leftside;
        tempRight = tempRight - app.spotfindingBackground_rightside;
        tempMeansStdsLeft(n-startFrame+1,1) = mean2(tempLeft);  tempMeansStdsLeft(n-startFrame+1,2) = std2(tempLeft);
      tempMeansStdsRight(n-startFrame+1,1) = mean2(tempRight); tempMeansStdsRight(n-startFrame+1,2) = std2(tempRight);
      [lastFrameRight, lastFrameLeft] = get_avg_frames(app,n-1,1);
        lastFrameLeft = lastFrameLeft - app.spotfindingBackground_leftside;
        lastFrameRight = lastFrameRight - app.spotfindingBackground_rightside;
        leftGradient = tempLeft - lastFrameLeft; rightGradient = tempRight - lastFrameRight;
        % start by updating last frame spot info to this frame spot info
        thisFrame = "frame" + num2str(n);
        lastFrame = "frame" + num2str(n-1);
        
        spotCoordsLeft.(thisFrame) = nan(size(spotCoordsLeft.(lastFrame)));
        spotCoordsRight.(thisFrame) = nan(size(spotCoordsLeft.(lastFrame)));
        regionsLeft.(thisFrame) = nan(size(regionsLeft.(lastFrame)));
        regionsRight.(thisFrame) = nan(size(regionsRight.(lastFrame)));
        subPixelCoordsLeft.(thisFrame) = nan(size(spotCoordsLeft.(lastFrame)));
        subPixelCoordsRight.(thisFrame) = nan(size(spotCoordsLeft.(lastFrame)));
        
        %%%%%%%%%%%%%%%%%%%%%%%
%         matchedSpotCoordsLeft = refineSpotCoords(spots, spots.spotCoordsLeft(:,:,n-1), tempLeft, "travel");
%         matchedSpotCoordsRight = mapSpotCoords(spots,matchedSpotCoordsLeft);
%         matchedSpotCoordsRight = refineSpotCoords(spots, matchedSpotCoordsRight, tempRight);

        for i = 1:arrayOfSpots.numSpots
          spotObj = arrayOfSpots.spotArray{i};
          nextFrameCoords = spotObj.findSpotInNextFrame(n, tempLeft, tempRight);
          spotIntensityLeftside = tempLeft(nextFrameCoords.left(2), nextFrameCoords.left(1));
          spotIntensityRightside = tempRight(nextFrameCoords.right(2), nextFrameCoords.right(1));
          if spotIntensityLeftside > threshold - 2*stdsAwayFromThreshLeft 
            spotObj.updateSpotData(nextFrameCoords.left, n, i,tempLeft, tempRight, "both", "refine");
          elseif spotIntensityRightside > threshold - 2*stdsAwayFromThreshRight
            spotObj.updateSpotData(nextFrameCoords.right, n, i,tempLeft, tempRight, "right");
          end
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % match last frame spots to this frame
        for m = 1:size(spotCoordsRight.(lastFrame),1)
          spotIntensityLeftside = nan; spotIntensityRightside = nan;
          if ~isnan(spotCoordsLeft.(lastFrame)(m,2)) 
            rows = max(1,(spotCoordsLeft.(lastFrame)(m,2)-maxTravelLength)):min((spotCoordsLeft.(lastFrame)(m,2)+maxTravelLength),imageSize(1));
            cols = max(1,(spotCoordsLeft.(lastFrame)(m,1)-maxTravelLength)):min((spotCoordsLeft.(lastFrame)(m,1)+maxTravelLength),imageSize(1));
            leftCoordsForSpotAreasFound(rows,cols) = m*2; %multiplied by 2 just for better contrast while debugging
            searchAreaAroundSpot = tempLeft(rows, cols);
            % find the pixel with the max intensity in the search area
            [y,x] = find(searchAreaAroundSpot == max(max(searchAreaAroundSpot)));
            if length(x) > 1 % if more than one pixel, then take the closest one
              d = pdist2(spotCoordsLeft.(lastFrame)(m,:),[x,y]);
              y = y(d == min(d)); x = x(d == min(d));
            end
            leftSpot = [cols(x), rows(y)];
            spotIntensityLeftside = tempLeft(rows(y),cols(x));
            % map spot to rightside and refine mapped coords to make sure 
            % we have the highest intensity spots in the search area
            rightSpot = round([cols(x) , rows(y),1]*app.f_map);
            rowsR = max((rightSpot(2) - areaForLocalMax),1):min((rightSpot(2) + areaForLocalMax),imageSize(1));
            colsR = max((rightSpot(1) - areaForLocalMax),1):min((rightSpot(1) + areaForLocalMax),imageSize(2));
            [yR,xR] = find(tempRight(rowsR, colsR) == max(max(tempRight(rowsR, colsR))));
            if length(xR) > 1
              d = pdist2(spotCoordsRight.(lastFrame)(m,:),[xR,yR]);
              yR = yR(d == min(d)); xR = xR(d == min(d));
            end
            rightSpot = [colsR(xR), rowsR(yR)];
            spotIntensityRightside = tempRight(rows(yR),cols(xR));
          elseif ~isnan(spotCoordsRight.(lastFrame)(m,2))
            rows = max(1,(spotCoordsRight.(lastFrame)(m,2)-maxTravelLength)):min((spotCoordsRight.(lastFrame)(m,2)+maxTravelLength),imageSize(1));
            cols = max(1,(spotCoordsRight.(lastFrame)(m,1)-maxTravelLength)):min((spotCoordsRight.(lastFrame)(m,1)+maxTravelLength),imageSize(2));
            searchAreaAroundSpot = tempRight(rows, cols);
            % find the pixel with the max intensity in the search area
            [y,x] = find(searchAreaAroundSpot == max(max(searchAreaAroundSpot)));
            if length(x) > 1
              d = pdist2(spotCoordsRight.(lastFrame)(m,:),[x,y]);
              y = y(d == min(d)); x = x(d == min(d));
            end
            rightSpot = [cols(x), rows(y)];
            spotIntensityRightside = tempRight(rows(y),cols(x));
          end
          if spotIntensityLeftside > threshold - 2*stdsAwayFromThreshLeft %2*std2(tempLeft)
            spotCoordsLeft.(thisFrame)(m,:) = leftSpot;
            rows = max((spotCoordsLeft.(thisFrame)(m,2)-floor(ImageForFittingSize/2)),1):min((spotCoordsLeft.(thisFrame)(m,2)+floor(ImageForFittingSize/2)),imageSize(1));
            cols = max((spotCoordsLeft.(thisFrame)(m,1)-floor(ImageForFittingSize/2)),1):min((spotCoordsLeft.(thisFrame)(m,1)+floor(ImageForFittingSize/2)),imageSize(2));
            tempSpot = tempLeft(rows,cols);
            [xc, yc] = radialcenter(app,tempSpot); % Return output relative to upper left coordinate
            subPixelCoordsLeft.(thisFrame)(m,:) = [(cols(1) + yc -1), (rows(1) + xc - 1)];
            regionsLeft.(thisFrame)(:,:,m) = locatePixelsWithHighestIntensity(app, spotCoordsLeft.(thisFrame)(m,2),...
              spotCoordsLeft.(thisFrame)(m,1), tempLeft, squarewidth, NumPixels);
            tracesLeft(m, n) = trace(tempLeft(regionsLeft.(thisFrame)(:,1,m),regionsLeft.(thisFrame)(:,2,m)));
            tracesLeft(m, n) = tracesLeft(m, n) - calculateCellBackground(regionsLeft.(thisFrame)(:,:,m), tempLeft, app.cellMap_leftside);
          else
            leftCoordsForSpotAreasFound(leftCoordsForSpotAreasFound == m*2) = 0;
          end
          if spotIntensityLeftside > threshold - 2*stdsAwayFromThreshLeft || spotIntensityRightside > threshold - 2*stdsAwayFromThreshRight %2*std2(tempRight)
            spotCoordsRight.(thisFrame)(m,:) = rightSpot;
            rows = max((spotCoordsRight.(thisFrame)(m,2)-floor(ImageForFittingSize/2)),1):min((spotCoordsRight.(thisFrame)(m,2)+floor(ImageForFittingSize/2)),imageSize(1));
            cols = max((spotCoordsRight.(thisFrame)(m,1)-floor(ImageForFittingSize/2)),1):min((spotCoordsRight.(thisFrame)(m,1)+floor(ImageForFittingSize/2)),imageSize(2));
            tempSpot = tempRight(rows,cols);
            [xc, yc] = radialcenter(app,tempSpot); % Return output relative to upper left coordinate
            subPixelCoordsRight.(thisFrame)(m,:) = [(cols(1) + yc -1), (rows(1) + xc - 1)];
            regionsRight.(thisFrame)(:,:,m) = locatePixelsWithHighestIntensity(app, spotCoordsRight.(thisFrame)(m,2),...
              spotCoordsRight.(thisFrame)(m,1), tempRight, squarewidth, NumPixels);
            tracesRight(m, n) = trace(tempRight(regionsRight.(thisFrame)(:,1,m),regionsRight.(thisFrame)(:,2,m)));
            tracesRight(m, n) = tracesRight(m, n) - calculateCellBackground(regionsRight.(thisFrame)(:,:,m), tempRight, app.cellMap_rightside);
          end
        end % end of loop on m spots found last frame
%spotCoordsLeft.(thisFrame);
        % now run findspots on this frame, but only keep the spots that
        % weren't already found
        rows = (1+distanceAwayEdges):(imageSize(1)-distanceAwayEdges);
        cols = (1+distanceAwayEdges):(imageSize(2)-distanceAwayEdges);
        newSpotCount = 0;
        for i = rows
          for j = cols
            tempSquareArea = tempLeft(i-areaForLocalMax:i+areaForLocalMax,j-areaForLocalMax:j+areaForLocalMax);
            if tempLeft(i,j) >= threshold && tempLeft(i,j) == max(max(tempSquareArea))
              if leftCoordsForSpotAreasFound(i,j) == 0 % might need to change this to accomodate for some overlap. Also need to find where there are breaks in the donor traces
                % add the new spot to the list
                % if
                % if ~any(leftCoordsForSpotAreasFound(i-areaForLocalMax:i+areaForLocalMax,j-areaForLocalMax:j+areaForLocalMax),'all')
                % then do what is below
                % else check first coords each spot and link the spot found
                % to a previous spot if it's in the search area for that
                % spot
                % for k = 1:length(firstCoordsEachSpotLeft)
                % rowsL =
                % firstCoordsEachSpotLeft(k,2)-areaForLocalMax:firstCoordsEachSpotLeft(k,2)+areaForLocalMax;
                % colsL =
                % firstCoordsEachSpotLeft(k,1)-areaForLocalMax:firstCoordsEachSpotLeft(k,1)+areaForLocalMax;
                % coords = [rowsL',colsL'];
                % if ismember([j,i],coords)
                % spotCoordsLeft.(thisFrame)(k,:) = [j,i];
                % 

                newSpotCount = newSpotCount +1;
                spotCoordsLeft.(thisFrame) = [spotCoordsLeft.(thisFrame); [j,i]];
                firstCoordsEachSpotLeft = [firstCoordsEachSpotLeft; [j,i,n]];
                spots = newSpot(spots, [j,i], n, tempLeft, tempRight, "refine");
      
                rightSpot = round([j, i, 1]*app.f_map);
                rowsR = max((rightSpot(2) - areaForLocalMax),1):min((rightSpot(2) + areaForLocalMax),imageSize(1));
                colsR = max((rightSpot(1) - areaForLocalMax),1):min((rightSpot(1) + areaForLocalMax),imageSize(2));
                [y,x] = find(tempRight(rowsR, colsR) == max(max(tempRight(rowsR, colsR))));
                if length(x) > 1
                  d = pdist2(rightSpot(1:2),[x,y]);
                  y = y(d == min(d)); x = x(d == min(d));
                end
                spotCoordsRight.(thisFrame) = [spotCoordsRight.(thisFrame); [colsR(x),rowsR(y)]];
                firstCoordsEachSpotRight = [firstCoordsEachSpotRight; [colsR(x),rowsR(y),n]];
              end
            end
          end
        end
        % get sub-pixel coords, regions, and traces for new spots and fill
        % in previous frames for this spot with nan or with
        % current spot coords
        newSpotNums = (size(spotCoordsLeft.(thisFrame),1) - newSpotCount+1):size(spotCoordsLeft.(thisFrame),1);
        for m = 1:newSpotCount
          rows = max((spotCoordsLeft.(thisFrame)(newSpotNums(m),2)-floor(ImageForFittingSize/2)),1):min((spotCoordsLeft.(thisFrame)(newSpotNums(m),2)+floor(ImageForFittingSize/2)),imageSize(1));
          cols = max((spotCoordsLeft.(thisFrame)(newSpotNums(m),1)-floor(ImageForFittingSize/2)),1):min((spotCoordsLeft.(thisFrame)(newSpotNums(m),1)+floor(ImageForFittingSize/2)),imageSize(2));
          tempSpot = tempLeft(rows,cols);
          [xc, yc] = radialcenter(app,tempSpot); % Return output relative to upper left coordinate
          subPixelCoordsLeft.(thisFrame) = [subPixelCoordsLeft.(thisFrame); [(cols(1) + yc -1), (rows(1) + xc - 1)]];
          %rightside
          rows = max((spotCoordsRight.(thisFrame)(newSpotNums(m),2)-floor(ImageForFittingSize/2)),1):min((spotCoordsRight.(thisFrame)(newSpotNums(m),2)+floor(ImageForFittingSize/2)),imageSize(1));
          cols = max((spotCoordsRight.(thisFrame)(newSpotNums(m),1)-floor(ImageForFittingSize/2)),1):min((spotCoordsRight.(thisFrame)(newSpotNums(m),1)+floor(ImageForFittingSize/2)),imageSize(2));
          tempSpot = tempRight(rows,cols);
          [xc, yc] = radialcenter(app,tempSpot); % Return output relative to upper left coordinate
          subPixelCoordsRight.(thisFrame) = [subPixelCoordsRight.(thisFrame); [(cols(1) + yc -1), (rows(1) + xc - 1)]];
          % regions
          newRegionLeft= locatePixelsWithHighestIntensity(app, spotCoordsLeft.(thisFrame)(newSpotNums(m),2),...
            spotCoordsLeft.(thisFrame)(newSpotNums(m),1), tempLeft, squarewidth, NumPixels);
          newRegionRight = locatePixelsWithHighestIntensity(app, spotCoordsRight.(thisFrame)(newSpotNums(m),2),...
            spotCoordsRight.(thisFrame)(newSpotNums(m),1), tempRight, squarewidth, NumPixels);
          % traces
          newLeftTrace = NaN(1, app.NumFrames);
          newLeftTrace(n) = trace(tempLeft(newRegionLeft(:,1),newRegionLeft(:,2))) - calculateCellBackground(newRegionLeft, tempLeft, app.cellMap_leftside);
          newRightTrace = NaN(1, app.NumFrames);
          newRightTrace(n) = trace(tempRight(newRegionRight(:,1),newRegionRight(:,2))) - calculateCellBackground(newRegionRight, tempRight, app.cellMap_rightside);
          tracesLeft = cat(1, tracesLeft, newLeftTrace);
          tracesRight = cat(1, tracesRight, newRightTrace);
          previousRegionFill = nan(size(newRegionLeft));
          previousSpotCoordsL = nan(1,2); %spotCoordsLeft.(thisFrame)(newSpotNums(m),:); %nan(1, 2);
          previousSpotCoordsR = nan(1,2); %spotCoordsRight.(thisFrame)(newSpotNums(m),:); %nan(1, 2);
          % fill in previous frame info so that structs are all the same size
          for j = startFrame:(n-1) 
            frame = "frame" + num2str(j);
            spotCoordsLeft.(frame) = cat(1,spotCoordsLeft.(frame), previousSpotCoordsL);
            spotCoordsRight.(frame) = cat(1,spotCoordsRight.(frame),previousSpotCoordsR);
            regionsLeft.(frame) = cat(3,regionsLeft.(frame), previousRegionFill);
            regionsRight.(frame) = cat(3,regionsRight.(frame), previousRegionFill);
            subPixelCoordsRight.(frame) = cat(1,subPixelCoordsRight.(frame), previousSpotCoordsR);
            subPixelCoordsLeft.(frame) = cat(1,subPixelCoordsLeft.(frame), previousSpotCoordsL);
          end % end of loop j
        end % end of loop on new spots found
        % update progress bar every 10 frames
%         if mod(n,5) == 0
%           progressBar.Value = n/endFrame;
%         end
%         progressBar.Value = n/endFrame;
        
        if n == 100
          disp('lksdjf')
        end
        

      end % end of loop through n frames
      % update info:
      app.num_spots = size(spotCoordsLeft.(thisFrame),1);
      valuesRight = {spotCoordsRight, regionsRight, tracesRight, subPixelCoordsRight, firstCoordsEachSpotRight};
      valuesLeft = {spotCoordsLeft, regionsLeft, tracesLeft, subPixelCoordsLeft, firstCoordsEachSpotLeft};
      close(progressBar);
    end


    %% Saving Analysis Functions
% %     function saveTextFileOfAnalysisSettings(app)
% %       % file info:
% %       movieInfo = dir(app.filename);
% %       fileInfo = ["Movie File Analyzed: " + string(app.filename), "Date of File: " + string(movieInfo.date),  app.path];
% %       
% %       % movie specs:
% %       movieSpecs = ["Camera Type: " + app.cameratype, "Num Frames: " + app.NumFrames, ...
% %         "Size in Pixels: [" + app.imagesizex + " x " + app.imagesizey + "]" ];
% %       
% %       
% %       
% %       % analysis type:
% %       analysisModeCheckboxes = findobj(app.SpotSettingsPanel, 'Type', 'uicheckbox', 'Value', true);
% %       if ~isempty(analysisModeCheckboxes)
% %         whichMode = string(analysisModeCheckboxes.Text);
% %       else
% %         whichMode = "Regular";
% %       end
% %       analysisTypeInfo = ["Analysis Type: " + app.AnalysisTypeDropDown.Value + " - " + app.SpotTypeDropDown.Value,  ...
% %         "Date of Analysis: " + datestr(now), "Method: " + whichMode];
% %       
% %       
% %       %newStr = upper(str)
% %       
% %       % analysis settings:
% %       analysisFileName = strrep(app.filename, app.fileExtension,['.traces']);
% %       if app.AnalysisTypeDropDown.Value == "FRET"
% %         analysisSettings = ["Num Pixels For Sum Intensity: " + app.NumPixelsForSumIntensityEditField.Value, "Square Size Around Each Spot: " + ...
% %           app.SquareSizeAroundEachSpotEditField.Value];
% %       elseif app.AnalysisTypeDropDown.Value == "TPM"
% %         analysisFileName = [" ", app.lastsavedTPM, app.lastsavedtrace];
% %         analysisSettings = "Square Size Around Each Bead: " + app.ImageforFittingSizeSpinner.Value;
% %       elseif app.AnalysisTypeDropDown.Value == "FRET and TPM"
% %         analysisSettings = ["FRET Settings:", "Num Pixels For Sum Intensity: " + app.NumPixelsForSumIntensityEditField.Value, "Square Size Around Each Spot: " + ...
% %           app.SquareSizeAroundEachSpotEditField.Value, "TPM Settings: ", "Square Size Around Each Bead: " + app.ImageforFittingSizeSpinner.Value];
% %       end
% %       
% %       analysisTypeInfo = ["Analysis",  "Type: " + app.AnalysisTypeDropDown.Value + " - " + app.SpotTypeDropDown.Value,  ...
% %         "Date: " + datestr(now), "Method: " + whichMode, "Saved As: " + analysisFileName];
% %       
% %       % background Calculations info:
% %       backgroundCalculations = ["Block Size: [" + app.BlockSizeEditField.Value + "]" ]; % add in framenumbers used for calculating bg
% %       
% %       % spotfinding settings
% %       spotFindingSettings = ["Spots Found: " + app.num_spots, "Threshold: " + app.ThresholdEditField.Value, "Which side: " + app.whichside, ...
% %         "Square Area To Search For Local Maximums: " + app.AreatoSearchforLocalMaximumsSpinner.Value, ...
% %         "Distance From Edges: " + app.DistanceAwayFromEdgesEditField.Value, "Nearest Neighbor: " + app.NearestNeighborEditField.Value, ...
% %         app.f_map_forDisplayInTextArea(3), "Offset Limit: " + app.OffsetLimitSpinner.Value, app.f_map_forDisplayInTextArea(4:end)];
% %       
% %       % background Calculations info:
% %       backgroundCalculations = ["Block Size: [" + app.BlockSizeEditField.Value + "]" , "Background Start Frame: " + app.StartFrameSpotfindingBg.Value, ...
% %         "Num Frames Averaged For Calculating Backgrounds: " + app.NumFramesToAvgSpotfindingBg.Value]; % add in framenumbers used for calculating bg
% %       
% %       
% %       % method settings
% %       if whichMode == "Red Green Alt Mode"
% %         % start & end frames..
% %         % bg calculation for green frames too..
% %         analysisTypeInfo = [analysisTypeInfo, " ", "Green Laser Start Frame: " + app.GreenStartFrameEditField.Value, "Green Laser End Frame: " + ...
% %           app.GreenEndFrameEditField.Value];
% %         backgroundCalculations = ["Block Size: [" + app.BlockSizeEditField.Value + "]" , "Red Background Start Frame: " + app.StartFrameSpotfindingBg.Value, ...
% %           "Green Background Start Frame: " + app.StartFrameForGreenLaserBackgroundEditField.Value, "Num Frames Averaged For Calculating Backgrounds: " + app.NumFramesToAverageForGreenLaserBackgroundSpinner.Value];         
% %       elseif whichMode == "Live Cell Mode"
% %         % number of cells maybe? Not sure yet
% %       elseif whichMode == "Red White Green Alt Mode"
% %         % no clue. look at this later
% %       elseif whichMode == "Live Cell Mode"
% %         analysisTypeInfo = [analysisTypeInfo, " ", "Green Laser Start Frame: " + app.GreenStartFrameEditField.Value, "Green Laser End Frame: " + ...
% %           app.GreenEndFrameEditField.Value];
% %       elseif whichMode == "Rachel's Big Spots Mode"
% %         % numpixels & square sizes need to be changed
% %         analysisSettings = [analysisSettings, "*not including big spots"];
% %         bigSpotInfo = "Big Spots Found: " + length(app.BigSpotAnalysisInfo.BigSpotNumbers);
% %         extraSettingsFormat = "\nSpot Number: %d, NumPixels: %d"; % \nDimensions For Square Size: %d x %d";
% %         extraSettingsInfo = [app.BigSpotAnalysisInfo.BigSpotNumbers, app.BigSpotAnalysisInfo.NumPixels];
% %         %app.BigSpotAnalysisInfo.SquareSize(:,1), app.BigSpotAnalysisInfo.SquareSize(:,2)];
% %       end
% %       
% %       
% %      
% %       
% %       fprintf('%s \n', fileInfo);
% %       fprintf('\n');
% %       %fprintf('\nMovie Specs:\n'); % name that something else
% %       fprintf("%s \n", movieSpecs);
% %       fprintf('\n');
% %       %fprintf('\nAnalysis:\n'); % name that something else
% %       fprintf("%s \n", analysisTypeInfo);
% %       fprintf('\n');
% %       fprintf('Analysis Settings\n'); % name that something else
% %       fprintf("%s \n", analysisSettings);
% %       fprintf('\n');
% %       fprintf('Spot Finding\n'); % name that something else
% %       fprintf("%s \n", spotFindingSettings);
% %       fprintf('\n');
% %       if whichMode == "Rachel's Big Spots Mode"
% %         fprintf("%s", bigSpotInfo);
% %         fprintf(extraSettingsFormat, round(extraSettingsInfo));
% %       end
% %       
% %       disp('pausehere')
% %       
% %       textFileName = strrep(app.filename, app.fileExtension,['_AnalysisSettings.txt']);
% %       fid = fopen(textFileName,'w');
% %       % start printing the txt file
% %       fprintf(fid,'%s \n', fileInfo);
% %       fprintf(fid,'\n');
% %       fprintf(fid,"%s \n", movieSpecs);
% %       fprintf(fid,'\n');
% %       fprintf(fid,"%s \n", analysisTypeInfo);
% %       fprintf(fid,'\n');
% %       fprintf(fid,'Analysis Settings\n');
% %       fprintf(fid,"%s \n", analysisSettings);
% %       fprintf(fid,'\n');
% %       fprintf(fid,'Spot Finding\n');
% %       fprintf(fid,"%s \n", spotFindingSettings);
% %       fprintf(fid,'\n');
% %       if whichMode == "Rachel's Big Spots Mode"
% %         fprintf(fid,"%s", bigSpotInfo);
% %         fprintf(fid,extraSettingsFormat, round(extraSettingsInfo));
% %       end
% %       fclose(fid);
% %       
% %       
% %       
% %     end
    
    function savetraces(app)
      % check that the settings will work
      squarewidth = app.SquareSizeAroundEachSpotEditField.Value; %% size of square matrix around each spot
      NumPixels = app.NumPixelsForSumIntensityEditField.Value; %% choosing 4 pixels with highest intensities
      if squarewidth*squarewidth <= NumPixels
        app.InfoTextArea.Value = "(Square Size around each box)^2 needs to be greater than the Number of Pixels for Sum Intensity.";
      end
      xcoords_leftside = app.x_spots_leftside;
      xcoords_rightside = app.x_spots_rightside;
      ycoords_leftside = app.y_spots_leftside;
      ycoords_rightside = app.y_spots_rightside;
      % framenumbers are not sequential for rgw alt mode
      if app.RedWhiteGreenAltModeCheckBox.Value == true % or if app.analysisMode == "RWGalt"; % right now it doesn't matter which..
        frameindex = app.frameIndexForFRETanalysis;
        nframes = length(app.frameIndexForFRETanalysis);
        realframenumbers = app.temporalFramenumbersForFRETanalysis;
        greenlaser_on = app.greenLaserFrameIdx(1);
        if app.AltModeEndFrameEditField.Value == inf
          greenlaser_off = app.NumFrames + 100;
        else
          greenlaser_off = app.alternatingFramesIdx(end);
        end
      else
        nframes = app.NumFrames;
        frameindex = 1:nframes;
        realframenumbers = 1:nframes;
        if app.RedGreenAltModeCheckBox.Value == true
          greenlaser_on = app.GreenStartFrameEditField.Value;
          greenlaser_off = app.GreenEndFrameEditField.Value;
        else
          greenlaser_on = -1;
          greenlaser_off = app.NumFrames + 100;
        end
      end

      
      regions_leftside=zeros(NumPixels,2,app.num_spots); %%% matricies size (NumPixels, 2), one for each spot
      regions_rightside=zeros(NumPixels,2,app.num_spots);
      app.InfoTextArea.Value = ['starting to save:' newline app.filename];
      drawnow;
      
      % for each spot, find the top 4 pixels with highest intensity using
      % the spotfinding image
      for m=1:app.num_spots
        %% create a (squarewidth)x(squarewidth) square around each spot
        rows = (ycoords_leftside(m)-floor(squarewidth/2)):(ycoords_leftside(m)+floor(squarewidth/2));
        cols = (xcoords_leftside(m)-floor(squarewidth/2)):(xcoords_leftside(m)+floor(squarewidth/2));
        peak = app.spotfindingImage_leftside(rows,cols);
        %% reshape "peak" into a 1-dimensional vector
        center=reshape(peak,[],1);
        %% sort so intensity values are ordered largest to smallest
        center=sort(center,'descend');
        %% find the specified number of pixels with the highest intensities
        [y,x] = find(ismember(peak,center(1:NumPixels)), NumPixels);
        %% translate the row and columns indices back to being relative to the whole image
        regions_leftside(:,:,m) = [y+rows(1)-1,x+cols(1)-1];
        %% now do the rightside
        %% create a (squarewidth)x(squarewidth) square around each spot
        rows = (ycoords_rightside(m)-floor(squarewidth/2)):(ycoords_rightside(m)+floor(squarewidth/2));
        cols = (xcoords_rightside(m)-floor(squarewidth/2)):(xcoords_rightside(m)+floor(squarewidth/2));
        peak = app.spotfindingImage_rightside(rows,cols);
        %% reshape "peak" into a 1-dimensional vector
        center=reshape(peak,[],1);
        %% sort so intensity values are ordered largest to smallest
        center=sort(center,'descend');
        %% find the specified number of pixels with the highest intensities
        [y,x] = find(ismember(peak,center(1:NumPixels)), NumPixels);
        %% translate the row and columns indices back to being relative to the whole image
        regions_rightside(:,:,m) = [y+rows(1)-1,x+cols(1)-1];
      end
      
      
      filename_forTrace=strrep(app.filename, app.fileExtension,'.traces');
      fid = fopen(filename_forTrace,'w');
      fwrite(fid,nframes,'int32'); %%% first line is number of frames % int32 takes 4 bytes of space and can store numbers ranged from -2147483648 to + 2147483647
      fwrite(fid,2*app.num_spots,'int16'); %%% second line is number of spots*2 % int16 takes 2 bytes of space and can store numbers ranged from -32768 to +32767
      
      % traces stored as 2D matrix size: (nframes) # of columns and
      % (1+2*app.num_spots) # of rows. First line of each column is
      % framenumber and rest of column is alternating Donor and
      % Acceptor intensity values for each spot
      traces = zeros(1+2*app.num_spots, nframes);
      for k=1:nframes
        traces(1,k)=realframenumbers(k); %% row1: first line is the framenumber, column (k) frame
        [temp_rightside, temp_leftside] = get_avg_frames(app,frameindex(k),1); %%% read intensities for this frame
        if k >= greenlaser_on && k <= greenlaser_off
          if app.RedWhiteGreenAltModeCheckBox.Value == true
            % temp_leftside = green frame, temp_rightside = white frame
            temp_rightside = temp_leftside(:,(1+app.imagesizex/2):app.imagesizex);
            temp_leftside = temp_leftside(:,1:app.imagesizex/2);
          end
          temp_rightside = temp_rightside-app.analysisBackground_greenFrames_rightside;  %%%THESE NEED TO BE INITIALIZED TO BACKGROUND WHEN NOT CLICKING A ALT MODE BOX - keith Nov 15 2024
          temp_leftside = temp_leftside-app.analysisBackground_greenFrames_leftside;
        else
          temp_rightside=temp_rightside-app.spotfindingBackground_rightside;
          temp_leftside=temp_leftside-app.spotfindingBackground_leftside;
        end
        % calculate intensities for trace
        for m=1:app.num_spots %% regions are the row and column coords of top 4 intensities
          traces(2*m,k)=trace(temp_leftside(regions_leftside(:,1,m),regions_leftside(:,2,m))); %trace function adds the intensities
          traces(2*m+1,k)=trace(temp_rightside(regions_rightside(:,1,m),regions_rightside(:,2,m)));
        end
        % save traces.
        fwrite(fid,traces(1:1+2*app.num_spots,k),'int16');
        % show a percentage bar
        if mod(k,5) == 0
          percent = (k/nframes)*100; batchpercent1 = (app.batchnumber/app.batchtotal)*100;
          app.InfoTextArea.Value = ['saving traces' newline app.filename newline num2str(percent) '%' newline 'Batch Progress: ' num2str(batchpercent1) '%'];
          drawnow;
        end
      end % end of loop on k frames
      
      fclose(fid);
      if (app.batchnumber/app.batchtotal)*100 == 100
        app.InfoTextArea.Value = ['done saving' newline filename_forTrace];
      end
      app.lastsavedtrace = filename_forTrace;

    end
    
    function saveRachelBigSpotTraces2(app) %, temp_image)
      % this code labels big spots if the coefficient of variation
      % (CV), (ratio of the standard deviation to the mean), of the pixels used
      % in the trace is less than 5% and the mean of the pixels
      % used for that trace is greater than the average mean of all traces
      % For the big spots it found, it fits a rectangular box around the spot by
      % comparing the pixel values on each edge of the box
      % to the threshold used for finding spots.
      % For each edge, if there are pixels that are > threshold, then
      % it expands that edge by one column or row until the edges of
      % the box are all lower than the threshold.
      % The pixels found when expanding the box around the big spot are
      % the pixels used to sum up the intensity values for those spots.
      % The rest of the spots are calculated the regular way
      
      % get & set initial values
      analysisSettings = zeros(app.num_spots, 5);
      bigSpots = [];
      squarewidth = app.SquareSizeAroundEachSpotEditField.Value; %% size of square matrix around each spot
      NumPixels = app.NumPixelsForSumIntensityEditField.Value; %% number of pixels to sum for intensity
      pixelValuesUsed = zeros(app.num_spots, NumPixels); 
      regions_rightside = zeros(NumPixels,2,app.num_spots); 
      squareAroundSpot = zeros(squarewidth,squarewidth,app.num_spots);
      CV_pixelsUsed = zeros(app.num_spots,1); 
      mean_pixelsUsed = zeros(app.num_spots,1);
      
      
%setoriginalandmovingpoints gives the same as the if statement below
%[~, ~, temp_image_bgSubtracted, temp_image_movingside] = setoriginalandmovingpoints(app, app.spotfindingImage_leftside, app.spotfindingImage_rightside, app.spotfindingBackground_leftside, app.spotfindingBackground_rightside);
      if app.whichside == "left"
        temp_image_averaged = app.spotfindingImage_leftside;
        temp_image_bgSubtracted = app.spotfindingImage_rightside-app.spotfindingBackground_rightside;
        xcoords = app.x_spots_leftside;
        ycoords = app.y_spots_leftside;
      elseif app.whichside == "right" 
        temp_image_bgSubtracted = app.spotfindingImage_rightside-app.spotfindingBackground_rightside;
        temp_image_averaged = app.spotfindingImage_rightside;
        xcoords = app.x_spots_rightside;
        ycoords = app.y_spots_rightside;
      elseif app.whichside == "both"
      end

      % for each spot, find the top num pixels with highest intensity
      for m=1:app.num_spots
        analysisSettings(m,:) = [NumPixels, floor(squarewidth/2), floor(squarewidth/2), floor(squarewidth/2), floor(squarewidth/2)];
        regions_rightside(:,:,m) = locatePixelsWithHighestIntensity(app, ycoords(m), xcoords(m),temp_image_averaged, squarewidth, NumPixels);
        squareAroundSpot(:,:,m) = temp_image_bgSubtracted(ycoords(m)-floor(squarewidth/2):ycoords(m)+floor(squarewidth/2),...
          xcoords(m)-floor(squarewidth/2):xcoords(m)+floor(squarewidth/2));
        pixelValuesUsed(m,:) = diag(temp_image_bgSubtracted(regions_rightside(:,1,m), regions_rightside(:,2,m)));
        mean_pixelsUsed(m) = mean(pixelValuesUsed(m,:));
        %%%% The coefficient of variation (CV) is defined as the ratio of the standard deviation to the mean,
        % It shows the extent of variability in relation to the mean of
        % the population. It is usually expressed as a percentage
        CV_pixelsUsed(m,1) = 100*std(pixelValuesUsed(m,:))/mean(pixelValuesUsed(m,:));
      end
      % flag the spots with a CV lower than 5%
      flag1 = find(CV_pixelsUsed < 5);
      % of those spots, find the ones with high intensity values
      flag2 = find(mean_pixelsUsed(flag1) > mean(pixelValuesUsed(:)));
      % these are the big spots
      bigSpotNumbers = flag1(flag2);
      % extract the big spot images
      bigSpotImages = squareAroundSpot(:,:,bigSpotNumbers);
      
      % not sure if this matters AT ALL, but delete variables that are
      % no longer needed:
      clear pixelValuesUsed squareAroundSpot CV_pixelsUsed areaOfSquare center K H C D peak flag1 flag2
      plotting = false;
      % For debugging, make plotting = true to show a graph of each spot
      for i = 1:length(bigSpotNumbers)
        fieldnameForThisSpot = "spot" + num2str(bigSpotNumbers(i));
        bigSpots.(fieldnameForThisSpot).temp = 1; %just to initialize spot field
        if plotting == true
          spotImage = bigSpotImages(:,:,i);
          bigSpots.(fieldnameForThisSpot).f = figure('Name',fieldnameForThisSpot);
          bigSpots.(fieldnameForThisSpot).f.Position(3) = 302*2;
          bigSpots.(fieldnameForThisSpot).f.Position(4) = 220*2;
          bigSpots.(fieldnameForThisSpot).layout = tiledlayout(bigSpots.(fieldnameForThisSpot).f,1,1);
          bigSpots.(fieldnameForThisSpot).ax = nexttile(bigSpots.(fieldnameForThisSpot).layout);
          axis(bigSpots.(fieldnameForThisSpot).ax, "image")
          
          image(bigSpots.(fieldnameForThisSpot).ax,spotImage,"CDataMapping","scaled");
          impixelinfo;
          colorbar
          bigSpots.(fieldnameForThisSpot).ax.XTickMode = "manual";
          bigSpots.(fieldnameForThisSpot).ax.YTickMode = "manual";
          bigSpots.(fieldnameForThisSpot).ax.XTick = [];
          bigSpots.(fieldnameForThisSpot).ax.YTick = [];
          bigSpots.(fieldnameForThisSpot).ax.XLimMode = "manual";
          bigSpots.(fieldnameForThisSpot).ax.YLimMode = "manual";
          bigSpots.(fieldnameForThisSpot).ax.XLim = [0.5, (size(spotImage,1)+0.5)];
          bigSpots.(fieldnameForThisSpot).ax.YLim = [0.5, (size(spotImage,1)+0.5)];
        end
      end
      % make a rectangular box around the big spot by expanding the
      % sides of the rectangle until the pixel values on the borders
      % are lower than threshold
      threshold = mean(mean_pixelsUsed) + std(mean_pixelsUsed);
      i = 1;
      while i <= length(bigSpotNumbers)
        m = bigSpotNumbers(i);
        fieldnameForThisSpot = "spot" + num2str(m);
        spotImage = bigSpotImages(:,:,i);
        if ~any(spotImage > threshold)
          fieldnameForThisSpot = "spot" + num2str(bigSpotNumbers(i));
          if plotting == true
            close(bigSpots.(fieldnameForThisSpot).f)
          end
          bigSpotImages(:,:,i) = [];
          bigSpots = rmfield(bigSpots,fieldnameForThisSpot);
          bigSpotNumbers(i) = [];
          i = i-1;
        else
          % start with a box that is twice the size of the usual box
          w = temp_image_bgSubtracted(ycoords(m)-squarewidth:ycoords(m)+squarewidth,xcoords(m)-squarewidth:xcoords(m)+squarewidth);
          % make a logical matrix that shows which pixels are above the
          % threshold
          bw = logical(w >= threshold);
          % check to see if any of the edges of the box have pixels
          % that are within the threshold limit
          checkingEdges = [any(bw(:,1)), any(bw(:,end)), any(bw(1,:)), any(bw(end,:))]; % left, right, top, bottom
          % keep track of the edges of the box
          squareEdges = ones(4, 1)*squarewidth;
          % continue expanding the box until all of the pixels on each
          % edge are lower than the threshold
          while any(checkingEdges)
            % increase the edges that still have pixels above the
            % threshold
            squareEdges(checkingEdges) = squareEdges(checkingEdges) + 1;
            % make sure we are still within the borders of the image
            coordsToUse = [xcoords(m)-squareEdges(1), xcoords(m)+squareEdges(2), ycoords(m)-squareEdges(3),   ycoords(m)+squareEdges(4)];
            coordLimits = [1,app.imagesizex/2, 1, app.imagesizey];
            limitsOutsideOfBorder = zeros(4,1,'logical');
            limitsOutsideOfBorder([1,3]) = logical(coordsToUse([1,3]) < coordLimits([1,3]));
            limitsOutsideOfBorder([2,4]) = logical(coordsToUse([2,4]) > coordLimits([2,4]));
            limitsInsideBorder = [xcoords(m)-1,  app.imagesizex/2 - xcoords(m) , ycoords(m) - 1, app.imagesizey - ycoords(m)];
            squareEdges(limitsOutsideOfBorder) = limitsInsideBorder(limitsOutsideOfBorder);
            checkingEdges(limitsOutsideOfBorder) = false;
            % get the pixel values for the new box size
            w = temp_image_bgSubtracted(ycoords(m)-squareEdges(3):ycoords(m)+squareEdges(4),xcoords(m)-squareEdges(1):xcoords(m)+squareEdges(2));
            bw = logical(w >= threshold);
            % check to make sure there are not other spots on the
            % edges. If there are, remove them from the logical matrix
            [L,n] = bwlabel(bw);
            if n > 1
              row = squareEdges(3) + 1;
              col = squareEdges(2) + 1;
              labelNumberForBigSpot = L(row,col);
              L(L ~= labelNumberForBigSpot) = 0;
              bw = logical(L);
            end
            checkingEdges = and([any(bw(:,1)), any(bw(:,end)), any(bw(1,:)), any(bw(end,:))],checkingEdges);
          end % end of while loop to expand the box size
          % store pixel values of the big spot
          fieldnameForThisSpot = "spot" + num2str(m);
          bigSpots.(fieldnameForThisSpot).Pixels = w(bw);
          % store analysis settings
          analysisSettings(m,2:5) = squareEdges;
          [row, col] = find(bw == true);
          analysisSettings(m,1) = length(row); %numpixels
          if plotting == true
            bigSpots.(fieldnameForThisSpot).ax.Children.CData= w;
            bigSpots.(fieldnameForThisSpot).ax.XLim = [0.5, (squareEdges(1) + squareEdges(2) + 1.5)];
            bigSpots.(fieldnameForThisSpot).ax.YLim = [0.5, (squareEdges(3) + squareEdges(4) + 1.5)];
            % mark pixels being used for trace
            line(bigSpots.(fieldnameForThisSpot).ax, col, row,'linestyle','none','marker','*','color','r');
          end     
          wholeImageBW = logical(temp_image_bgSubtracted >= threshold);
          wholeLabel = bwlabel(wholeImageBW);
          currentSpotLabelNumber = wholeLabel(ycoords(m), xcoords(m));
          wholeLabel(wholeLabel ~= currentSpotLabelNumber) = 0;
          wholeImageBW = logical(wholeLabel);
          bigSpots.(fieldnameForThisSpot).BW= wholeImageBW;
          [ycoordsInImage, xcoordsInImage] = find(wholeImageBW == true);
          n = 1; % check to make sure no other spots are using the same pixels
          while n <= app.num_spots
            squareSpotCoordsy = ycoords(n)-analysisSettings(n,4):ycoords(n)+analysisSettings(n,5);
            % because the big spots tend to have horizontal tails,
            % expand the x-coord area slightly on the right side
            amountToExpand = app.TailLengthpixelsSpinner.Value;
            xExpandedLeft = max(1, xcoords(n)-analysisSettings(n,2) - amountToExpand);
            xExpandedRight = min(app.imagesizex/2, xcoords(n)+analysisSettings(n,3) + amountToExpand);
            squareSpotCoordsx = xExpandedLeft:xExpandedRight;
            if n ~= m
              if any(ismember(squareSpotCoordsy, ycoordsInImage)) && any(ismember(squareSpotCoordsx, xcoordsInImage))
                % remove spots that are too close
                idx = find(bigSpotNumbers == m);
                disp("spot " + num2str(n) + " close to big spot " + num2str(idx))
                if ismember(n,bigSpotNumbers)
                  % might want to change this to compare the two big
                  % spots and see which we want to keep
                  idx = find(bigSpotNumbers == n);
                  bigSpotImages(:,:,idx) = [];
                  fieldnameForSpotn = "spot" + num2str(n);
                  bigSpots = rmfield(bigSpots,fieldnameForSpotn);
                  if plotting == true
                  close(bigSpots.(fieldnameForSpotn).f)
                  end
                  bigSpotNumbers(idx) = [];
                end
                xcoords(n) = [];
                ycoords(n) = [];
                app.x_spots_rightside(n) = [];
                app.y_spots_rightside(n) = [];
                app.x_spots_leftside(n) = []; % need to delete them still even though they are not being used
                app.y_spots_leftside(n) = [];
                regions_rightside(:,:,n) = [];
                % mean_pixelsUsed - could edit this but doesn't seem
                % necessary
                % need to shift spot numbers
                idx = find(bigSpotNumbers > n);
                bigSpotNumbers(idx) = bigSpotNumbers(idx) -1;
                %if plotting == true
                for q = 1:length(idx)
                  num = bigSpotNumbers(idx(q));
                  newfieldname = "spot" + num2str(num);
                  oldfieldname = "spot" + num2str(num + 1);
                  bigSpots.(newfieldname) = bigSpots.(oldfieldname);
                  bigSpots = rmfield(bigSpots,oldfieldname);
                  if plotting == true
                  bigSpots.(newfieldname).f.Name = newfieldname;
                  end
                end
                %end
                if n < m
                  m = m - 1;
                  fieldnameForThisSpot = "spot" + num2str(m);
                end
                app.num_spots = app.num_spots - 1;
                analysisSettings(n,:) = [];
                n = n-1;
              end % end of removing close spots
            end
            n = n + 1;
          end% end of checking for spots close by
        end
        i = i+1;
      end % end of loop on big spots
      
      updatedisplay(app);
      
      nframes = app.NumFrames;
      filename_forTrace=strrep(app.filename, app.fileExtension,'.traces');
      fid = fopen(filename_forTrace,'w');
      fwrite(fid,nframes,'int32'); %%% first line is number of frames % int32 takes 4 bytes of space and can store numbers ranged from -2147483648 to + 2147483647
      fwrite(fid,2*app.num_spots,'int16'); %%% second line is number of spots*2 % int16 takes 2 bytes of space and can store numbers ranged from -32768 to +32767
      
      % traces stored as 2D matrix size: (nframes) # of columns and
      % (1+2*app.num_spots) # of rows. First line of each column is
      % framenumber and rest of column is alternating Donor and
      % Acceptor intensity values for each spot
      traces = zeros(1+2*app.num_spots, nframes);
      %donorTraceValues = zeros(app.num_spots, nframes);
      for k=1:nframes
        traces(1,k)=k; %% row1: first line is the framenumber, column (k) frame
        [temp_rightside, temp_leftside] = get_avg_frames(app,k,1); %%% read intensities for this frame
        %%% subtract background
        if app.whichside == "left"
          temp_image = temp_leftside - app.spotfindingImage_leftside;
        elseif app.whichside == "right" 
          temp_image = temp_rightside - app.spotfindingBackground_rightside;
        end
        
        % calculate intensities for trace
        for m=1:app.num_spots
          if ismember(m, bigSpotNumbers)
            fieldnameForThisSpot = "spot" + num2str(m);
            pixelsThisFrame = temp_image(bigSpots.(fieldnameForThisSpot).BW);
            traces(2*m+1,k) = sum(pixelsThisFrame);
            traces(2*m,k) = 0;
            %donorTraceValues(m,k) = sum(pixelsThisFrame);
          else
            traces(2*m,k) = 0;
            traces(2*m+1,k)=trace(temp_image(regions_rightside(:,1,m),regions_rightside(:,2,m)));
            %donorTraceValues(m,k) = trace(temp_image(regions_rightside(:,1,m),regions_rightside(:,2,m)));
          end
        end
        % save traces.
        fwrite(fid,traces(1:1+2*app.num_spots,k),'int16');
        % show a percentage bar
        if mod(k,5) == 0
          percent = (k/app.NumFrames)*100;
          batchpercent1 = (app.batchnumber/app.batchtotal)*100;
          app.InfoTextArea.Value = ['saving traces' newline app.filename newline num2str(percent) '%' newline 'Batch Progress: ' num2str(batchpercent1) '%'];
          drawnow;
        end
      end
      app.BigSpotAnalysisInfo.BigSpotNumbers = bigSpotNumbers;
      app.BigSpotAnalysisInfo.NumPixels = analysisSettings(bigSpotNumbers,1);
      sqsz1 = analysisSettings(bigSpotNumbers,2) + analysisSettings(bigSpotNumbers,3) +1;
      sqsz2 = analysisSettings(bigSpotNumbers,4) + analysisSettings(bigSpotNumbers,5) +1;
      app.BigSpotAnalysisInfo.SquareSize = [sqsz1, sqsz2];
    end


    function saveLiveCellTraces(app)
      
      xcoords_leftside = app.x_spots_leftside;
      xcoords_rightside = app.x_spots_rightside;
      ycoords_leftside = app.y_spots_leftside;
      ycoords_rightside = app.y_spots_rightside;
      
      % framenumbers are not sequential for rgw alt mode
      % not sure if this will work with rgw alt mode either.. currently
      % we don't do live cell that way.
      if app.AnalysisTypeDropDown.Value == "FRET and TPM"
        firstframes = 1:app.greenlaserstartframe;
        frameindex = cat(2,firstframes,app.altframes_green);
        realframenumbers = cat(2,firstframes,app.realframes_green);
        nframes = size(realframenumbers,2);
      else
        startFrame = app.GreenStartFrameEditField.Value;
        endFrame = app.GreenEndFrameEditField.Value;
        frameindex = startFrame:endFrame;
        realframenumbers = startFrame:endFrame;
        nframes = size(realframenumbers,2);
      end
      
      regions_leftside=zeros(NumPixels,2,app.num_spots); %%% matricies size (NumPixels, 2), one for each spot
      regions_rightside=zeros(NumPixels,2,app.num_spots);
      app.InfoTextArea.Value = ['starting to save:' newline app.filename];
      drawnow;
      
      %create file
      filename_forTrace=strrep(app.filename, app.fileExtension,['.traces']);
      fid = fopen(filename_forTrace,'w');
      fwrite(fid,nframes,'int32'); %%% first line is number of frames % int32 takes 4 bytes of space and can store numbers ranged from -2147483648 to + 2147483647
      fwrite(fid,2*app.num_spots,'int16'); %%% second line is number of spots*2 % int16 takes 2 bytes of space and can store numbers ranged from -32768 to +32767
      
      % create second file for viewing in other apps
      for m = 1:app.num_spots
        app.spotTraceData(m).name = string(app.fileprefix) + "_mol" + num2str(m);
        app.spotTraceData(m).donor = zeros(nframes,1);
        app.spotTraceData(m).acceptor = zeros(nframes,1);
        app.spotTraceData(m).leftCoords = [xcoords_leftside(m), ycoords_leftside(m)];
        app.spotTraceData(m).rightCoords = [xcoords_rightside(m), ycoords_rightside(m)];
      end
      %%%% trying new method of finding the highest intensity pixels
      %%%% per frame instead of just once using an averaged frame
      for k=1:nframes
        % load image for this frame
        [temp_rightside, temp_leftside] = get_avg_frames(app,frameindex(k),1);
        if string(app.InvertSwitch.Value) == "Invert"
          %inverted image because beads are lower intensity than background
          temp_wholeImage_bg = [app.spotfindingBackground_leftside, app.spotfindingBackground_rightside];
          temp_wholeImage = [temp_leftside, temp_rightside];
          temp_wholeImage = bsxfun(@times, temp_wholeImage_bg, 2) - temp_wholeImage;    %strange way to mult. all elements by constant
          temp_leftside= temp_wholeImage(:, 1:app.imagesizex/2);
          temp_rightside = temp_wholeImage(:,(1+app.imagesizex/2):end);
        end
        
        % for each spot, find the top 4 pixels with highest intensity in
        % this frame
        for m=1:app.num_spots
          %% create a 3x3 square around each spot
          peak=temp_leftside(ycoords_leftside(m)-floor(squarewidth/2):ycoords_leftside(m)+floor(squarewidth/2),...
            xcoords_leftside(m)-floor(squarewidth/2):xcoords_leftside(m)+floor(squarewidth/2));
          %% reshape "peak" into a 9x1 matrix
          areaOfSquare = size(peak,1)*size(peak,1);
          center=reshape(peak,areaOfSquare,1);%center=reshape(peak,squarewidth*squarewidth,1);
          %% sort matrix so intensity values are ordered smallest to largest
          center=sort(center);
          %% find the coords in "peak" where the values are >= center(6), ie. the 4 largest intensity values in the 3x3 area
          %% I & J are 4x1 matricies describing the row (I) and column (J) #'s of the 4 largest intensity values in the 3x3 matrix "peak"
          [I,J]=find(peak>=center(areaOfSquare-NumPixels+1));%[I,J]=find(peak>=center(squarewidth*squarewidth-NumPixels+1));
          %% A & B make sure you only take the top 4. (In case there are two that are the same)
          A=I(1:NumPixels);
          B=J(1:NumPixels);
          %% regions are the row and column coords of top 4 intensities
          regions_leftside(:,:,m)=[A+ycoords_leftside(m)-floor(squarewidth/2)-1,B+xcoords_leftside(m)-floor(squarewidth/2)-1];
          
          peak2=temp_rightside(ycoords_rightside(m)-floor(squarewidth/2):ycoords_rightside(m)+floor(squarewidth/2),...
            xcoords_rightside(m)-floor(squarewidth/2):xcoords_rightside(m)+floor(squarewidth/2)); %% creates a 3x3 square around each spot
          
          center=reshape(peak2,areaOfSquare,1); %% reshapes "peak" into a 9x1 matrix
          center=sort(center); %% sorts matrix so values are smallest to largest
          [K,H]=find(peak2>=center(areaOfSquare-NumPixels+1)); %% finds the indices in "peak" where the values are >= center(6)
          C=K(1:NumPixels); %% I & J are row and column #'s
          D=H(1:NumPixels);
          regions_rightside(:,:,m)=[C+ycoords_rightside(m)-floor(squarewidth/2)-1,D+xcoords_rightside(m)-floor(squarewidth/2)-1];
          
        end
        % subtract background (bg calculated when analyze button pushed or batch analysis button pushed)
        if app.RedGreenAltModeCheckBox.Value == true
          greenlaser_on = app.GreenStartFrameEditField.Value;
          greenlaser_off = app.GreenEndFrameEditField.Value;
          if k > greenlaser_on && k < greenlaser_off
            temp_rightside=temp_rightside-app.analysisBackground_greenFrames_rightside;
            temp_leftside = temp_leftside-app.analysisBackground_greenFrames_leftside;
          else
            temp_rightside=temp_rightside-app.spotfindingBackground_rightside;
            temp_leftside=temp_leftside-app.spotfindingBackground_leftside;
          end
        elseif app.AnalysisTypeDropDown.Value == "FRET and TPM"
          if k > greenlaser_on
            temp_rightside=temp_rightside-app.analysisBackground_greenFrames_rightside;
            temp_leftside = temp_leftside-app.analysisBackground_greenFrames_leftside;
          else
            temp_rightside=temp_rightside-app.spotfindingBackground_rightside;
            temp_leftside=temp_leftside-app.spotfindingBackground_leftside;
          end
        else
          temp_rightside=temp_rightside-app.spotfindingBackground_rightside;
          temp_leftside=temp_leftside-app.spotfindingBackground_leftside;
        end


        % traces stored as 2D matrix size: (nframes) # of columns and
        % (1+2*app.num_spots) # of rows. First line of each column is
        % framenumber and rest of column is alternating Donor and
        % Acceptor intensity values for each spot
        traces = zeros(1+2*app.num_spots, nframes);
        %for k=1:nframes %% this is where the loop on frames used to be
        traces(1,k)=realframenumbers(k); %% row1: first line is the framenumber, column (k) frame
        
        % calculate intensities for trace
        for m=1:app.num_spots %% regions are the row and column coords of top 4 intensities
          traces(2*m,k)=trace(temp_leftside(regions_leftside(:,1,m),regions_leftside(:,2,m))); %trace function adds the intensities
          traces(2*m+1,k)=trace(temp_rightside(regions_rightside(:,1,m),regions_rightside(:,2,m)));
          % subtract the background inside the cell
          % findBackgroundInCells only takes the bg from the pixels
          % that are inside the cells found by the edge detection.
          % For now, use the regular square method
          %[backgroundAroundSpotInCell_mean,  backgroundAroundSpotInCell_med, pixelsUsed] = backgroundInCell_MedianMean(app, app.cellMap_leftside, regions_leftside(:,:,m), temp_leftside);
          %[backgroundAroundSpotInCell_mean,  backgroundAroundSpotInCell_med, pixelsUsed] = backgroundInCell_MedianMean(app, app.cellMap_rightside, regions_rightside(:,:,m), temp_rightside);
          backgroundInCell_leftside = backgroundInCell_SquareMethod(app, regions_leftside(:,:,m), temp_leftside);
          backgroundInCell_rightside = backgroundInCell_SquareMethod(app, regions_rightside(:,:,m), temp_rightside);
          traces(2*m,k) = traces(2*m,k) - backgroundInCell_leftside;
          traces(2*m+1,k) = traces(2*m+1,k) - backgroundInCell_rightside;
          app.spotTraceData(m).acceptor(k) = traces(2*m,k);
          app.spotTraceData(m).donor(k) = traces(2*m+1,k);
        end
        
        % save traces.
        fwrite(fid,traces(1:1+2*app.num_spots,k),'int16');
        
        % show a percentage bar
        if mod(k,5) == 0
          percent = (k/app.NumFrames)*100;
          batchpercent1 = (app.batchnumber/app.batchtotal)*100;
          app.InfoTextArea.Value = ['saving traces' newline app.filename newline num2str(percent) '%' newline 'Batch Progress: ' num2str(batchpercent1) '%'];
          drawnow;
        end
        
      end % end of loop on k frames
      
      fclose(fid);
      if batchpercent1 == 100
        app.InfoTextArea.Value = ['done saving' newline filename_forTrace];
      end
      app.lastsavedtrace = filename_forTrace;
      % Save spot trace data
      data = app.spotTraceData;
      save('spotTraceData', 'data');
    end
    
    function saveTPManalysis(app,fname)
      ImageForFittingSize = app.ImageforFittingSizeSpinner.Value; % this is the size in pixels around the spot (+1) (needs to be odd)
      NumPoints = app.num_spots;
      % framenumbers are not sequential for rgw alt mode
      if app.AnalysisTypeDropDown.Value == "FRET and TPM"
        nframes = length(app.whiteLightFrameIdx);
        framenumber = app.whiteLightFrameIdx;
        realframenumbers = app.whiteLightTemporalFramenumbers;
        background = [app.white_background_leftside, app.white_background_rightside];
      else
        nframes = app.NumFrames;
        framenumber = 1:nframes;
        realframenumbers = framenumber;
        background = [app.spotfindingBackground_leftside, app.spotfindingBackground_rightside];
      end
      
      
      FitResultsFramewise  = zeros(app.num_spots,2, nframes); % this will hold all fit results; 1 is x, 2 is y
      %app.squarespotimage = zeros(ImageForFittingSize , ImageForFittingSize , app.num_spots, nframes);
      %             app.squarespotcoordinfo = zeros(app.num_spots,4);
      
      %%% if whichside is not both, then it only records the movement
      %%% on one side.
      if app.whichside == "both"
        % check to make sure this works
        ycoord= [app.y_spots_leftside; app.y_spots_rightside];
        xspotsTranslated = app.x_spots_rightside + app.imagesizex/2;
        xcoord= [app.x_spots_leftside;xspotsTranslated];
      elseif app.whichside == "right"
        ycoord=app.y_spots_rightside;
        xcoord=app.x_spots_rightside;
      else
        ycoord=app.y_spots_leftside;
        xcoord=app.x_spots_leftside;
      end
      
      idx = 1;
      while(idx<=nframes)%loop on all frames
        %get the image data from one frame
        
        [temp_rightside, temp_leftside] = get_avg_frames(app,framenumber(idx),1);
        if app.RedWhiteGreenAltModeCheckBox.Value == true
          whiteFrame = temp_rightside;
          temp_leftside = whiteFrame(:,1:app.imagesizex/2);
          temp_rightside = whiteFrame(:,(1+app.imagesizex/2):end);
        end
        if string(app.InvertSwitch.Value) == "Invert"
          %inverted image because beads are lower intensity than background
          temp_wholeImage = [temp_leftside, temp_rightside];
          temp_wholeImage = bsxfun(@times, background, 2) - temp_wholeImage;    %strange way to mult. all elements by constant
          temp_leftside= temp_wholeImage(:, 1:app.imagesizex/2);
          temp_rightside = temp_wholeImage(:,(1+app.imagesizex/2):end);
        end
        temp_wholeImage = [temp_leftside, temp_rightside];
        % subtract background
        temp_image= temp_wholeImage - background;
        
        for i = 1:app.num_spots
          
          %get the image data around the bead
          tempSpot=temp_image(ycoord(i)-floor(ImageForFittingSize/2):ycoord(i)+floor(ImageForFittingSize/2),...
            xcoord(i)-floor(ImageForFittingSize/2):xcoord(i)+floor(ImageForFittingSize/2));
          
          % Return output relative to upper left coordinate
          [xc, yc] = radialcenter(app,tempSpot);
          
          % hold results of fits
          FitResultsFramewise(i,1, idx)=xc; % i is point #,idx is frame
          FitResultsFramewise(i,2, idx)=yc; % i is point #,idx is frame
          
          % store image data around spot
          %app.squarespotimage(:,:,i,idx) = tempSpot;
          
        end %this is the end of fitting all beads for this frame increment
        % show a percentage bar
        if mod(idx,20) == 0
          percent = (idx/app.NumFrames)*50;%half percent from here and half from saving
          batchpercent1 = (app.batchnumber/app.batchtotal)*100;
          app.InfoTextArea.Value = ['saving TPM' newline app.filename newline ...
            num2str(percent) '%' newline 'Batch Progress: ' num2str(batchpercent1) '%'];
          drawnow;
        end
        idx = idx + 1;
      end%this is end loop on frames
      
      % save analysis one text file for each spot
      for i = 1:NumPoints
        fname1 = fname + num2str(i)+ '.txt'; %fname is "cascade(j)TBPmol"
        f = fopen(fname1, 'w');
%         if app.AnalysisTypeDropDown.Value == "FRET and TPM"
%           for d=2:(nframes-1) %for rwg alt mode, I am adding row1column1 = realframenumber
%             fprintf(f, num2str(realframenumbers(d-1)));
%             fprintf(f, '	');
%             fprintf(f, num2str(FitResultsFramewise(i,1, d)));
%             fprintf(f, '	');
%             fprintf(f, num2str(FitResultsFramewise(i,2, d)));
%             fprintf(f, '\n');
%           end
%         else
          for d=2:(nframes-1)
            fprintf(f, num2str(FitResultsFramewise(i,1, d)));
            fprintf(f, '	');
            fprintf(f, num2str(FitResultsFramewise(i,2, d)));
            fprintf(f, '\n');
          end
%         end
        
        fclose(f);
        % show a percentage bar
        if mod(i,5) == 0
          percent2 = (100-percent)*(i/NumPoints) + percent;
          app.InfoTextArea.Value = ['saving TPM' newline app.filename newline ...
            num2str(percent2) '%' newline 'Batch Progress: ' num2str(batchpercent1) '%'];
          drawnow;
        end
      end % end loop on NumPoints
      
      app.InfoTextArea.Value = ['donesaving' fname];
      app.lastsavedTPM = fname1;
      beep
    end
    
    function [xc, yc] = radialcenter(~,I)
      % radialcenter.m
      %
      % Copyright 2011-2012, Raghuveer Parthasarathy, The University of Oregon
      %
      %%
      % Disclaimer / License
      %   This program is free software: you can redistribute it and/or
      %     modify it under the terms of the GNU General Public License as
      %     published by the Free Software Foundation, either version 3 of the
      %     License, or (at your option) any later version.
      %   This set of programs is distributed in the hope that it will be useful,
      %   but WITHOUT ANY WARRANTY; without even the implied warranty of
      %   MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the GNU
      %   General Public License for more details.
      %   You should have received a copy of the GNU General Public License
      %   (gpl.txt) along with this program.  If not, see <http://www.gnu.org/licenses/>.
      %%
      %
      % Calculates the center of a 2D intensity distribution.
      % Method: Considers lines passing through each half-pixel point with slope
      % parallel to the gradient of the intensity at that point.  Considers the
      % distance of closest approach between these lines and the coordinate
      % origin, and determines (analytically) the origin that minimizes the
      % weighted sum of these distances-squared.
      %
      % Inputs
      %   I  : 2D intensity distribution (i.e. a grayscale image)
      %        Size need not be an odd number of pixels along each dimension
      %
      % Outputs
      %   xc, yc : the center of radial symmetry,
      %            px, from px #1 = left/topmost pixel
      %            So a shape centered in the middle of a 2*N+1 x 2*N+1
      %            square (e.g. from make2Dgaussian.m with x0=y0=0) will return
      %            a center value at x0=y0=N+1.
      %            Note that y increases with increasing row number (i.e. "downward")
      %   sigma  : Rough measure of the width of the distribution (sqrt. of the
      %            second moment of I - min(I));
      %            Not determined by the fit -- output mainly for consistency of
      %            formatting compared to my other fitting functions, and to get
      %            an estimate of the particle "width"
      %
      % Raghuveer Parthasarathy
      % The University of Oregon
      % August 21, 2011 (begun)
      % last modified Apr. 6, 2012 (minor change)
      % Copyright 2011-2012, Raghuveer Parthasarathy
      
      
      % Number of grid points
      [Ny, Nx] = size(I);
      
      
      % grid coordinates are -n:n, where Nx (or Ny) = 2*n+1
      % grid midpoint coordinates are -n+0.5:n-0.5;
      % The two lines below replace
      %    xm = repmat(-(Nx-1)/2.0+0.5:(Nx-1)/2.0-0.5,Ny-1,1);
      % and are faster (by a factor of >15 !)
      % -- the idea is taken from the repmat source code
      xm_onerow = -(Nx-1)/2.0+0.5:(Nx-1)/2.0-0.5;
      xm = xm_onerow(ones(Ny-1, 1), :);
      % similarly replacing
      %    ym = repmat((-(Ny-1)/2.0+0.5:(Ny-1)/2.0-0.5)', 1, Nx-1);
      ym_onecol = (-(Ny-1)/2.0+0.5:(Ny-1)/2.0-0.5)';  % Note that y increases "downward"
      ym = ym_onecol(:,ones(Nx-1,1));
      
      % Calculate derivatives along 45-degree shifted coordinates (u and v)
      % Note that y increases "downward" (increasing row number) -- we'll deal
      % with this when calculating "m" below.
      dIdu = I(1:Ny-1,2:Nx)-I(2:Ny,1:Nx-1);
      dIdv = I(1:Ny-1,1:Nx-1)-I(2:Ny,2:Nx);
      
      % Smoothing --
      h = ones(3)/9;  % simple 3x3 averaging filter
      fdu = conv2(dIdu, h, 'same');
      fdv = conv2(dIdv, h, 'same');
      dImag2 = fdu.*fdu + fdv.*fdv; % gradient magnitude, squared
      
      % Slope of the gradient .  Note that we need a 45 degree rotation of
      % the u,v components to express the slope in the x-y coordinate system.
      % The negative sign "flips" the array to account for y increasing
      % "downward"
      m = -(fdv + fdu) ./ (fdu-fdv);
      
      % *Very* rarely, m might be NaN if (fdv + fdu) and (fdv - fdu) are both
      % zero.  In this case, replace with the un-smoothed gradient.
      NNanm = sum(isnan(m(:)));
      if NNanm > 0
        unsmoothm = (dIdv + dIdu) ./ (dIdu-dIdv);
        m(isnan(m))=unsmoothm(isnan(m));
      end
      % If it's still NaN, replace with zero. (Very unlikely.)
      NNanm = sum(isnan(m(:)));
      if NNanm > 0
        m(isnan(m))=0;
      end
      
      %
      % Almost as rarely, an element of m can be infinite if the smoothed u and v
      % derivatives are identical.  To avoid NaNs later, replace these with some
      % large number -- 10x the largest non-infinite slope.  The sign of the
      % infinity doesn't matter
      try
        m(isinf(m))=10*max(m(~isinf(m)));
      catch
        % if this fails, it's because all the elements are infinite.  Replace
        % with the unsmoothed derivative.  There's probably a more elegant way
        % to do this.
        m = (dIdv + dIdu) ./ (dIdu-dIdv);
      end
      
      
      % Shorthand "b", which also happens to be the
      % y intercept of the line of slope m that goes through each grid midpoint
      b = ym - m.*xm;
      
      % Weighting: weight by square of gradient magnitude and inverse
      % distance to gradient intensity centroid.
      sdI2 = sum(dImag2(:));
      xcentroid = sum(sum(dImag2.*xm))/sdI2;
      ycentroid = sum(sum(dImag2.*ym))/sdI2;
      w  = dImag2./sqrt((xm-xcentroid).*(xm-xcentroid)+(ym-ycentroid).*(ym-ycentroid));
      
      % least-squares minimization to determine the translated coordinate
      % system origin (xc, yc) such that lines y = mx+b have
      % the minimal total distance^2 to the origin:
      wm2p1 = w./(m.*m+1);
      sw  = sum(sum(wm2p1));
      smmw = sum(sum(m.*m.*wm2p1));
      smw  = sum(sum(m.*wm2p1));
      smbw = sum(sum(m.*b.*wm2p1));
      sbw  = sum(sum(b.*wm2p1));
      det = smw*smw - smmw*sw;
      xc = (smbw*sw - smw*sbw)/det;    % relative to image center
      yc = (smbw*smw - smmw*sbw)/det; % relative to image center


      %%
      % Return output relative to upper left coordinate
      xc = xc + (Nx+1)/2.0;
      yc = yc + (Ny+1)/2.0;
      
      % A rough measure of the particle width.
      % Not at all connected to center determination, but may be useful for tracking applications;
      % could eliminate for (very slightly) greater speed
      %             Isub = I - min(I(:));
      %             [px,py] = meshgrid(1:Nx,1:Ny);
      %             xoffset = px - xc;
      %             yoffset = py - yc;
      %             r2 = xoffset.*xoffset + yoffset.*yoffset;
      %             sigma = sqrt(sum(sum(Isub.*r2))/sum(Isub(:)))/2;  % second moment is 2*Gaussian width
    end
    
    function saveAllAppSettings(app,event)

      classesThatDontNeedToBeSaved = {'matlab.ui.control.TextArea', 'matlab.ui.control.Slider', ...
        'matlab.ui.container.TabGroup', 'matlab.ui.control.EditField', 'matlab.ui.container.Tab', ...
        'matlab.graphics.layout.TiledChartLayout', 'matlab.ui.controls.AxesToolbar'};
      classesToOnlySaveEnableProp = {'matlab.ui.control.Label', 'matlab.ui.control.Button', 'matlab.ui.control.StateButton'};
      propertiesThatDontNeedToBeSaved = {'app.bigSpotLayout', 'app.bigSpotAxis', 'app.batchAnalysisMode','app.path', ...
        'app.filename', 'app.fileprefix', 'app.cameratype', 'app.fileExtension', 'app.numCameras'};
      % properties that are general for all movie files in the folder
      selectedProperties_frameNumSettings = { 'app.numgreenlaserframeson', 'app.numwhitelightframeson', 'app.numgreenlaserframesoff', ...
        'app.numwhitelightframesoff', 'app.greenlaserstartframe', 'app.whitelightstartframe', 'app.altframes_white', ...
        'app.altframes_green', 'app.realframes_white', 'app.realframes_green'};
      selectedProperties_spotFindingSettings = {'app.blocksize', 'app.manualNearestNeighborMode', 'app.manualBlocksize', 'app.whichside' };
      selectedProperties_displaySettings = {'app.colorMapForImage', 'app.leftspotcolor', 'app.rightspotcolor' };

      % properties that are only for the current movie file
      selectedProperties_spotFindingResults = {'app.x_spots_leftside', 'app.x_spots_rightside', 'app.y_spots_leftside', 'app.y_spots_rightside', ...
       'app.xoffset', 'app.yoffset', 'app.f_map', 'app.f_map_forDisplayInTextArea', 'app.num_spots', 'app.keep_spots_displayed', ...
       'app.spotfindingImage_rightside', 'app.spotfindingImage_leftside', 'app.spotfindingBackground_rightside', 'app.spotfindingBackground_leftside'};
      selectedProperties_liveCellResults = {'app.cellMap_leftside', 'app.cellMap_rightside', 'app.liveCellImage', 'app.edgeMask_leftside', ...
      'app.edgeMask_rightside', 'app.numSpotsNotInACellText', 'app.numSpotsInCellText', 'app.enhancedImage_rightside', 'app.enhancedImage_leftside', ...
      'app.labeledImage_leftside', 'app.labeledImage_rightside'};
      selectedProperties_bigSpotResults = {'app.BigSpotAnalysisInfo'};
      
      selectedPropertiesToSave = [selectedProperties_spotFindingResults, selectedProperties_liveCellResults, ...
        selectedProperties_bigSpotResults];
      generalPropertiesToSave = [selectedProperties_frameNumSettings, selectedProperties_spotFindingSettings, ...
        selectedProperties_displaySettings];
      classesThatIncludeSizeSettings = {'matlab.ui.Figure', 'matlab.ui.container.GridLayout', 'matlab.ui.container.Panel'};

      % settings are saved under their normal fieldnames. If it is a
      % variable, the value is stored under the fieldname "Variables"
      generalSettings = [];
      specificSettings = [];
      sizeSettings = [];
      props = properties(app);
      for i = 1:length(props)
        propName = props{i};
        propertyNameWithApp = strcat('app.',propName);
        property = app.(propName);
        propertyClass = class(property);
        % skip over the ones that don't need to be saved
        if ismember(propertyClass, classesThatDontNeedToBeSaved) || ismember(propertyNameWithApp, propertiesThatDontNeedToBeSaved)
          continue
        end
        %% size settings
        if ismember(propertyClass, classesThatIncludeSizeSettings)
          if ismember(propertyClass, 'matlab.ui.Figure')
            sizeSettings.(propName).Position = property.Position;
            continue
          end
          if property == app.GuiForPrimeGridLayout
            sizeSettings.(propName).ColumnWidth = property.ColumnWidth;
            sizeSettings.(propName).RowHeight = property.RowHeight;
            sizeSettings.(propName).ColumnSpacing = property.ColumnSpacing;
            sizeSettings.(propName).RowSpacing = property.RowSpacing;
            sizeSettings.(propName).Padding = property.Padding;
            continue
          end
          if property == app.moviePanel_outer 
            sizeSettings.(propName).Position = property.Position;
            continue
          end
          continue
        end
        %% general settings
        if ismember(propertyNameWithApp, generalPropertiesToSave)
          if ~isempty(property)
            generalSettings.(propName).Variables = property;
            continue
          end
        end
        if ismember(propertyClass, 'matlab.ui.container.Menu')
          if isfield(property, 'Checked')
            generalSettings.(propName).Checked = property.Checked;
          end
          continue
        end
        % maybe separate out checkboxes between general and specific..
        if ismember(propertyClass, {'matlab.ui.control.DropDown', 'matlab.ui.control.Switch', 'matlab.ui.control.CheckBox'})
          if property == app.UseCurrentMappingCheckBox
            generalSettings.(propName).Value = property.Value; %string or char
            generalSettings.LoadLastMappingButton.Enable = app.LoadLastMappingButton.Enable;
          else
            specificSettings.(propName).Value = property.Value; %string or char, logical for checkbox
            specificSettings.(propName).Enable = property.Enable;
          end
          continue
        end
        if ismember(propertyClass, {'matlab.ui.control.NumericEditField', 'matlab.ui.control.Spinner'})
          generalSettings.(propName).Value = property.Value; %string or char
          specificSettings.(propName).Enable = property.Enable;
          continue
        end
        %% specific settings
        % uncomment and specify settings in loading function if you want to
        % load toolbar settings. Also remove from classes not to save
%         if ismember(propertyClass, 'matlab.ui.controls.AxesToolbar')
%           buttons = property.Children;
%           specificSettings.(propName).Children = buttons;
%           for j = 1:size(buttons,1)
%             if ismember(class(buttons(j)), 'matlab.ui.controls.ToolbarStateButton')
%               specificSettings.(propName).Children(j).Value = buttons(j).Value;
%             end
%           end
%           continue
%         end
        if ismember(propertyNameWithApp, selectedPropertiesToSave)
          if ~isempty(property)
            specificSettings.(propName).Variables = property;
          end
          continue
        end
        if ismember(propertyClass, {'matlab.graphics.axis.Axes'})
          specificSettings.(propName).CLim = property.CLim;
          continue
        end

       if ismember(propertyClass, classesToOnlySaveEnableProp)
          specificSettings.(propName).Enable = property.Enable;
          continue
       end
      end


      % save the generalsetttings in a folder called "guiForPrime", which is a
      % subfolder of "DefaultSettings", which is a folder that is saved in
      % the same folder as the app. If any of the folders do not exist,
      % then they will be created.
      %if string(event.Source.Tag) == "all"
        [filepath,~,~] = fileparts(which('guiForPrime.mlapp'));
        folderNameWithPath = fullfile(filepath, 'DefaultSettings', 'guiForPrime');
        %folderNameWithPath = string(savePlace(1:k)) + "DefaultSettings\guiForPrime";
        if isfolder(folderNameWithPath) == false
          mkdir(folderNameWithPath);
        end
        savedSettingsFileNameWithPath = fullfile(folderNameWithPath, "savedSettings.mat");
       % savedSettingsFileNameWithPath = folderNameWithPath + "\savedSettings.mat";
        if isfile(savedSettingsFileNameWithPath)
          matObj = matfile(savedSettingsFileNameWithPath,'Writable',true);
          matObj.dateLastSaved = datestr(now);
          matObj.generalSettings = generalSettings;
          matObj.sizeSettings = sizeSettings;
        else
          dateLastSaved = datestr(now);
          save(savedSettingsFileNameWithPath, "dateLastSaved","generalSettings", "sizeSettings", '-v7.3');
        end
      %end
      % save the settings specific to the opened movie in a folder called
      % "Settings\guiForPrime", located in the current folder with the data
      if ~isempty(app.filename)
        movieFileName = string(erase(app.filename,app.fileExtension)) + "settings";
      else
        updateTextInfo(app);
        app.InfoTextArea.Value = ["App settings saved"; app.InfoTextArea.Value];
        return
      end
      dataSettingsFolder = fullfile(cd, 'Settings', 'guiForPrime');
      if isfolder(dataSettingsFolder) == false
        mkdir(dataSettingsFolder);
      end
      %specificSettingsFileName = dataSettingsFolder + "\specificSettings.mat";
      savedSettingsFileNameWithPath = fullfile(dataSettingsFolder, "specificSettingsForThisDataSet.mat");
      if isfile(savedSettingsFileNameWithPath)
        matObj2 = matfile(savedSettingsFileNameWithPath,'Writable',true);
        matObj2.(movieFileName) = specificSettings;
      else
        settings.(movieFileName) = specificSettings;
        save(savedSettingsFileNameWithPath, '-struct','settings', '-v7.3');
      end
      updateTextInfo(app);
      app.InfoTextArea.Value = ["App settings saved"; app.InfoTextArea.Value];
    end
    
    function loadAllAppSettings(app)
      % first load general settings which are saved in the folder that the
      % guiForPrime.mlapp software is saved in
%       savePlace = which('guiForPrime.mlapp');
      [filepath,~,~] = fileparts(which('guiForPrime.mlapp'));
      savedSettingsFileNameWithPath = fullfile(filepath, 'DefaultSettings', 'guiForPrime', "savedSettings.mat");
%       k = strfind(savePlace,'\');
%       k = k(end);
%       savedSettingsFileNameWithPath = string(savePlace(1:k)) + "DefaultSettings\guiForPrime\savedSettings.mat";
      if ~isfile(savedSettingsFileNameWithPath)
        app.InfoTextArea.Value = "No previous settings found";
      else
        matObj = matfile(savedSettingsFileNameWithPath);
        dateLastSaved = matObj.dateLastSaved;
        
        generalSettings = matObj.generalSettings;
        fields = fieldnames(generalSettings);
        numSettingsSaved = size(fields,1);
        for i = 1:numSettingsSaved
          propName = fields{i};
          if isfield(generalSettings.(propName),'Variables')
            app.(propName) = generalSettings.(propName).Variables;
          else
            moreFields = fieldnames(generalSettings.(propName));
            for j = 1:size(moreFields,1)
              morePropNames = moreFields{j};
              app.(propName).(morePropNames) = generalSettings.(propName).(morePropNames);
            end
          end
        end
      end
      % then load settings specific to opened file, if there is a file
      % opened and if there are settings saved for it
      if ~isempty(app.filename)
        movieFileName = string(erase(app.filename,app.fileExtension)) + "settings";
      else
        return
      end
      specificSettingsFileName = fullfile(cd, 'Settings', 'guiForPrime', 'specificSettingsForThisDataSet.mat');
      %specificSettingsFileName = string(cd) + "\Settings\guiForPrime\specificSettingsForThisDataSet.mat";
      if isfile(specificSettingsFileName) == false
        return
      else
        matObj = matfile(specificSettingsFileName);
        movieFilesWithSavedSettings = who(matObj);
      end
      if ismember(movieFileName, movieFilesWithSavedSettings)
        movieSettings = matObj.(movieFileName);
      else
        return
      end
        fields = fieldnames(movieSettings);
        numSettingsSaved = size(fields,1);
        for i = 1:numSettingsSaved
          propName = fields{i};
          if isfield(movieSettings.(propName),'Variables')
            app.(propName) = movieSettings.(propName).Variables;
          %elseif isfield(movieSettings.(propName), 'Children')
          else
            moreFields = fieldnames(movieSettings.(propName));
            for j = 1:size(moreFields,1)
              %morePropNames = moreFields{j};
              app.(propName).(moreFields{j}) = movieSettings.(propName).(moreFields{j});
            end
          end
        end
%         for i = 1:numSettingsSaved
%           propName = fields{i};
%           loadMethod = movieSettings.(propName).LoadMethod;
%           if loadMethod == "individuallyPicked"
%             loadingInstructions = movieSettings.(propName).LoadingInstructions;
%             if loadingInstructions == "checkFileInfo"
%               % for now, don't load. Later change to check that filename is
%               % the same in saved settings as now, then only load spot info
%               % if the files are the same
% %             elseif loadingInstructions == "framesToUseInfo"
% %               % later, make sure it is the same file as before before
% %               % loading this. BUT, the ones sorted by classes are already
% %               % going to be loaded, so maybe it doesn't matter
% %               property = movieSettings.(propName).valuesToLoad.Value;
% %             elseif ismember(loadingInstructions, ["assignValue", "spotFindingSettings"])
% %               property = movieSettings.(propName).valuesToLoad.Value;
%             elseif loadingInstructions == "spotFindingResults"
%               app.(propName) = movieSettings.(propName).valuesToLoad.Value;
%             elseif ismember(loadingInstructions, ["liveCellResults", "bigSpotResults"])
%               % for now, just load it, but later check to make sure that it
%               % is the same file before loading.
%               app.(propName) = movieSettings.(propName).valuesToLoad.Value;
%             end
%           else
%             propertyValues = fieldnames(movieSettings.(propName).valuesToLoad);
%             for j = 1:size(propertyValues,1)
%               app.(propName).(propertyValues{j}) = movieSettings.(propName).valuesToLoad.(propertyValues{j});
%             end
%           end
%         end
      updateTextInfo(app);
      updatedisplay(app);
        app.InfoTextArea.Value = ["Date settings were saved: " + dateLastSaved; app.InfoTextArea.Value];
      
    end


  end %% end of methods(Access = public)
  
  methods (Access = private)
    
    function callback_keepAspectRatioConstant(app, s,e)
      ratio = app.imagesizex/app.imagesizey;
      %width = app.moviePanel_outer.InnerPosition(3);
      %height = app.moviePanel_outer.InnerPosition(4);
      % width = ratio*height
      if ratio*app.moviePanel_outer.InnerPosition(4) < app.moviePanel_outer.InnerPosition(3)
        app.moviePanel.Position(4) = app.moviePanel_outer.InnerPosition(4);

        app.moviePanel.Position(3) = app.moviePanel_outer.InnerPosition(4)*ratio;
        app.moviePanel.Position(2) = 1;
        app.moviePanel.Position(1) = (app.moviePanel_outer.InnerPosition(3) - app.moviePanel.OuterPosition(3))/2;
      else
        app.moviePanel.Position(3) = app.moviePanel_outer.InnerPosition(3);
        app.moviePanel.Position(4) = app.moviePanel_outer.InnerPosition(3)/ratio;
        app.moviePanel.Position(1) = 1;
        app.moviePanel.Position(2) = (app.moviePanel_outer.InnerPosition(4) - app.moviePanel.OuterPosition(4))/2;
      end
    end
    function callback_keepRealsizeImage(app, ~,~)
      if app.moviePanel_outer.InnerPosition(3) < app.imagesizex + 3
        % NOte:
        %app.guiForPrimeUIFigure.Position(3) -
        %app.GuiForPrimeGridLayout.ColumnWidth{4} = app.moviePanel_outer.Position(3)
%         sizeOfWeightedPartOfGridColumns = app.guiForPrimeUIFigure.Position(3) - app.GuiForPrimeGridLayout.ColumnWidth{4} ...
%           - app.PixelInfoRightside.OuterPosition(3) - app.PixelInfoLeftside.OuterPosition(3);
        minSizeOfWeightedPartOfGridColumns = (app.imagesizey + 5) - app.PixelInfoRightside.OuterPosition(3) - ...
          app.PixelInfoLeftside.OuterPosition(3);
        % this is so that it doesn't funk out
        screenSize = get(groot,'ScreenSize');
        fixedPosition = (app.imagesizex + 5) + app.GuiForPrimeGridLayout.ColumnWidth{4};
        app.guiForPrimeUIFigure.Position(1) = (screenSize(3) - fixedPosition)/3;
        app.guiForPrimeUIFigure.Position(3) = fixedPosition;
        % manually change grid layout
        app.GuiForPrimeGridLayout.ColumnWidth{2} = minSizeOfWeightedPartOfGridColumns;

      else
        app.GuiForPrimeGridLayout.ColumnWidth{2} = '9x';
        app.moviePanel.Position(1) = (app.moviePanel_outer.InnerPosition(3) - app.moviePanel.OuterPosition(3))/2;
      end
      if app.moviePanel_outer.InnerPosition(4) < app.imagesizey + 3
%         sizeOfWeightedPartOfGridRows = app.guiForPrimeUIFigure.Position(4) - app.GuiForPrimeGridLayout.RowHeight{6} ...
%           - app.PixelInfoRightside.OuterPosition(4) - app.FrameNumberEditField.Position(4) ...
%           - app.InfoAndMappingPanel.Position(4); %make sure app.GuiForPrimeGridLayout.RowSpacing = 0;
        minSizeOfWeightedPartOfGridRows = (app.imagesizey + 5) - app.InfoAndMappingPanel.Position(4);
        bigWeightedPart = (minSizeOfWeightedPartOfGridRows)*(10/12);
        smallWeightedPart = (minSizeOfWeightedPartOfGridRows)*(2/12);
        % this is so that it doesn't funk out
        screenSize = get(groot,'ScreenSize');
        
        fixedPosition = (app.imagesizey + 5) + app.GuiForPrimeGridLayout.RowHeight{6} + app.PixelInfoRightside.OuterPosition(4) + ...
          app.FrameNumberEditField.Position(4);
        app.guiForPrimeUIFigure.Position(2) = (screenSize(4) - fixedPosition)/3;%screenSize(4)/4;
        app.guiForPrimeUIFigure.Position(4) = fixedPosition;
        %app.guiForPrimeUIFigure.Position([2,4]) = [screenSize(4)/4, fixedPosition];
        app.GuiForPrimeGridLayout.RowSpacing

        % manually change grid layout
        app.GuiForPrimeGridLayout.RowHeight{[2,3]} = [bigWeightedPart, smallWeightedPart];
        %app.GuiForPrimeGridLayout.RowHeight{2} = bigWeightedPart;
        %app.GuiForPrimeGridLayout.RowHeight{3} = smallWeightedPart;
      else
        app.GuiForPrimeGridLayout.RowHeight{2} = '10x';
        app.GuiForPrimeGridLayout.RowHeight{3} = '2x';
        app.moviePanel.Position(2) = (app.moviePanel_outer.InnerPosition(4) - app.moviePanel.OuterPosition(4))/2;
%         app.GuiForPrimeGridLayout.RowSpacing
%         app.guiForPrimeUIFigure.Position
%         app.moviePanel.Position(2)
      end
    end
    
    
    function callback_restoreView(app, source, event)
%       bothSides = findobj(source.Parent.Children, "Tag", "BothOrOne");
%       if bothSides.Value == false
%         if string(source.Tag) == "Right"
%           app.movieAxisRightside.XLim = [0.5, (app.imagesizex/2 + 0.5)];
%           app.movieAxisRightside.YLim = [0.5, (app.imagesizey + 0.5)];
%         elseif string(source.Tag) == "Left"
%           app.movieAxisLeftside.XLim = [0.5, (app.imagesizex/2 + 0.5)];
%           app.movieAxisLeftside.YLim = [0.5, (app.imagesizey + 0.5)];
%         end
%       else
        app.movieAxisRightside.XLim = [0.5, (app.imagesizex/2 + 0.5)];
        app.movieAxisRightside.YLim = [0.5, (app.imagesizey + 0.5)];
        app.movieAxisLeftside.XLim = [0.5, (app.imagesizex/2 + 0.5)];
        app.movieAxisLeftside.YLim = [0.5, (app.imagesizey + 0.5)];
%       end
      updatedisplay(app);
    end
    
    function callback_closeApp(app,src,event)
      %display(event.SelectedOption);
      if event.SelectedOption == "OK"
      delete(app);
      end
    end
    
    function callback_zoomToolbarButtonPressed(app, source, event)
      if source.Value == true
        % re-assign button down functions to make zoom rectangle
        app.movieAxisLeftside.ButtonDownFcn = @(s,d)callback_zoomIn(app,s,d);
        app.movieAxisRightside.ButtonDownFcn = @(s,d)callback_zoomIn(app,s,d);
        % set hit test for images to off. Otherwise the axis btn down fcn
        % won't get called
        set(app.movieAxisLeftside.Children, 'HitTest', "off", "PickableParts", 'none');
        set(app.movieAxisRightside.Children, 'HitTest', "off", "PickableParts", 'none');

%         app.moviePanel_outer.HandleVisibility = "off";
%         app.moviePanel.HandleVisibility = "off";
%         app.movieAxisLeftside.Interruptible = "off";
%         app.movieAxisRightside.Interruptible = "off";
        
      else
        app.movieAxisLeftside.ButtonDownFcn = [];
        app.movieAxisRightside.ButtonDownFcn = [];
        % set these back to normal - not sure if mouse pointer callbacks
        % won't work if these aren't set back.
                set(app.movieAxisLeftside.Children, 'HitTest', "on", "PickableParts", 'visible');
        set(app.movieAxisRightside.Children, 'HitTest', "on", "PickableParts", 'visible');
%                 app.movieAxisLeftside.Interruptible = "on";
%         app.movieAxisRightside.Interruptible = "on";
      end
      drawnow;
    end

    function callback_zoomIn(app, source, event)
%       squareZoomArea = findobj(source.Parent.Children, "Tag", "square");
% %       app.guiForPrimeUIFigure.CurrentAxes.Tag
%       if squareZoomArea.Value == true
%         source.UserData = drawrectangle(event.source, "FixedAspectRatio",true, "AspectRatio",1,'Color',rand([1,3]));
% %         source.UserData = drawrectangle( event.Axes(1),"FixedAspectRatio",true, "AspectRatio",1,'Color',rand([1,3]), ...
% %           'DrawingArea', 'unlimited');
% %         source.UserData = drawrectangle(app.guiForPrimeUIFigure.CurrentAxes,"FixedAspectRatio",true, "AspectRatio",1,'Color',rand([1,3]), ...
% %           'DrawingArea', 'unlimited');
%         %app.guiForPrimeUIFigure.CurrentAxes
%       else
%         source.UserData = drawrectangle(event.source,'Color',rand([1,3]), 'DrawingArea', 'unlimited');
%       end
      source.UserData = drawrectangle(source, "FixedAspectRatio",true, "AspectRatio",1,'Color',rand([1,3]));
      % Pause while drawing rectangle event.Axes = app.guiForPrimeUIFigure.CurrentAxes
%       h = @(ax) app.guiForPrimeUIFigure.CurrentAxes;
%       eL = listener(source.UserData,'DrawingStarted',h(source.UserData.Axes));
      %el = addlistener(source.UserData,'Parent','PreSet',app.guiForPrimeUIFigure.CurrentAxes) ;
      eL1 = listener(source.UserData,'DrawingStarted',@uiwait);
%       eL1 = listener(source.UserData,'DrawingStarted',@willThisWork);
      % Resume when finished drawing rectangle
      eL2 = listener(source.UserData,'DrawingFinished',@uiresume);
      pos = source.UserData.Position;%[xmin, ymin, width, height]. xmin and ymin specify the location of the upper left corner of the rectangle.
      xmin = floor(pos(1));
      ymin = floor(pos(2));
      width = ceil(pos(3));
      height = ceil(pos(4));
      delete(source.UserData);
      source.UserData = []; %for some reason delete didn't get rid of it here..
      xlims = [xmin-0.5, (xmin+width)+0.5];
      ylims = [ymin-0.5, (ymin+height)+0.5];
      % copied and pasted this from the bothsides stuff below
      app.movieAxisLeftside.XLim = xlims;
      app.movieAxisLeftside.YLim = ylims;
      app.movieAxisRightside.XLim = xlims;
      app.movieAxisRightside.YLim = ylims;
      
%       bothSides = findobj(source.Parent.Children, "Tag", "BothOrOne");
%       if bothSides.Value == false
%         if string(source.Tag) == "Right"
%           % this way doesn't change the scaling of the cData
%           app.movieAxisRightside.XLim = xlims;
%           app.movieAxisRightside.YLim = ylims;
%           % this way changes the scaling of the cData so you have to
%           % change the CData back when you restore view (affects
%           % contrast)
%           %             zoomedInImage = app.current_rightside(bottom:(bottom+height), left:(left+width));
%           %             app.movieAxisRightside.XLim = [0.5, size(zoomedInImage,2) + 0.5];
%           %             app.movieAxisRightside.YLim = [0.5, size(zoomedInImage,1) + 0.5];
%           %             app.movieAxisRightside.Children(end).CData = zoomedInImage;
%           drawnow;
%         elseif string(source.Tag) == "Left"
%           app.movieAxisLeftside.XLim = xlims;
%           app.movieAxisLeftside.YLim = ylims;
%         end
%       else
%         app.movieAxisLeftside.XLim = xlims;
%         app.movieAxisLeftside.YLim = ylims;
%         app.movieAxisRightside.XLim = xlims;
%         app.movieAxisRightside.YLim = ylims;
%       end
    % set pointer back to an arrow
    app.guiForPrimeUIFigure.Pointer = 'arrow';
    end
    
    function callback_zoomBothSidesButtonChanged(app, source, event)
      leftButton = findobj(app.movieAxisToolbarLeftside.Children, "Tag", "BothOrOne");
      leftButton.Value = source.Value;
      rightButton = findobj(app.movieAxisToolbarRightside.Children, "Tag", "BothOrOne");
      rightButton.Value = source.Value;
    end
    function callback_squareButtonChanged(app, source, event)
      disp('lkjdf')
%       leftButton = findobj(app.movieAxisToolbarLeftside.Children, "Tag", "square");
%       leftButton.Value = source.Value;
%       rightButton = findobj(app.movieAxisToolbarRightside.Children, "Tag", "square");
%       rightButton.Value = source.Value;
    end
    
    function callback_smileButtonPushed(app, source, event)
      disp('kdjfsl')
      smile = makeASmile(app);
      leftSmile = smile(:,1:size(smile,2)/2);
      rightSmile = smile(:,(1+size(smile,2)/2):end);
      app.movieAxisLeftside.Children(end).CData = leftSmile;
      app.movieAxisRightside.Children(end).CData = rightSmile;
    end
    function icon = makeZoomBothIcon(app)
      icon = ones(25, "double")*64;
      row = 13;
      columns = 2:24;
      icon(row,columns) = 1;
      row = 12;
      %columns = [3:7,19:23];
      columns = [3:23];
      icon(row,columns) = 1;
      row = 14;
      icon(row,columns) = 1;
      row = 11;
      columns = [4:7, 19:22];
      icon(row,columns) = 1;
      row = 15;
      icon(row,columns) = 1;
      row = 10;
      columns = [5:7, 19:21];
      icon(row,columns) = 1;
      row = 16;
      icon(row,columns) = 1;
      row = 9;
      columns = [6:7, 19:20];
      icon(row,columns) = 1;
      row = 17;
      icon(row,columns) = 1;
      row = 8;
      columns = [7, 19];
      icon(row,columns) = 1;
      row = 18;
      icon(row,columns) = 1;
    end
    function icon = makeSquareIcon(app)
      icon = ones(25, "double")*64;
      row = 3;
      columns = 3:22;
      icon(row,columns) = 1;
      row = 22;
      icon(row,columns) = 1;
      column = 3;
      rows = 3:22;
      icon(rows,column) = 1;
      column = 22;
      icon(rows,column) = 1;
    end

    function smile = makeASmile(app)
      smile = ones(50,"logical");
      i = 2;
      columns = [19    20    21    22    23    24    25    26    27    28    29    30    31    32];
      smile(i,columns) = false;
      i = i+1;
      columns = [17    18    19    20    21    30    31    32    33    34];
      smile(i,columns) = false;
      i = i+1;
      columns = [14    15    16    17    34    35    36];
      smile(i,columns) = false;
      i = i+1;
      columns = [13    14    15    36    37    38];
      smile(i,columns) = false;
      i = i+1;
      columns = [11    12    13    38    39    40];
      smile(i,columns) = false;
      i = i+1;
      columns = [10    11    39    40    41];
      smile(i,columns) = false;
      i = i+1;
      columns = [9    10    41    42];
      smile(i,columns) = false;
      i = i+1;
      columns = [8     9    42    43];
      smile(i,columns) = false;
      i = i+1;
      columns = [7     8    43    44];
      smile(i,columns) = false;
      i = i+1;
      columns = [6     7    44    45];
      smile(i,columns) = false;
      i = i+1;
      columns = [6    44    45];
      smile(i,columns) = false;
      i = i+1;
      columns = [5     6    29    30    31    32    45    46];
      smile(i,columns) = false;
      i = i+1;
      columns = [4     5    28    29    30    31    32    33    46];
      smile(i,columns) = false;
      i = i+1;
      columns = [4     5    28    29    30    31    32    33    46    47];
      smile(i,columns) = false;
      i = i+1;
      columns = [3     4    18    19    20    28    29    30    31    32    33    47];
      smile(i,columns) = false;
      i = i+1;
      columns = [3     4    17    18    19    20    21    29    30    31    32    47    48];
      smile(i,columns) = false;
      i = i+1;
      columns = [3    17    18    19    20    21    22    30    31    48];
      smile(i,columns) = false;
      i = i+1;
      columns = [2     3    17    18    19    20    21    22    48];
      smile(i,columns) = false;
      i = i+1;
      columns = [2     3    17    18    19    20    21    48    49];
      smile(i,columns) = false;
      i = i+1;
      columns = [2     3    18    19    20    21    48    49];
      smile(i,columns) = false;
      i = i+1;
      columns = [2    49];
      smile(i,columns) = false;
      i = i+1;
      columns = [2    49];
      smile(i,columns) = false;
      i = i+1;
      columns = [2    49];
      smile(i,columns) = false;
      i = i+1;
      columns = [2    37    38    42    43    49];
      smile(i,columns) = false;
      i = i+1;
      columns = [2    38    39    40    41    42    49];
      smile(i,columns) = false;
      i = i+1;
      columns = [2    39    40    49];
      smile(i,columns) = false;
      i = i+1;
      columns = [2    39    40    49];
      smile(i,columns) = false;
      i = i+1;
      columns = [2    38    39    40    49];
      smile(i,columns) = false;
      i = i+1;
      columns = [2     3    19    38    39    48    49];
      smile(i,columns) = false;
      i = i+1;
      columns = [ 2     3    18    38    39    48    49];
      smile(i,columns) = false;
      i = i+1;
      columns = [2     3    17    18    37    38    48];
      smile(i,columns) = false;
      i = i+1;
      columns = [3    14    15    16    17    18    19    36    37    38    48];
      smile(i,columns) = false;
      i = i+1;
      columns = [3     4    14    18    19    35    36    37    47    48];
      smile(i,columns) = false;
      i = i+1;
      columns = [4    19    20    21    34    35    36    47];
      smile(i,columns) = false;
      i = i+1;
      columns = [4     5    20    21    22    32    33    34    35    46    47];
      smile(i,columns) = false;
      i = i+1;
      columns = [5    21    22    23    24    25    26   28    29    30    31    32    33    34    46];
      smile(i,columns) = false;
      i = i+1;
      columns = [5     6    23    24    25    26    27    28    29    30    31    32    45    46];
      smile(i,columns) = false;
      i = i+1;
      columns = [6     7    44    45];
      smile(i,columns) = false;
      i = i+1;
      columns = [6     7    44];
      smile(i,columns) = false;
      i = i+1;
      columns = [7     8    43    44];
      smile(i,columns) = false;
      i = i+1;
      columns = [8     9    42    43];
      smile(i,columns) = false;
      i = i+1;
      columns = [9    10    41    42];
      smile(i,columns) = false;
      i = i+1;
      columns = [10    11    12    39    40    41];
      smile(i,columns) = false;
      i = i+1;
      columns = [11    12    13    38    39];
      smile(i,columns) = false;
      i = i+1;
      columns = [13    14    15    36    37    38];
      smile(i,columns) = false;
      i = i+1;
      columns = [15    16    17    34    35    36];
      smile(i,columns) = false;
      i = i+1;
      columns = [17    18    19    20    21    30    31    32    33    34];
      smile(i,columns) = false;
      i = i+1;
      columns = [20    21    22    23    24    25    26    27    28    29    30    31];
      smile(i,columns) = false;
      %           windowSize = 51;
      % kernel = ones(windowSize) / windowSize ^ 2;
      % blurryImage = conv2(single(binaryImage), kernel, 'same');
      % binaryImage = blurryImage > 0.5; % Rethreshold
      i = 1;
      
      smallSmile = smile;


      smile = double(smile);
      smile = imgaussfilt(smile, 0.75);
      %           figure("Name","fig" + num2str(i))
      %           i = i+1;
      %           imshow(smile)
      
      smile = imresize(smile, [200,200], 'method', 'bilinear', 'Dither', true);
      %           figure("Name","fig" + num2str(i))
      %           i = i+1;
      %           imshow(smile)
      
      smile = im2bw(smile, 0.75);
      %           figure("Name","fig" + num2str(i))
      %           i = i+1;
      %           imshow(smile)


      smile = double(smile);
      smile = imgaussfilt(smile, 2);
      %           figure("Name","fig" + num2str(i))
      %           i = i+1;
      %           imshow(smile)
      
      smile = imresize(smile, [350,350], 'method', 'bilinear', 'Dither', true);
      %           figure("Name","fig" + num2str(i))
      %           i = i+1;
      %           imshow(smile)
      
      
      smile = im2bw(smile, 0.7);
      %           figure("Name","fig" + num2str(i))
      %           i = i+1;
      %           imshow(smile)
      
      
      smile(smile(:,:) > 0.7) = 1;
      
    smile(smile(:,:) <= 0.7) = 64;
    end
  end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
      % add path to subfolders
      [filepath,~,~] = fileparts(which('guiForPrime.mlapp'));
      addpath(genpath(filepath));
      % look to see if there are size settings saved, otherwise adapt size
      % to computer's screen
      savedSettingsFileNameWithPath = fullfile(filepath, 'DefaultSettings', 'guiForPrime',"savedSettings.mat");
      if isfile(savedSettingsFileNameWithPath)
        matObj = matfile(savedSettingsFileNameWithPath);
        sizeSettings = matObj.sizeSettings;
        generalSettings = matObj.generalSettings;
        propNames = fieldnames(sizeSettings);
        for i = 1:size(propNames,1)
          propName = propNames{i};
          props = fieldnames(sizeSettings.(propNames{i}));
          for j = 1:size(props,1)
            app.(propName).(props{j}) = sizeSettings.(propName).(props{j});
          end
        end
      else
      % set size of app to work with screen
      screenSize = get(groot,'ScreenSize');
      screenWidth = screenSize(3);
      screenHeight = screenSize(4);
      left = app.guiForPrimeUIFigure.Position(1);
      bottom = app.guiForPrimeUIFigure.Position(2);
      width = app.guiForPrimeUIFigure.Position(3);
      height = app.guiForPrimeUIFigure.Position(4);
      if width + left > screenWidth
        width = screenWidth*0.5;
        left = 1;
      end
      if height + bottom > screenHeight
        height = 0.6*screenHeight;
        bottom = 1;
      end
      app.guiForPrimeUIFigure.Position = [left bottom width height];

      end


      %ResizeableObjects = findobj(app, '-property', AutoResizeChildren);
      
      iptPointerManager(app.guiForPrimeUIFigure,'enable');
      
      app.guiForPrimeUIFigure.Resize = 'on';
      app.guiForPrimeUIFigure.AutoResizeChildren = 'on';
      

      app.AnalyzeTabGroup.AutoResizeChildren = 'on';
      app.SpotsTab.AutoResizeChildren =  'on';
      app.SpotSettingsPanel.AutoResizeChildren =  'on';
      app.AnalysisSettingsTabGroup.AutoResizeChildren =  'on';
      app.AnalysisPanel.AutoResizeChildren =  'on';
      app.LiveCellModeTab.AutoResizeChildren =  'on';
      app.AdvancedSettingsTab.AutoResizeChildren =  'on';
      app.InfoAndMappingPanel.AutoResizeChildren =  'on';
      
      app.PlaybackSettingsTab.AutoResizeChildren = 'off';
      app.ContrastSettingsTab.AutoResizeChildren = 'off';
      app.OtherViewSettingsTab.AutoResizeChildren = 'off';
      app.TabGroup.SelectedTab = app.ContrastSettingsTab;
      
      app.moviePanel.BorderType = 'none';

      
      % set Items Data for drop down selectors
      typesOfAnalysis = ["FRET", "TPM", "FRET and TPM"];
      app.AnalysisTypeDropDown.ItemsData = typesOfAnalysis;
      
      typesOfAnalysis2 = ["left", "right", "both"];
      app.WhichSideDropDown.ItemsData = typesOfAnalysis2;
      app.whichside = app.WhichSideDropDown.Value;
      
      typesOfSpotsToFind = ["Single Molecule", "Random"];
      app.SpotTypeDropDown.ItemsData = typesOfSpotsToFind;


      % need to update all pending functions and callbacks before moving
      % the movie panel.
      drawnow;
      % put movie panel in middle of screen
      app.moviePanel_outer.AutoResizeChildren = 'off';
      app.moviePanel.Position(1) = (app.moviePanel_outer.InnerPosition(3) - app.moviePanel.OuterPosition(3))/2;
      app.moviePanel.Position(2) = (app.moviePanel_outer.InnerPosition(4) - app.moviePanel.OuterPosition(4))/2;
      
      
      if exist('generalSettings','var')
        fields = fieldnames(generalSettings);
        numSettingsSaved = size(fields,1);
        for i = 1:numSettingsSaved
          propName = fields{i};
          if isfield(generalSettings.(propName),'Variables')
            app.(propName) = generalSettings.(propName).Variables;
          else
            moreFields = fieldnames(generalSettings.(propName));
            for j = 1:size(moreFields,1)
              morePropNames = moreFields{j};
              if isprop(app,(propName))
              app.(propName).(morePropNames) = generalSettings.(propName).(morePropNames);
              end
            end
          end
        end
      end
      
      
%       objectsThatAffectFramenumbers = [app.GreenStartFrameEditField, app.GreenEndFrameEditField];
%       callback = @(event) autoFrameSelectionCheckboxValueChanged(app, event);
%       el = addlistener(objectsThatAffectFramenumbers,'ValueChanged',callback);


      drawnow;


        end

        % Window button motion function: guiForPrimeUIFigure
        function guiForPrimeUIFigureWindowButtonMotion(app, event)
      % in order for iptPointerManager(app.guiForPrimeUIFigure,'enable');
      % to work, this callback needs to exist. Even if it does nothing.
        end

        % Key press function: guiForPrimeUIFigure
        function guiForPrimeUIFigureKeyPress(app, event)
      if ~empty(app.current_leftside) && app.PlayMovieButton.Value == false
        key = event.Key;
        switch key
          case 'rightarrow'
            now_frame = app.current_frame;
            if now_frame >= app.NumFrames
              app.current_frame = 1;
            else
              app.current_frame = now_frame + 1;
            end
            [right, left] = get_avg_frames(app,app.current_frame,1);
            app.current_leftside = left;
            app.current_rightside = right;
            app.movieAxisLeftside.Children(end).CData = left;
            app.movieAxisRightside.Children(end).CData = right;
            app.Frameslider.Value = app.current_frame;
            drawnow;
          case 'leftarrow'
            now_frame = app.current_frame;
            if now_frame - 1 <= 0
              app.current_frame = app.NumFrames;
            else
              app.current_frame = now_frame - 1;
            end
            [right, left] = get_avg_frames(app,app.current_frame,1);
            app.current_leftside = left;
            app.current_rightside = right;
            app.Frameslider.Value = app.current_frame;
            app.movieAxisLeftside.Children(end).CData = left;
            app.movieAxisRightside.Children(end).CData = right;
            drawnow;
          otherwise
            return
        end
      end
        end

        % Window scroll wheel function: guiForPrimeUIFigure
        function guiForPrimeUIFigureWindowScrollWheel(app, event)
      if app.HighContrastButton.Value == true || app.LowContrastButton.Value == true
      verticalScrollCount = event.VerticalScrollCount;
      if app.LeftButton.Value == true
        lims = app.movieAxisLeftside.CLim;
        axisFieldname = "movieAxisLeftside";
      else
        lims = app.movieAxisRightside.CLim;
        axisFieldname = "movieAxisRightside";
      end
      if app.HighContrastButton.Value == true
        low = lims(1);
        high = lims(2);
        high = high + verticalScrollCount*app.SensitivitySpinner.Value;
        if high <= low
          low = low - abs(high - low) -1;
        end
        app.(axisFieldname).CLim = [low high];
      elseif app.LowContrastButton.Value == true
        low = lims(1);
        high = lims(2);
        low = low + verticalScrollCount*app.SensitivitySpinner.Value;
        if high <= low
          high = high + abs(high - low) +1;
        end
        app.(axisFieldname).CLim = [low high];
      end
      left_lims = app.movieAxisLeftside.CLim;
      right_lims = app.movieAxisRightside.CLim;
      leftText = "Left Contrast: [" + num2str(left_lims(1)) + ", " + num2str(left_lims(2)) + "]";
      rightText = "Right Contrast: [" + num2str(right_lims(1)) + ", " + num2str(right_lims(2)) + "]";
      app.ContrastTextArea.Value = [leftText; rightText];
      app.InstructionsTextArea.Value = "Left Contrast: [ " + num2str(left_lims(1)) + ", " + num2str(left_lims(2)) + ...
        " ]  Right Contrast: [ " + num2str(right_lims(1)) + ", " + num2str(right_lims(2)) + " ]";
      drawnow;
      end
        end

        % Close request function: guiForPrimeUIFigure
        function guiForPrimeUIFigureCloseRequest(app, event)

      delete(app)
        end

        % Menu selected function: CascadeMenu_2, EvolveMenu_2, 
        % PrimeMenu_2, TwinPrimeMenu_2
        function OpenFileMenuSelected(app, event)
      
      fileType = string(event.Source.Text);
      fclose all;
      % opens dialog box to pick a file
      [datafile,datapath]=uigetfile({'*.pma', 'Movie Files (*.pma)'; ...
        '*.*', 'AllFiles (*.*)'},'Choose a file');
      if datafile==0, return; end
      app.path = datapath;
      cd(datapath);
      % in case the wrong camera was selected
      %       [~,name,ext] = fileparts(datafile);
      %       if string(ext) == ".pma" && event.Source.Text(2:4) ~= name(2:4)
      %         title = "Confirm File Selection";
      %         message = ["Menu file type selection was " + fileType + " but file name is " + string(name); "Please confirm selection."];
      %         selection = uiconfirm(app.guiForPrimeUIFigure,message,title,'Options',{'Correct','Cancel'},'Icon','warning');
      %         if string(selection) == "Cancel"
      %           return
      %         end
      %       end


      % set some initial values
      % changed for batch analysis
      app.keep_spots_displayed = false;
      
      if fileType == "Cascade"
        app.cameratype = "Cascade";
        app.fileprefix = "cascade";
        app.fileExtension = ".pma";
        app.numCameras = 1;
        if app.manualBlocksize == false
          app.blocksize = [16 16];
        end
      elseif fileType == "Evolve"
        app.cameratype = "Evolve";
        app.fileprefix = "Evo";
        app.fileExtension = ".pma";
        app.numCameras = 1;
        if app.manualBlocksize == false
          app.blocksize = [16 16];
        end
      elseif fileType == "Prime"
        app.cameratype = "Prime";
        app.fileprefix = "Pri";
        app.fileExtension = ".pma";
        app.numCameras = 1;
        if app.manualBlocksize == false
          app.blocksize = [20 20];
        end
      elseif fileType == "TwinPrime"
        app.cameratype = "TwinPrime";
        app.fileprefix = "Pri";
        app.fileExtension = ".pmc";
        app.numCameras = 2;
        if app.manualBlocksize == false
          app.blocksize = [20 20];
        end
      end
      % open file
      fid = fopen(datafile,'r'); %%file id
      %extract dimensions of movie
      sizeX=fread(fid,1,'uint16'); %%%pixels in x direction
      sizey = fread(fid,1,'uint16'); %%%pixels in y direction
      fclose(fid);
      
%       if app.numCameras == 2
%         app.imagesizex = sizeX;
%         app.imagesizey = sizey;
%       else
%         app.imagesizex = sizeX/2;
%         app.imagesizey = sizey;
%       end
      
      % determine initial blocksize and NearestNeighbor
      %[17 17] is used for 170X170 image
      %[16 16] for 256X256 image
      if app.manualNearestNeighborMode == false
        if app.blocksize(1) == 20
          if sizeX < 1000
            app.NearestNeighborEditField.Value = 3;
          else
            app.NearestNeighborEditField.Value = 5;
          end
        else
          if sizeX < 500
            app.NearestNeighborEditField.Value = 3;
          else
            app.NearestNeighborEditField.Value = 5;
          end
        end
      end
      
      if app.manualBlocksize == false
        app.BlockSizeEditField.Value = string(app.blocksize(1)) + ", " + string(app.blocksize(2));
%       else
%         BlockSizeEditFieldValueChanged(app, event);
      end


      if isfile("contrastSettings.mat")
        app.LoadContrastSettingsButton.Enable = true;
      else
        app.LoadContrastSettingsButton.Enable = false;
      end


      % open movie needs to happen after the lastmapping above
      OpenMovie(app,datafile)
      if app.batchAnalysisMode == "off"
        if isfile("lastmapping.mat")
          if app.UseCurrentMappingCheckBox.Value == true
            loadLastMapping(app);
          else
            app.LoadLastMappingButton.Enable = "on";
            app.UseCurrentMappingCheckBox.Enable = "on";
            app.f_map_forDisplayInTextArea = "Click 'load last mapping' to load last mapping";
          end
        else
          app.f_map_forDisplayInTextArea = "No previous mapping found";
        end
      end
      
      if isempty(app.movieAxisLeftside)
        setUpMovieAxis(app);
        app.movieAxisLeftside.CLimMode = "manual";
        app.movieAxisRightside.CLimMode = "manual";
      else
        % delete spots and other older info & update image data
        clear app.x_spots_leftside app.x_spots_rightside app.y_spots_leftside app.y_spots_rightside ...
          app.num_spots app.cellMap_leftside app.cellMap_rightside app.edgeMask_leftside app.edgeMask_rightside ...
          app.liveCellImage app.enhancedImage_leftside app.enhancedImage_rightside app.labeledImage_leftside app.labeledImage_rightside
        %% I think that is all of them.
        %% TODO: also reset certian checkboxes, etc. ... figure out what all needs to be reset.
        delete(findobj(app.movieAxisLeftside, 'Type', 'line'));
        delete(findobj(app.movieAxisRightside, 'Type', 'line'));
        app.movieAxisLeftside.Children(end).CData = app.current_leftside;
        app.movieAxisRightside.Children(end).CData = app.current_rightside;
      end
      
      updateTextInfo(app);
      if app.RealsizeMenu.Checked == true
      height = (app.imagesizey+3);
      width = (app.imagesizex+3);
      left = (app.moviePanel_outer.InnerPosition(3) - app.moviePanel.OuterPosition(3))/2;
      bottom = (app.moviePanel_outer.InnerPosition(4) - app.moviePanel.OuterPosition(4))/2;
      app.moviePanel.Position = [left bottom width height];
      app.moviePanel_outer.SizeChangedFcn = @(source,event)callback_keepRealsizeImage(app,source,event);
      end

      
%       if isfile("appSettings.mat")
%         FileInfo = dir("appSettings.mat");
%         dateSettingsWereSaved = FileInfo.date;
%         datee = datestr(dateSettingsWereSaved,2);
%         %% add in an option to choose which settings to load
%         message = sprintf('Previous settings saved on: %s \n Load these settings?', datee);
%         title = 'Load Last Used Settings?';
%         selection = uiconfirm(app.guiForPrimeUIFigure,message,title,...
%           'Options',{'Yes','No'},...
%           'DefaultOption',1,'CancelOption',2);
%         if string(selection) == "Yes"
%           loadAllAppSettings(app);
%         end
%         
%       end


        end

        % Menu selected function: MovieAsaviMenu
        function MovieAsaviMenuSelected(app, event)
      
      %
      %             endframemessage  = 'End at (must be less than ' + num2str(app.NumFrames) + ') :';
      %             prompt = {'Begin at :',endframemessage};
      %                 dlgtitle = 'Save as .avi';
      %                 dims = [1 20];
      %                 definput = {'1', num2str(app.NumFrames)};
      %                 answer = inputdlg(prompt,dlgtitle,dims,definput);
      %             startframe = str2num(answer{1});
      %             endframe = str2num(answer{2});
      %             numframes = endframe - startframe;
      %
      % %             aviobj=avifile(filename_forsaveasavi,'compression','indeo5');
      %             fseek(app.FileID, (4+app.imagesizex*app.imagesizey*(startframe-1)*2*app.numCameras+(startframe-1)*2*app.numCameras),-1);
      %             app.movieAxisLeftside.Visible = "off";
      %             app.movieAxisRightside.Visible = "off";
      %             app.ImageAxesOneside.Visible = "off";
      %
      % %
      %             if app.numCameras == 2
      %                 filename_forsaveasavi_left=strrep(app.filename,app.fileExtension,"left.avi");
      %                 filename_forsaveasavi_right=strrep(app.filename,app.fileExtension,"right.avi");
      %                 aviobj_left=avifile(filename_forsaveasavi_left,'compression','indeo5');
      %                 aviobj_right=avifile(filename_forsaveasavi_right,'compression','indeo5');
      %                 frame = 1:numframes;
      %                 junk = 1:numframes;
      %                 tempR = zeros([app.imagesizex, app.imagesizey]);
      %                 tempL = zeros([app.imagesizex, app.imagesizey]);
      %                 for i = 1:numframes
      %                     frame = fread(app.FileID,1, 'uint16');
      %                     tempR = fread(app.FileID,[app.imagesizex, app.imagesizey], 'uint16');
      %                     tempR = tempR';
      %                     fread(app.FileID,1, 'uint16'); %will this work?
      % %                     junk = fread(app.FileID,1, 'uint16'); %frame number again
      %                     tempL = fread(app.FileID,[app.imagesizex, app.imagesizey], 'uint16');
      %                     tempL = tempL';
      %                     tempL = flip(tempL,2);
      %                     % put frame image on axes
      %                     image(app.movieAxisRightside,tempR,"CDataMapping","scaled","Tag",'Right');
      %                     image(app.movieAxisLeftside,tempL,"CDataMapping","scaled","Tag",'Left');
      %                     % getframe takes frame, as displayed on axis, and saves
      %                     F_right = getframe(app.movieAxisRightside);
      %                     F_left = getframe(app.movieAxisLeftside);
      %                     aviobj_left = addframe(aviobj_left,F_left);
      %                     aviobj_right = addframe(aviobj_right,F_right);
      %                     percent = (i/numframes)*100;
      %                     app.InfoTextArea.Value = ['saving:' newline filename_forsaveasavi_left newline ...
      %                        filename_forsaveasavi_right newline num2str(percent) '%'];
      %                     drawnow;
      %                 end
      %                 aviobj_left = close(aviobj_left);
      %                 aviobj_right = close(aviobj_right);
      %                 app.InfoTextArea.Value = ['Done Saving:' newline filename_forsaveasavi_left newline ...
      %                        filename_forsaveasavi_right];
      %             else
      %                 frame = 1:numframes;
      %                 filename_forsaveasavi=strrep(app.filename,app.fileExtension,".avi");
      %                 aviobj=avifile(filename_forsaveasavi,'compression','indeo5');
      %                 for i = 1:numframes
      %                     frame = fread(app.FileID,1, 'uint16');
      %                     temp = fread(app.FileID,[app.imagesizex, app.imagesizey], 'uint16');
      %                     temp = temp';
      %                     image(app.ImageAxesOneside,temp,"CDataMapping","scaled","Tag",'One');
      %                     F = getframe(app.ImageAxesOneside);
      %                     aviobj = addframe(aviobj,F);
      %                     percent = (i/numframes)*100;
      %                     app.InfoTextArea.Value = ['saving:' newline filename_forsaveasavi newline num2str(percent) '%'];
      %                     drawnow;
      %                 end
      %                 aviobj = close(aviobj);
      %                 app.InfoTextArea.Value = ['Done Saving:' newline filename_forsaveasavi];
      %             end
      
      
        end

        % Menu selected function: AcceptorMenu_2, DonorMenu_2
        function SpotColorMenuSelected(app, event)
      c = uisetcolor;
      if string(event.Source.Text) == "Acceptor"
        acceptorSpots = findobj(app.movieAxisRightside, 'Type', 'line', 'Color', app.rightspotcolor);
        app.rightspotcolor = c;
        acceptorSpots.Color = c;
      else
        donorSpots = findobj(app.movieAxisLeftside, 'Type', 'line', 'Color', app.leftspotcolor);
        app.leftspotcolor = c;
        donorSpots.Color = c;
      end
        end

        % Menu selected function: AutumnMenu, BoneMenu, 
        % ColorcubeMenu, CoolMenu, FlagMenu, GrayMenu, HotMenu, 
        % JetMenu, LinesMenu, ParulaMenu, PinkMenu, PrismMenu, 
        % WinterMenu, hsvMenu_2, turboMenu
        function ColormapMenuSelected(app, event)
      app.colorMapForImage = string(event.Source.Text);
      app.colorMapForImage = lower(app.colorMapForImage);
      % Update colormap
      colormap(app.movieAxisRightside,app.colorMapForImage);
      colormap(app.movieAxisLeftside,app.colorMapForImage);
      % update menu
      allColormapOptions = findobj(event.Source.Parent.Children);
      % uncheck all options
      set(allColormapOptions,'Checked', false);
      % check selected option
      event.Source.Checked = true;

        end

        % Callback function: DownButton, ThresholdEditField, UpButton
        function ThresholdChanged(app, event)
      if string(event.Source.Type) == "uinumericeditfield"
        thresh = app.ThresholdEditField.Value;
      elseif string(event.Source.Text) == "Up"
        thresh = app.ThresholdEditField.Value +2;
      else
        thresh = app.ThresholdEditField.Value -2;
      end
      thresh = round(thresh,3);
      app.ThresholdEditField.Value = thresh;
      if ~isempty(app.movieLayout)
        app.keep_spots_displayed = true;
        FindSpotsButtonPushed(app, event);
      end
        end

        % Value changed function: GreenEndFrameEditField, 
        % GreenStartFrameEditField
        function StartOrEndFrameValueChanged(app, event)
      if app.RedGreenAltModeCheckBox.Value == true && event.Source == app.GreenEndFrameEditField
        app.StartFrameForGreenLaserBackgroundEditField.Value = app.GreenEndFrameEditField.Value ...
         - app.NumFramesToAverageForGreenLaserBackgroundSpinner.Value;
%         app.stFrame_greenBG = app.GreenEndFrameEditField.Value - 10;
      elseif app.LiveCellModeCheckBox.Value == true
        if event.Source == app.GreenStartFrameEditField
          app.StartFrameSpotfindingImage.Value = app.GreenStartFrameEditField.Value;
        elseif event.Source == app.GreenEndFrameEditField
          app.StartFrameSpotfindingBg.Value = app.GreenEndFrameEditField.Value ...
           - app.NumFramesToAvgSpotfindingBg.Value;
        end
%         app.startFrameForSpotfindingBackground = app.GreenEndFrameEditField.Value - 10;
%         app.StartFrameforcalculatingbgEditField.Value = app.startFrameForSpotfindingBackground;
%         app.stframe_spots = app.GreenStartFrameEditField.Value;
      end
        end

        % Value changed function: LiveCellModeCheckBox, 
        % RachelsBigSpotsModeCheckBox, RedGreenAltModeCheckBox, 
        % RedWhiteGreenAltModeCheckBox
        function ModeCheckBoxValueChanged(app, event)
      rwgObjects = findobj(app.RedWhiteGreenAltModeTab, 'Tag', 'rwg');
      liveCellObjects = findobj(app.LiveCellModeTab, 'Tag', 'livecell');
      greenLaserStartEndFrameObjects = findobj(app.SpotSettingsPanel, 'Tag', 'greenLaser');
      allObjectsForAllModes = [rwgObjects; liveCellObjects; greenLaserStartEndFrameObjects];
      analysisModeCheckboxes = findobj(app.SpotSettingsPanel, 'Tag', 'analysisMode');
      spotAndBgFramenumsObjects = findobj(app.FramenumbersTab,'Type', 'uispinner','-or','Type', 'uinumericeditfield');
      % disable all settings then enable the ones that apply to the
      % selected mode
      set(allObjectsForAllModes, 'Enable', false);
      set(analysisModeCheckboxes,'Enable', false, 'Value', false);
      set(app.SplitgreenwhitemoviesCheckBox, 'Value', false);
      % these are used in all but Live cell mode, so enable them here and
      % then disable if in live cell mode
      set(spotAndBgFramenumsObjects, 'Enable', true);
      switch class(event.Source)
        case 'matlab.ui.control.CheckBox'
          if event.Value == true
            value = true;
            % re-enable selected value
            event.Source.Value = true;
            event.Source.Enable = true;
          else
            value = false;
          end
        otherwise
          value = true;
      end
      
      if value == true %|| string(event.Source.Type) == "uistatebutton"
        if app.RedGreenAltModeCheckBox.Value == true
          % set analysisMode
          app.analysisMode = "RGalt";
          % enable green start and end frame edit fields
          set(greenLaserStartEndFrameObjects, 'Enable', true);
          % allow big spots mode 
          app.RachelsBigSpotsModeCheckBox.Enable = true;
          % update green frame background start frame 
          if app.autoStartFrameGreenCheckbox.Value == true
          app.StartFrameForGreenLaserBackgroundEditField.Value = app.GreenEndFrameEditField.Value...
            - app.NumFramesToAverageForGreenLaserBackgroundSpinner.Value;
          end
        elseif app.RedWhiteGreenAltModeCheckBox.Value == true
          % set analysisMode
          app.analysisMode = "RWGalt";
          % calculate frame index
          setFramenumbersForRWGaltMode(app);
          % navigate to red-white-green alt mode tab
          app.AnalysisSettingsTabGroup.SelectedTab = app.RedWhiteGreenAltModeTab;
          app.SplitgreenwhitemoviesCheckBox.Value = true;
          % enable red-white-green alt mode options
          set(rwgObjects, 'Enable', true);

          % update background start frames
          if app.autoStartFrameGreenCheckbox.Value == true
            app.StartFrameForGreenLaserBackgroundEditField.Value = app.greenLaserFrameIdx(end)...
            - (2*app.NumFramesToAverageForGreenLaserBackgroundSpinner.Value+2);
          app.StartFrameForWhiteLightBg.Value = app.whiteLightFrameIdx(end)...
            - (2*app.NumFramesToAvgForWhiteLightBg.Value+2);
          end
          if app.autoStartFrameBgCheckbox.Value == true
            if app.AltModeEndFrameEditField.Value == inf
              app.StartFrameSpotfindingBg.Value = 0;
              app.NumFramesToAvgSpotfindingBg.Value = 0;
            end
          end
          
          SplitgreenwhitemoviesCheckBoxValueChanged(app, event);
        elseif app.LiveCellModeCheckBox.Value == true
          % set analysisMode
          app.analysisMode = "liveCell";
          % enable start and end frame edit fields
          set(greenLaserStartEndFrameObjects, 'Enable', true);
          % edit start and num frames for finding spots and background
          % set spotFinding image frame to be the first green laser frame
          % and numFrames to average to 1
          % set background frames to zero
          app.StartFrameSpotfindingImage.Value = app.GreenStartFrameEditField.Value;
          app.NumFramesToAverageForSpotfindingImageSpinner.Value = 1;
          app.NumFramesToAvgSpotfindingBg.Value = 10;
          app.StartFrameSpotfindingBg.Value = app.GreenEndFrameEditField.Value...
            - app.NumFramesToAvgSpotfindingBg.Value -1;
          % disable changing the frames used in spotfinding
          %set(spotAndBgFramenumsObjects, 'Enable', false);
          % navigate to live cell mode tab
          app.AnalysisSettingsTabGroup.SelectedTab = app.LiveCellModeTab;
          % enable input options for live cell mode tab
          set(liveCellObjects, 'Enable', true);
          % add a default liveCellMap image
          [imageRight, imageLeft] = get_avg_frames(app,1,10);
          app.liveCellImage = [imageLeft, imageRight];
        elseif app.RachelsBigSpotsModeCheckBox.Value == true
          % set analysisMode
          app.analysisMode = "bigSpots";
          % allow red green alt mode still
          app.RedGreenAltModeCheckBox.Enable = true;
          % navigate to big spots tab
          %app.AnalysisSettingsTabGroup.SelectedTab = app.BigSpotsTab;
        end
      else
        % set analysisMode
          app.analysisMode = "regular";

        % enable all checkboxes
        set(analysisModeCheckboxes, 'Enable', true);
        % navigate back to default tab
        app.AnalysisSettingsTabGroup.SelectedTab = app.AnalyzeTab;
        % possibly don't do this here..
        updateTextInfo(app)
      end
        end

        % Value changed function: GreenCheckBox, RedCheckBox, 
        % WhiteCheckBox
        function spotfindingFramesRWGmodeCheckboxesChanged(app, event)
      % set the other checkboxes to false
      allCheckboxes = [app.GreenCheckBox, app.RedCheckBox, app.WhiteCheckBox];
      set(allCheckboxes, 'Value', false);
      event.Source.Value = event.Value;
      % update spotfinding image framenumbers
      if event.Source == app.RedCheckBox
        app.StartFrameSpotfindingImage.Value = 1;
      elseif event.Source == app.GreenCheckBox
        app.StartFrameSpotfindingImage.Value = app.GreenLaserStartFrameForRGWEditField.Value + 1;
      elseif event.Source == app.WhiteCheckBox
        app.StartFrameSpotfindingImage.Value = app.GreenLaserStartFrameForRGWEditField.Value + 1 + app.GreenLaserNumFramesOnEditField.Value;
      end
      % update background framenumbers
      if app.AltModeEndFrameEditField.Value == inf
        if event.Source == app.RedCheckBox
          app.NumFramesToAvgSpotfindingBg.Value = 0;
          app.StartFrameSpotfindingBg.Value = 0;
        else
          app.StartFrameSpotfindingBg.Value = app.NumFrames - ...
            2*app.NumFramesToAvgSpotfindingBg.Value -1;
        end
      else
        if event.Source == app.RedCheckBox
          app.StartFrameSpotfindingBg.Value = app.NumFrames - ...
            app.NumFramesToAvgSpotfindingBg.Value -1;
        else
          app.StartFrameSpotfindingBg.Value = app.AltModeEndFrameEditField.Value - ...
            2*app.NumFramesToAvgSpotfindingBg.Value -1;
        end
      end
      

        end

        % Value changed function: autoNumFramesBgCheckbox, 
        % autoNumFramesGreenCheckbox, autoNumFramesSpotsCheckbox, 
        % autoNumFramesToAvgWhiteLightBg, autoStartFrameBgCheckbox, 
        % autoStartFrameForWhiteLightBg, 
        % autoStartFrameGreenCheckbox, autoStartFrameSpotsCheckbox
        function autoFrameSelectionCheckboxValueChanged(app, event)
%       if event.Source.Value == true
%         %       objectsThatAffectFramenumbers = [app.GreenStartFrameEditField, app.GreenEndFrameEditField];
% %       callback = @(event) autoFrameSelectionCheckboxValueChanged(app, event);
% %       el = addlistener(objectsThatAffectFramenumbers,'ValueChanged',callback);
%         event.Source.UserData.el = addlistener(hSource, EventName, callback);
%         % better yet, just enable/disable the listener
%       else
%         delete(event.Source.UserData.el);
%       end
      
      value = event.Source.Value;
      tag = string(event.Source.Tag);
      if value == true
        if contains(tag,"StF")
          if tag == "greenStF"
            if app.RedWhiteGreenAltModeCheckBox.Value == false
              app.StartFrameForGreenLaserBackgroundEditField.Value = app.GreenEndFrameEditField.Value...
                - app.NumFramesToAverageForGreenLaserBackgroundSpinner.Value-1;
            else
              if app.AltModeEndFrameEditField.Value == inf
                app.StartFrameForGreenLaserBackgroundEditField.Value = app.NumFrames - ...
                  2*app.NumFramesToAvgSpotfindingBg.Value -2;
              else
                app.StartFrameForGreenLaserBackgroundEditField.Value = app.AltModeEndFrameEditField.Value - ...
                  2*app.NumFramesToAvgSpotfindingBg.Value -2;
              end
            end
          elseif tag == "whiteStF"
            if app.AltModeEndFrameEditField.Value == inf
              app.StartFrameForWhiteLightBg.Value = app.NumFrames - 1 - ...
                2*app.NumFramesToAvgForWhiteLightBg.Value -2;
            else
              app.StartFrameForWhiteLightBg.Value = app.AltModeEndFrameEditField.Value - 1 - ...
                2*app.NumFramesToAvgForWhiteLightBg.Value -2;
            end
          elseif tag == "spotsStF"
            if app.LiveCellModeCheckBox.Value == true
              app.StartFrameSpotfindingImage.Value = app.GreenStartFrameEditField.Value;
            else
              app.StartFrameSpotfindingImage.Value = 1;
            end
          elseif tag == "bgStF"
            if app.LiveCellModeCheckBox.Value == true
              app.StartFrameSpotfindingBg.Value = app.GreenEndFrameEditField.Value...
                - app.NumFramesToAvgSpotfindingBg.Value-1;
            elseif app.RedWhiteGreenAltModeCheckBox.Value == true
              if app.AltModeEndFrameEditField.Value == inf
                app.StartFrameSpotfindingBg.Value = 0;
                app.NumFramesToAvgSpotfindingBg.Value = 0;
              else
                nFramesAvailableAtEndOFMovie = app.NumFrames - app.AltModeEndFrameEditField.Value;
                if nFramesAvailableAtEndOFMovie >= app.NumFramesToAvgSpotfindingBg.Value
                  app.StartFrameSpotfindingBg.Value = app.NumFrames - ...
                app.NumFramesToAvgSpotfindingBg.Value-1;
                else
                  app.StartFrameSpotfindingBg.Value = app.AltModeEndFrameEditField.Value;
                  app.NumFramesToAvgSpotfindingBg.Value = nFramesAvailableAtEndOFMovie;
                end
              end

            else
              app.StartFrameSpotfindingBg.Value = app.NumFrames - ...
                app.NumFramesToAvgSpotfindingBg.Value-1;
            end
          end
        elseif contains(tag, "NF")
          correspondingObj = findobj(app.FramenumbersTab,'Type','uispinner','Tag',tag);
          if ~isempty(correspondingObj)
          correspondingObj.Value = 10;
          end
        end
      end
        end

        % Button pushed function: FindSpotsButton
        function FindSpotsButtonPushed(app, event)
      %%%%% First get background and spot finding images %%%%%%
      if app.RedWhiteGreenAltModeCheckBox.Value == true 
        %make sure frames are in the red framenumber index for spotfinding
        %images
        spotfindingFrames = app.StartFrameSpotfindingImage.Value:(app.StartFrameSpotfindingImage.Value + app.NumFramesToAvgSpotfindingImage.Value -1);
        if ~all(ismember(spotfindingFrames, app.redLaserFrameIdx))
          error("spotfinding frame numbers are out of bounds of red laser frames")
        end
      end
        % 1. get image for finding spots
        [app.spotfindingImage_rightside, app.spotfindingImage_leftside] = get_avg_frames(app, ...
          app.StartFrameSpotfindingImage.Value,app.NumFramesToAvgSpotfindingImage.Value);
        % 2. calculate background
        [zright,zleft] = get_avg_frames(app,app.StartFrameSpotfindingBg.Value, ...
          app.NumFramesToAvgSpotfindingBg.Value);
        if app.numCameras == 1 
          [app.spotfindingBackground_rightside, app.spotfindingBackground_leftside] = ...
            calculateBackground(app, app.blocksize, [zleft, zright]);
        elseif app.numCameras == 2 % TwinPrime Camera
          [R, L] = calculateBackground(app, app.blocksize, zright);
          app.spotfindingBackground_rightside = [L, R];
          [R, L] = calculateBackground(app, app.blocksize, zleft);
          app.spotfindingBackground_leftside = [L, R];
        end


      %%%%% Next find the spots %%%%%%%%%%%%%%%%%%%%%%%%%%%%
      % 3. find offset if needed
      if app.UseCurrentMappingCheckBox.Value == 0
        find_offset(app)
      end
      
      % 5. define original and moving images. Original image is the one we
      % find the spots with, moving image is the one the spots will have to
      % be mapped to. Usually Original side is left and moving side is right
      [offsetx, offsety, originalside_bgSubtracted, movingside_bgSubtracted] = ...
        setoriginalandmovingpoints(app, app.spotfindingImage_leftside, app.spotfindingImage_rightside, ...
        app.spotfindingBackground_leftside, app.spotfindingBackground_rightside);
      % 6. find spots
      [original_spots, moving_spots] = findspots(app, offsetx, offsety, originalside_bgSubtracted, movingside_bgSubtracted);
      if isempty(original_spots)
        % delete old points
        delete(findobj(app.movieAxisLeftside, 'Type', 'line'));
        delete(findobj(app.movieAxisRightside, 'Type', 'line'));
        app.x_spots_leftside = [];
        app.x_spots_rightside = [];
        app.y_spots_leftside = [];
        app.y_spots_rightside = [];
        app.num_spots = 0;
        if app.analysisMode == "liveCell"
          % create progress bar with message to tell user it will take
          % longer
          if isempty(event.Source.UserData)
            msg = "No spots found in first green frame. Checking next frames, please wait.";
            event.Source.UserData = uiprogressdlg(app.guiForPrimeUIFigure,'Title','Please Wait',...
              'Message',msg, 'Indeterminate','on');
          end
          if app.StartFrameSpotfindingImage.Value ~= app.GreenEndFrameEditField.Value
            app.StartFrameSpotfindingImage.Value = app.StartFrameSpotfindingImage.Value + 1;
            FindSpotsButtonPushed(app, event);
          else
            close(event.Source.UserData)
          end
        end
        return;
      end
      if ~isempty(event.Source.UserData)
        close(event.Source.UserData)
      end
      % 7. Store coordinates based on which side
      storespotsbasedonwhichside(app,original_spots, moving_spots);
      % 8. If in Live Cell mode, and if option is selected, remove spots not in a cell
      if app.LiveCellModeCheckBox.Value == true
        % set movie to first frame for spotfinding
        app.current_leftside = app.spotfindingImage_leftside;
        app.current_rightside = app.spotfindingImage_rightside;
        app.current_frame = app.StartFrameSpotfindingImage.Value;
        app.Frameslider.Value = app.StartFrameSpotfindingImage.Value;

      end
      %%%%% Then update the display %%%%%%%%%%%%%%%%%%%%%%%%%%%%
      % 9. Update display
      updatedisplay(app);
      % 10. Update text
      %updateTextInfo(app); % already included in updatedisplay
      
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
      if event.Source == app.AnalyzeButton
        app.batchAnalysisMode = "off";
      end
      %%% spotfinding image and bg have already been set when findspots
      %%% button was called. Still need to calculate green laser bg and
      %%% white light bg if rwg alt mode
      % 1. Calculate backgrounds that haven't been calculated yet and
      % assign correct FRET analysis function
      switch app.analysisMode
        case "RGalt"
          analyzeFret = @(app) app.savetraces;
          [zright,zleft] = get_avg_frames(app,app.StartFrameForGreenLaserBackgroundEditField.Value, ...
            app.NumFramesToAverageForGreenLaserBackgroundSpinner.Value);
          if app.numCameras == 1
            [app.analysisBackground_greenFrames_rightside, app.analysisBackground_greenFrames_leftside] = ...
              calculateBackground(app, app.blocksize, [zleft, zright]);
          else % TwinPrime Camera
            [rightRight, leftRight] = calculateBackground(app, app.blocksize, zright);
            app.analysisBackground_greenFrames_rightside = [leftRight, rightRight];
            [rightLeft, leftLeft] = calculateBackground(app, app.blocksize, zleft);
            app.analysisBackground_greenFrames_leftside = [leftLeft, rightLeft];
          end
        case "RWGalt"
          analyzeFret = @(app) app.savetraces;
          [greenImage,~] = get_avg_frames(app,app.StartFrameForGreenLaserBackgroundEditField.Value, ...
            app.NumFramesToAverageForGreenLaserBackgroundSpinner.Value);
          [~, whiteImage] = get_avg_frames(app,app.StartFrameForWhiteLightBg.Value, ...
            app.NumFramesToAvgForWhiteLightBg.Value);
          if app.numCameras == 1
            [app.analysisBackground_greenFrames_rightside, app.analysisBackground_greenFrames_leftside] = ...
              calculateBackground(app, app.blocksize, greenImage);
            [app.white_background_rightside, app.white_background_leftside] = ...
              calculateBackground(app, app.blocksize, whiteImage);
          else
            error("add code for using rwg alt mode with 2 cameras - analysisButtonPushed")
          end
        case "liveCell"
          analyzeFret = @(app) app.saveLiveCellTraces;
        case "bigSpots"
          analyzeFret = @(app) app.saveRachelBigSpotTraces2;
        case "regular"
          analyzeFret = @(app) app.savetraces;
      end
      
      % 2. Analyze and save the data
      switch app.AnalysisTypeDropDown.Value
        case "FRET"
          analyzeFret(app);
        case "TPM"
          fname = strrep(app.filename,app.fileExtension,'TBPmol');
          saveTPManalysis(app,fname)
        case "FRET and TPM"
          % calculate fret first
          analyzeFret(app);
          % update analysis progress bar
          app.batchnumber = app.batchnumber + 1;
          % calculate TPM second
          fname = strrep(app.filename,app.fileExtension,'TBPmol');
          saveTPManalysis(app,fname);
      end
      % update analysis progress bar
      app.batchnumber = app.batchnumber + 1;
     
      
      % 3. Save analysis settings and tell user when analysis is finished
    %%  saveTextFileOfAnalysisSettings(app);
      % save a copy of the x and y coords for this movie for use in the
      % spotMovies app
      
% %       xyCoordsSaveName = strrep(app.filename,app.fileExtension,'_xySpotCoords');
% %       xspotsLeft = app.x_spots_leftside;
% %       xspotsRight = app.x_spots_rightside;
% %       yspotsLeft= app.y_spots_leftside;
% %       yspotsRight = app.y_spots_rightside;
% %       save(xyCoordsSaveName, "xspotsLeft", "yspotsLeft", "xspotsRight", "yspotsRight");
      if app.batchAnalysisMode == "off"
        app.InfoTextArea.Value = 'Done saving analysis';
      end

        end

        % Button pushed function: BatchAnalysisButton
        function BatchAnalysisButtonPushed(app, event)
      % turn on batch analysis mode for progress bar
      app.batchAnalysisMode = "on";
      % get list of movies in file
      movieFileNamesWithWildcard = app.fileprefix + "*" + app.fileExtension;
      movieFilesStruct = dir(movieFileNamesWithWildcard);
      numberOfFiles = size(movieFilesStruct,1);
      app.batchtotal=numberOfFiles;
      if app.AnalysisTypeDropDown.Value == "FRET and TPM"
        app.batchtotal = app.batchtotal*2;
      end
      % stupid stupid matlab doesn't seem to have any sensible way to get
      % the values of a field out of a structure. so here is a function
      % to do it.
      getMovieName = @(movieNumber) movieFilesStruct(movieNumber).name;
      for i = 1:numberOfFiles
        fclose all;
        % clear previous movie data: works without clearing also, but
        % might as well clear some of it. (not all variables to clear are
        % listed here yet)
        clear app.x_spots_leftside app.x_spots_rightside app.y_spots_leftside app.y_spots_rightside ...
          app.num_spots app.cellMap_leftside app.cellMap_rightside app.edgeMask_leftside app.edgeMask_rightside ...
          app.liveCellImage app.enhancedImage_leftside app.enhancedImage_rightside app.labeledImage_leftside app.labeledImage_rightside ...
          app.filename app.BigSpotAnalysisInfo
        app.filename = getMovieName(i);
        OpenMovie(app,app.filename);
        ModeCheckBoxValueChanged(app, event); %resets the background framenumbers that depend on numframes
        FindSpotsButtonPushed(app, event);
        AnalyzeButtonPushed(app, event);
      end
      app.InfoTextArea.Value = 'Done with Batch Analysis';
        end

        % Value changed function: PlayMovieButton
        function PlayMovieButtonValueChanged(app, event)
      if app.PlayMovieButton.Value == true
        app.PlayMovieButton.Text = 'Stop Movie';
        if app.PlayonLoopCheckBox.Value == true
          app.Frameslider.Value = app.playbackStartFrame.Value;
        end
      elseif app.PlayMovieButton.Value == false
        app.PlayMovieButton.Text = 'Play Movie';
      end
      drawnow;
      Play_Movie(app);
      updatedisplay(app);
        end

        % Value changed function: Frameslider
        function FramesliderValueChanged(app, event)
      new_frame = app.Frameslider.Value;
      new_frame = round(new_frame);
      [app.current_rightside, app.current_leftside] = get_avg_frames(app,new_frame,1);
      app.current_frame = new_frame;
      updatedisplay(app)
      app.FrameNumberEditField.Value = "Frame: " + num2str(new_frame);
      
        end

        % Value changing function: Frameslider
        function FramesliderValueChanging(app, event)
      changingValue = round(event.Value);
      [right, left] = get_avg_frames(app,changingValue,1);
      app.movieAxisLeftside.Children(end).CData = left;
      app.movieAxisRightside.Children(end).CData = right;
      app.FrameNumberEditField.Value = "Frame: " + num2str(changingValue);
        end

        % Value changed function: DropDown_imagedisplay
        function DropDown_imagedisplayValueChanged(app, event)
      
      imagetodisplay = string(app.DropDown_imagedisplay.Value);
      if ~isempty(app.current_leftside)
        if imagetodisplay == "Background" || imagetodisplay == "Subtract Background"
          % pick frames to use for finding background & spot image
          imStart = app.StartFrameSpotfindingImage.Value;
          nframesToAverage_bg = app.NumFramesToAvgSpotfindingBg.Value;
          nframesToAverage = app.NumFramesToAvgSpotfindingImage.Value;
          if app.RedGreenAltModeCheckBox.Value == true
            if app.current_frame >= app.GreenStartFrameEditField.Value && app.current_frame <= app.GreenEndFrameEditField.Value
              bgStart = app.StartFrameForGreenLaserBackgroundEditField.Value;
              nframesToAverage_bg = app.NumFramesToAverageForGreenLaserBackgroundSpinner.Value;
              app.InstructionsTextArea.Value = "Background: Green Laser";
              if imagetodisplay == "Subtract Background"
                imStart = app.GreenStartFrameEditField.Value;
                app.InstructionsTextArea.Value = "Background Subtracted: Green Laser Frames";
              end
            else
              bgStart = app.StartFrameSpotfindingBg.Value;
              app.InstructionsTextArea.Value = "Background: Red Laser";
              if imagetodisplay == "Subtract Background"
                app.InstructionsTextArea.Value = "Background Subtracted: Red Laser Frames";
              end
            end
          elseif app.RedWhiteGreenAltModeCheckBox.Value == true
            % add what to do here later.
          else
            bgStart = app.StartFrameSpotfindingBg.Value;
          end
          % calculate background
          [zright,zleft] = get_avg_frames(app,bgStart,nframesToAverage_bg);
          if app.numCameras == 1
            [rightsideBackground, leftsideBackground] = ...
              calculateBackground(app, app.blocksize, [zleft,zright]);
          else % TwinPrime Camera
            [rightRight, leftRight] = calculateBackground(app, app.blocksize, zright);
            rightsideBackground = [leftRight, rightRight];
            [rightLeft, leftLeft] = calculateBackground(app, app.blocksize, zleft);
            leftsideBackground = [leftLeft, rightLeft];
          end
          app.InstructionsTextArea.Value = "Background";
          % subtract bg if it was selected
          if imagetodisplay == "Subtract Background"
            % get images
            [imageR, imageL] = get_avg_frames(app,imStart,nframesToAverage);
            if string(app.InvertSwitch.Value) == "Invert"
              %inverted image because beads are lower intensity than background
              temp_wholeImage_bg = [leftsideBackground, rightsideBackground];
              temp_wholeImage = [imageL, imageR];
              temp_wholeImage = bsxfun(@times, temp_wholeImage_bg, 2) - temp_wholeImage;    %strange way to mult. all elements by constant
              %subtract out the background from the current image
              imageL = temp_wholeImage(:, 1:app.imagesizex/2);
              imageR = temp_wholeImage(:,(1+app.imagesizex/2):end);
            end
            leftsideBackground = imageL - leftsideBackground;
            rightsideBackground = imageR - rightsideBackground;
            if app.RedGreenAltModeCheckBox.Value ~= true
              app.InstructionsTextArea.Value = "Background Subtracted";
            end
          end
          % display
          delete(findobj(app.movieAxisLeftside, 'Type', 'line'));
          delete(findobj(app.movieAxisRightside, 'Type', 'line'));
          app.movieAxisLeftside.Children(end).CData = leftsideBackground;
          app.movieAxisRightside.Children(end).CData = rightsideBackground;
          app.InstructionsTextArea.Value = "Background";
        end
        if imagetodisplay == "Original"
          [app.current_rightside, app.current_leftside] = get_avg_frames(app,app.Frameslider.Value,1);
          app.movieAxisLeftside.Children(end).CData = app.current_leftside;
          app.movieAxisRightside.Children(end).CData = app.current_rightside;
          plotspots(app);
        end
        drawnow;
      end
        end

        % Button pushed function: ViewTracesButton
        function ViewTracesButtonPushed(app, event)

      viewTracesBasic(app.filename);
      uiconfirm(app.guiForPrimeUIFigure,'Close guiForPrime App?','Confirm Close',...
            'CloseFcn',@(src,event)callback_closeApp(app,src,event));
      %analyzeTraces_workingVersion2021();
        end

        % Button pushed function: CheckforDoubleFramesButton
        function CheckforDoubleFramesButtonPushed(app, event)
      fid=fopen(app.filename,'r'); %%file id
      file_name=app.filename; %%name of file
      
      fseek(fid,0,-1); %% go to first line in file
      imagesize_x=fread(fid,1,'uint16'); %%%pixels in x direction
      imagesize_y=fread(fid,1,'uint16'); %%%pixels in y direction
      
      numframes = app.NumFrames;
      
      %             frameR = 1:numframes;
      %             frameL = 1:numframes;
      %             doubleframes = 1:numframes;
      for i = 1:numframes
        frameR(i) = fread(fid,1,'uint16');
        temp_imageR=fread(fid,[imagesize_x, imagesize_y], 'uint16');
        frameL(i) = fread(fid,1,'uint16');
        temp_imageL=fread(fid,[imagesize_x, imagesize_y], 'uint16');
        frameR1(i) = fread(fid,1,'uint16');
        temp_imageR1=fread(fid,[imagesize_x, imagesize_y], 'uint16');
        frameL1(i) = fread(fid,1,'uint16');
        temp_imageL1=fread(fid,[imagesize_x, imagesize_y], 'uint16');
        checkR = temp_imageR - temp_imageR1;
        checkL = temp_imageL - temp_imageL1;
        if max(max(checkR)) == min(min(checkR))
          doubleframesR(i) = 1;
        else
          doubleframesR(i) = 0;
        end
        if max(max(checkL)) == min(min(checkL))
          doubleframesL(i) = 1;
        else
          doubleframesL(i) = 0;
        end
        %                 whichframesaredouble = frameR.*doubleframes;
      end
      sumL(doubleframesL(:)==1);
      sumR(doubleframesR(:)==1);
      whichframesaredoubleR = frameR.*doubleframesR;
      whichframesaredoubleL = frameL.*doubleframesL;
      
        end

        % Button pushed function: ViewTPMButton
        function ViewTPMButtonPushed(app, event)
      
      [file,path]=uigetfile('*.txt',...
        'Select any file in the batch .','MultiSelect',"off");
      app.ViewTPMApp = guiForPrime_TPMexplorer(app, file, path);
        end

        % Button pushed function: ViewFRETTPMButton
        function ViewFRETTPMButtonPushed(app, event)
      % in saveTPManalysis, this is the data I need: FitResultsFramewise
      %saved as: numspots x [xpoints,ypoints] x numframes
      % in savetraces, this is the data: traces(1:1+2*app.num_spots,k)
      %saved as: intenistyvalues(odd/even) x numframes
      % note: make sure savetraces doesn't dobule for TPM mode
      % note: make sure opposite for saveTPM
      %             app.ViewFRETandTPMApp = guiForPrime_viewFRETandTPM(app,FitResultsFramewise,traces);
      % changing to give last saved analysis instead of data
      
      app.ViewFRETandTPMApp = guiForPrime_viewFRETandTPM(app,app.lastsavedTPM,app.lastsavedtrace);
        end

        % Value changed function: NearestNeighborEditField
        function NearestNeighborEditFieldValueChanged(app, event)
      app.manualNearestNeighborMode = true;
        end

        % Value changed function: WhichSideDropDown
        function WhichSideDropDownValueChanged(app, event)
      value = app.WhichSideDropDown.Value;
      app.whichside = string(value);
        end

        % Value changed function: DetectCellEdgesButton
        function DetectCellEdgesButtonValueChanged(app, event)
      value = app.DetectCellEdgesButton.Value;
      if value == true

        %average the frames of the movie that show the cells in white light
        numFramesToAverage2 = app.NumFramesToAverageForLiveCellImageEditField.Value;
        [imageRight, imageLeft] = get_avg_frames(app,1,numFramesToAverage2);
        % modify image to enhance contrast
        app.enhancedImage_leftside = enhanceImageForFindingCells(app,imageLeft);
        app.enhancedImage_rightside = enhanceImageForFindingCells(app,imageRight);
        temp = [app.enhancedImage_leftside, app.enhancedImage_rightside];
        [background_rightside, background_leftside] = calculateBackground(app, app.blocksize, temp);
        app.enhancedImage_leftside = app.enhancedImage_leftside - background_leftside;
        app.enhancedImage_rightside = app.enhancedImage_rightside - background_rightside;
        
        minArea = app.MinAreaofCellEditField.Value;
        % find cells & create labeled image & cell map
        if app.whichside == "left"
          [app.cellMap_leftside, app.labeledImage_leftside, app.regionProps_leftside] = findEdgesOfCells(app, app.enhancedImage_leftside, minArea);
          app.labeledImage_rightside = 255 * repmat(uint8(app.enhancedImage_rightside), 1, 1, 3);%app.enhancedImage_rightside;
          app.labeledImage_rightside = im2double(app.labeledImage_rightside);
          
        elseif app.whichside == "right"
          [app.cellMap_rightside, app.labeledImage_rightside, app.regionProps_rightside] = findEdgesOfCells(app, app.enhancedImage_rightside, minArea);
          app.labeledImage_leftside = 255 * repmat(uint8(app.enhancedImage_leftside), 1, 1, 3);%app.enhancedImage_leftside;
          app.labeledImage_leftside = im2double(app.labeledImage_leftside);
          
        elseif app.whichside == "both"
          enhancedImage = enhanceImageForFindingCells(app,[imageLeft, imageRight]);
          [cellMap_wholeimage, labeledImage_wholeimage, cellInfo_bothsides] = findEdgesOfCells(app, enhancedImage, minArea);
          % separate out left and right for display
          app.cellMap_leftside = cellMap_wholeimage(:,1:app.imagesizex/2);
          app.cellMap_rightside = cellMap_wholeimage(:,(1+app.imagesizex/2):end);
          app.labeledImage_rightside = labeledImage_wholeimage(:,(1+app.imagesizex/2):end);
          app.labeledImage_leftside = labeledImage_wholeimage(:,1:app.imagesizex/2);
        end
        % update display
        app.current_rightside = app.labeledImage_rightside;
        app.current_leftside = app.labeledImage_leftside;
        app.movieAxisLeftside.Children(end).CData = app.labeledImage_leftside;
        app.movieAxisRightside.Children(end).CData = app.labeledImage_rightside;
        
        app.Frameslider.Value = 1;
        app.current_frame = 1;
        app.InfoTextArea.Value = "Click on the cells you don't want to include. Click 'Cell Edges' button again when finished.";

        greenLaserStartEndFrameObjects = findobj(app.SpotSettingsPanel, 'Tag', 'greenLaser');
%         set(greenLaserStartEndFrameObjects, 'FontColor', "k", 'FontWeight', "normal");
%         
%         set(app.DetectCellEdgesButton, 'FontColor', "k", 'BackgroundColor', [0.96,0.96,0.96], ...
%           'FontWeight', "normal");
        
        
%         app.GreenEndFrameEditFieldLabel.FontColor = "black";
%         app.GreenStartFrameEditFieldLabel.FontColor = "black";
%         app.GreenEndFrameEditFieldLabel.FontWeight = "normal";
%         app.GreenStartFrameEditFieldLabel.FontWeight = "normal";
%         app.DetectCellEdgesButton.FontColor = "k";
%         app.DetectCellEdgesButton.BackgroundColor = [0.96,0.96,0.96];
%         app.DetectCellEdgesButton.FontWeight = "normal";
        app.guiForPrimeUIFigure.Pointer = "cross";
        % this lets the windowsdownbutton function know that we are in live
        % cell mode
        app.LiveCellSelectionMode = true;
        % temporarilary disable a few things
        app.Frameslider.Enable = "off";
        app.PlayMovieButton.Enable = "off";
        
      else % when user clicks the button again to say they are finished
        
        
%         set(app.InfoTextArea, 'FontColor', "k", 'FontSize', 12, ...
%           'FontWeight', "normal");
        
        app.LiveCellSelectionMode = false;
        app.InstructionsTextArea.Value = " ";
        % in case they want to get back to the live cell labeled image
        app.LiveCellNewFigureButton.Enable = "on";
        % re-enable other things
        app.Frameslider.Enable = "on";
        app.PlayMovieButton.Enable = "on";
        % set pointer back to normal
        app.guiForPrimeUIFigure.Pointer = 'arrow';
        
        % set movie back to begining
        [imageRight, imageLeft] = get_avg_frames(app,1,app.NumFramesToAvgSpotfindingImage.Value);
        app.current_rightside = imageRight;
        app.current_leftside = imageLeft;
        %         app.movieAxisLeftside.Children.CData = app.current_leftside;
        %         app.movieAxisRightside.Children.CData = app.current_rightside;
        
        % move frameslider
        app.Frameslider.Value = 1;
        app.current_frame = 1;


        % make mask to show edges on image
        if app.whichside == "left"
          % re-do labeled image to reflect user selected cells to be
          % taken out
%           app.labeledImage_leftside = labeloverlay(app.enhancedImage_leftside, app.cellMap_leftside,'Transparency',0.3);
%           app.labeledImage_leftside = im2double(app.labeledImage_leftside);
          BW = logical(app.cellMap_leftside);
          mask = edge(BW);
          app.edgeMask_leftside = mask;
          if app.UseCurrentMappingCheckBox.Value == true && ~isempty(app.f_map)
            multiplierForOffset = 1;
            [app.edgeMask_rightside, app.labeledImage_rightside, app.cellMap_rightside, app.regionProps_rightside] = createCellMaskOfOtherSide(app, app.cellMap_leftside, multiplierForOffset, app.enhancedImage_rightside);
          else
            app.edgeMask_rightside = zeros(size(mask),'logical');
            app.cellMap_rightside = zeros(size(app.cellMap_leftside));
          end
        elseif app.whichside == "right"
          app.labeledImage_rightside= labeloverlay(app.enhancedImage_rightside, app.cellMap_rightside,'Transparency',0.3);
          app.labeledImage_rightside = im2double(app.labeledImage_rightside);
          BW = logical(app.cellMap_rightside);
          mask = edge(BW);
          app.edgeMask_rightside = mask;
          if app.UseCurrentMappingCheckBox.Value == true && ~isempty(app.f_map)
            multiplierForOffset = -1;
            [app.edgeMask_leftside, app.labeledImage_leftside, app.cellMap_leftside, app.regionProps_leftside] = createCellMaskOfOtherSide(app, app.cellMap_rightside, multiplierForOffset, app.enhancedImage_leftside);
          else
            app.edgeMask_leftside = zeros(size(mask),'logical');
            app.cellMap_leftside = zeros(size(app.cellMap_rightside));
          end
        else
          bothImages = [app.cellMap_leftside, app.cellMap_rightside];
          BWR = logical(bothImages);
          mask = edge(BWR);
          app.edgeMask_leftside = mask(:,1:app.imagesizex/2);
          app.edgeMask_rightside = mask(:,(1+app.imagesizex/2):end);
        end
        app.liveCellImage = [app.labeledImage_leftside, app.labeledImage_rightside];
        % temporary for pengning's data right now
%         [~, name, ~] = fileparts(app.filename);
%         if app.ThickerEdgesCheckBox.Value == true
%           detectionMethod = "thick";
%         else
%           detectionMethod = "thin";
%         end
%         cellsLeftside = liveCell_EdgeDetection(app.regionProps_leftside, app.cellMap_leftside,...
%           app.labeledImage_leftside, app.edgeMask_leftside, detectionMethod, app);
%         saveName = name + "CellsLeftside";
%         save(saveName, "cellsLeftside");
%         cellsRightside = liveCell_EdgeDetection(app.regionProps_rightside, app.cellMap_rightside,...
%           app.labeledImage_rightside, app.edgeMask_rightside, detectionMethod, app);
%         saveName = name + "CellsRightside";
%         save(saveName, "cellsRightside");
%         saveName = name + "regionPropsLeft"; cellInfoLeftside = app.regionProps_leftside; cellmapLeftside = app.cellMap_leftside;
%         save(saveName, 'cellInfoLeftside', 'cellmapLeftside');
%         saveName = name + "regionPropsRight"; cellInfoRightside = app.regionProps_rightside; cellmapRightside = app.cellMap_rightside;
%         save(saveName, 'cellInfoRightside', 'cellmapRightside');
        updatedisplay(app);
      end
        end

        % Value changed function: BlockSizeEditField
        function BlockSizeEditFieldValueChanged(app, event)
      value = app.BlockSizeEditField.Value;
      app.manualBlocksize = true;
      if any(value == ',') || any(value == ' ')
        if any(value == ',')
          separationStart = find(value == ',');
        else
          separationStart = find(value == ' ');
        end
        firstBlockNumber = str2double(value(1:separationStart-1));
        if any(value == ' ')
          separationEnd = find(value == ' ');
          secondBlockNumber = str2double(value(separationEnd+1:end));
        elseif any(value == ',')
          separationEnd = find(value == ',');
          secondBlockNumber = str2double(value(separationEnd+1:end));
        end
      else
        app.InfoTextArea.Value = "Input form needs to be: blocksize1, blocksize2";
      end
      if app.imagesizex/firstBlockNumber ~= round(app.imagesizex/firstBlockNumber) || app.imagesizey/secondBlockNumber ~= round(app.imagesizey/secondBlockNumber)
        d = divisors(app.imagesizex);
        d = num2str(d);
        app.InfoTextArea.Value = ["invalid block size. Block size must be a divisor of " +  num2str(app.imagesizex); ...
          "Divisors: " + string(d)];
        app.BlockSizeEditField.Value = string(app.blocksize(1)) + ", " + string(app.blocksize(2));
      else
        app.blocksize = [firstBlockNumber, secondBlockNumber];
        app.InfoTextArea.Value = "block size = " + num2str(firstBlockNumber) + ", " + num2str(secondBlockNumber);
      end

        end

        % Button pushed function: LiveCellNewFigureButton
        function LiveCellNewFigureButtonPushed(app, event)
      figure;
      flippedImage = flipud(app.liveCellImage);
      imshow(flippedImage);
        end

        % Callback function: MappingMenu, SaveMappingButton
        function SaveMappingButtonPushed(app, event)
      % Save mapping info (this saves original offsets, not the offsets modified by whichside)
      x_offset = app.xoffset;
      y_offset = app.yoffset;
      whatside = app.whichside;
      f = app.f_map;
      save('lastmapping','x_offset','y_offset','f',"whatside");
      app.InfoTextArea.Value = "mapping saved";
        end

        % Callback function: LastMappingMenu, LoadLastMappingButton
        function LoadLastMappingButtonPushed(app, event)
      loadLastMapping(app);
      updateTextInfo(app);
        end

        % Button pushed function: ViewTracesWithMovieButton
        function ViewTracesWithMovieButtonPushed(app, event)
      if app.LiveCellModeCheckBox.Value == true
      begin = app.GreenStartFrameEditField.Value;
      finish = app.GreenEndFrameEditField.Value;
      spotMovies({app, app.filename, app.cameratype, app.y_spots_leftside, app.y_spots_rightside, ...
        app.x_spots_leftside, app.x_spots_rightside, app.edgeMask_leftside, app.edgeMask_rightside, begin, finish});
      else
      spotMovies({app, app.filename, app.cameratype, app.y_spots_leftside, app.y_spots_rightside, ...
        app.x_spots_leftside, app.x_spots_rightside, [], [], 1, app.NumFrames});
      end


        end

        % Button pushed function: clearedgesButton
        function clearedgesButtonPushed(app, event)
      
%       clear cellMap_leftside  cellMap_rightside  cellMap_wholeimage  LiveCellSelectionMode ...
%         liveCellImage    edgeMask_leftside     edgeMask_rightside        edgeMask_wholeimage ...
%         numSpotsNotInACell        numSpotsNotInACellText  newSpotsText       enhancedImage_rightside  enhancedImage_leftside ...
%         enhancedImage_wholeimage  labeledImage_leftside  labeledImage_rightside  labeledImage_wholeimage
      liveCellVariables = {'cellMap_leftside',  'cellMap_rightside',    ...
        'liveCellImage',    'edgeMask_leftside',     'edgeMask_rightside',  ...
         'enhancedImage_rightside',  'enhancedImage_leftside', ...
          'labeledImage_leftside',  'labeledImage_rightside'};
      for i = 1:size(liveCellVariables,2)
        app.(liveCellVariables{i}) = [];
      end
      
      updatedisplay(app);
        end

        % Value changed function: HighContrastButton, LeftButton, 
        % LowContrastButton, RightButton
        function ContrastButtonValueChanged(app, event)
      whichButton = string(event.Source.Text);
      value = event.Source.Value;
      if whichButton == "Right"
        app.LeftButton.Value = ~value;
      elseif whichButton == "Left"
        app.RightButton.Value = ~value;
      end
      if value == true
        if whichButton == "High Contrast"
          app.LowContrastButton.Value = false;
        elseif whichButton == "Low Contrast"
          app.HighContrastButton.Value = false;
        end
      end
      if app.SaveContrastSettingsButton.Enable == false
        app.SaveContrastSettingsButton.Enable = true;
      end
        end

        % Button pushed function: roiaroundbiggestspotButton
        function roiaroundbiggestspotButtonPushed(app, event)
      % h = drawassisted(app.movieAxisRightside);
      %h = drawfreehand(app.movieAxisLeftside);
      roi = drawrectangle(app.movieAxisRightside);
      left = round(roi.Position(1));
      bottom = round(roi.Position(2));
      height = round(roi.Position(3));
      width = round(roi.Position(4));
      imageWithBigSpot = app.current_rightside(bottom:(bottom+height), left:(left+width));
      
      app.bigSpotLayout = tiledlayout(app.RachelSpotPanel,1,1,'TileSpacing','none', "Padding","none");
      app.bigSpotAxis = nexttile(app.bigSpotLayout);
      axis(app.bigSpotAxis, 'image');
      image(app.bigSpotAxis,imageWithBigSpot,"CDataMapping","scaled");
      app.bigSpotAxis.XTickMode = "manual";
      app.bigSpotAxis.YTickMode = "manual";
      app.bigSpotAxis.XTick = [];
      app.bigSpotAxis.YTick = [];
      app.bigSpotAxis.ButtonDownFcn = @(s,d)callback_bigSpotPixels(app,s,d);
      app.bigSpotAxis.Tag = 'bigSpot';
      %       colormap(app.bigSpotAxis,app.colorMapForImage);
      
      
      app.bigSpotAxis.CLim = app.movieAxisRightside.CLim;
      
      tb = axtoolbar(app.bigSpotAxis);
      btn = axtoolbarbtn(tb,'state');
      btn.Value = 'on';
      btn.Visible = 'off';
      
      btn.ButtonDownFcn = @(s,d)callback_bigSpotPixels(app,s,d);
      
      %https://www.mathworks.com/help/images/correcting-nonuniform-illumination.html
      
      %app.bigSpotAxis.Children.CData = left;
      %       app.bigSpotAxis.XLimMode = "manual";
      %       app.bigSpotAxis.YLimMode = "manual";
      
      
      %       bigSpotLayout
      %       bigSpotAxis
      %
      %
      minn = app.minEditField.Value;
      %       maxx = app.maxEditField.Value;
      %       BW = roicolor(imageWithBigSpot,minn, maxx);
      %       stats = regionprops(BW,'MajorAxisLength')
      %       h
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
      % there is probably a better way to do this..
      setUpMovieAxis(app); %This takes away the spots which we dont' want
      updatedisplay(app); % this puts the spots and everything else back.
      app.movieAxisLeftside.CLimMode = "manual";
      app.movieAxisRightside.CLimMode = "manual";
        end

        % Button pushed function: BackwardButton, ForwardButton
        function ForwardButtonPushed(app, event)
      now_frame = app.current_frame;
      oneMoreFrame = 1;
      if app.SplitgreenwhitemoviesCheckBox.Value == true && now_frame > (app.GreenLaserStartFrameForRGWEditField.Value +1)
          oneMoreFrame = 2;

      end
      if event.Source.Tag == '>>' %#ok<BDSCA>
        if (now_frame + oneMoreFrame) > app.NumFrames
          app.current_frame = 1;
        else
          app.current_frame = now_frame + oneMoreFrame;
        end
      else
        if now_frame - oneMoreFrame <= 0
          app.current_frame = app.NumFrames;
        else
          app.current_frame = now_frame - oneMoreFrame;
        end
      end
      [app.current_rightside, app.current_leftside] = get_avg_frames(app,app.current_frame,1);
      app.Frameslider.Value = app.current_frame;
      updatedisplay(app)
      app.FrameNumberEditField.Value = "Frame: " + num2str(app.current_frame);
      drawnow;
        end

        % Callback function: ContrastSettingsMenu_2, 
        % SaveContrastSettingsButton
        function SaveContrastSettingsButtonPushed(app, event)
      % Save contrast settings for later use or use in other apps
      left_lims = app.movieAxisLeftside.CLim;
      right_lims = app.movieAxisRightside.CLim;
      leftHigh = left_lims(2);
      leftLow = left_lims(1);
      rightHigh = right_lims(2);
      rightLow = right_lims(1);
      save('contrastSettings','leftHigh','leftLow','rightHigh','rightLow');
      app.InstructionsTextArea.Value = "Left Contrast: [ " + num2str(left_lims(1)) + ", " + num2str(left_lims(2)) + ...
        " ]  Right Contrast: [ " + num2str(right_lims(1)) + ", " + num2str(right_lims(2)) + " ] -- Contrast Settings Saved";
      app.LoadContrastSettingsButton.Enable = true;
      drawnow;
        end

        % Callback function: ContrastSettingsMenu, 
        % LoadContrastSettingsButton
        function LoadContrastSettingsButtonPushed(app, event)
      % load last mapping
      load contrastSettings.mat leftHigh leftLow rightHigh rightLow
      app.movieAxisLeftside.CLim = [leftLow leftHigh];
      app.movieAxisRightside.CLim = [rightLow rightHigh];
      app.InstructionsTextArea.Value = "Left Contrast: [ " + num2str(leftLow) + ", " + num2str(leftHigh) + ...
        " ]  Right Contrast: [ " + num2str(rightLow) + ", " + num2str(rightHigh) + " ] -- Contrast Settings Loaded";
      drawnow;
        end

        % Button pushed function: SmileButton
        function SmileButtonPushed(app, event)
      [smile, smallSmile] = makeASmile(app);
      size(smile)
      app.movieAxisLeftside.Children(end).CData = smile(:,1:175);
      app.movieAxisRightside.Children(end).CData = smile(:,176:end);
      disp('sdlkfj')

        end

        % Callback function
        function VisualizeSettingsButtonPushed(app, event)
      %roi = drawrectangle('Position',[500,500,1000,1000],'StripeColor','r');
      if app.whichside == "left"
        roi = drawrectangle(app.movieAxisLeftside, "InteractionsAllowed","translate", "Position",[1 1 30 30])
        %roi = images.roi.Rectangle(app.movieAxisLeftside, "InteractionsAllowed","translate", "Position",[1, 1, 3, 3])
      else
        roi = drawrectangle(app.movieAxisRightside, "InteractionsAllowed","translate", "Position",[1 1 30 30])
      end
      % to find the object again, use this:
      %obj = findobj(app.movieAxisLeftside.Children, 'Type', 'images.roi.rectangle')
      % set up listeners
      %addlistener(roi,'MovingROI',@allevents);
      %addlistener(roi,'ROIMoved',@allevents);
      %       function allevents(src,evt)
      %     evname = evt.EventName;
      %     switch(evname)
      %         case{'MovingROI'}
      %             disp(['ROI moving previous position: ' mat2str(evt.PreviousPosition)]);
      %             disp(['ROI moving current position: ' mat2str(evt.CurrentPosition)]);
      %         case{'ROIMoved'}
      %             disp(['ROI moved previous position: ' mat2str(evt.PreviousPosition)]);
      %             disp(['ROI moved current position: ' mat2str(evt.CurrentPosition)]);
      %     end
      % end
      
      currentSettings.nn = app.NearestNeighborEditField.Value;
      %       currentSettings.blockSize = app.blocksize;
      %       currentSettings.blockSizeText = app.BlockSizeEditField.Value;
      %       currentSettings.distanceFromEdges = app.DistanceAwayFromEdgesEditField.Value;
      %       currentSettings.squarewidth = app.SquareSizeAroundEachSpotEditField.Value;
      %       currentSettings.numPixels = app.NumPixelsForSumIntensityEditField.Value;
      %       currentSettings.areaToSearch = app.AreatoSearchforLocalMaximumsSpinner.Value;
      %       currentSettings.imageForFittingSize = app.ImageforFittingSizeSpinner.Value;
      %       currentSettings.offset = app.OffsetLimitSpinner.Value;
      %
      %       inspectAnalysisSettings(app, currentSettings);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
%app.num_spots = size(spotCoordsLeft.(thisFrame),1);
%trackMovementOfSpots_findspotsMethod(app, event)
cd('C:\Users\TPM Project\Chelsea\From_Pengning\20200924 Taq MutS M88C regular cell Alexa555 LD655 12mW green laser\+EP HILO');
% moviesInFolder= dir('*.pma');
% for i = 1:size(moviesInFolder,1)
%   app.filename = moviesInFolder(i).name;
%   OpenMovie(app,app.filename)
%   FindSpotsButtonPushed(app, event);
%   disp('pause')
% end
t = tic;
data = liveCellData;
moviesInFolder= dir('*.pma');
for i = 1:size(moviesInFolder,1)


  app.filename = moviesInFolder(i).name;
  OpenMovie(app,app.filename);
  app.DetectCellEdgesButton.Value = true;
  DetectCellEdgesButtonValueChanged(app, event);
  app.DetectCellEdgesButton.Value = false;
  DetectCellEdgesButtonValueChanged(app, event);
  cellMaps = liveCell_cellMap(app);
  FindSpotsButtonPushed(app, event);
  
%   % try loading the full movie at once:
%         startFrame = app.GreenStartFrameEditField.Value;
%         framenumber = startFrame;
%       endFrame = app.GreenEndFrameEditField.Value;
%       fseek(app.FileID, (4+app.imagesizex*app.imagesizey*(framenumber-1)*2*app.numCameras+(framenumber-1)*2*app.numCameras),-1);
%           fread(app.FileID,1, 'uint16'); % first thing it will read will be frame number
%           t2 = tic;
%           for idx = startFrame:endFrame
%             temp = fread(app.FileID,[app.imagesizex, app.imagesizey], 'uint16');
%             fread(app.FileID,1, 'uint16'); % first thing it will read will be frame number
%             temp = temp';
%             frameFieldname = "frame" + num2str(idx);
%             tempLeft.(frameFieldname) = temp(:,1:app.imagesizex/2);
%             tempRight.(frameFieldname) = temp(:,(1+app.imagesizex/2):app.imagesizex);
%           end
%           toc(t2)


  spots = findLiveCellSpotTraces(app, cellMaps); %, tempLeft, tempRight);
 

  %spots.assignSpotsToCells(app);
  data.addMovieData(spots, cellMaps)
%   [~, name, ~] = fileparts(app.filename);
%   saveName = name + ".traces";
%   spotArray.saveTraceFilesOfAllSpots(spotArray, saveName);
  %saveName = name + "_SpotData";
  %save(saveName, "spots");
  %data.addSpotArray(spots);
  
%           cellsLeftside = liveCell_EdgeDetection(app.regionProps_leftside, app.cellMap_leftside,...
%           app.labeledImage_leftside, app.edgeMask_leftside, detectionMethod, app);
%         saveName = name + "CellsLeftside";
%         save(saveName, "cellsLeftside");
%         cellsRightside = liveCell_EdgeDetection(app.regionProps_rightside, app.cellMap_rightside,...
%           app.labeledImage_rightside, app.edgeMask_rightside, detectionMethod, app);
%         saveName = name + "CellsRightside";
%         save(saveName, "cellsRightside");
clear spots cellMaps
  disp(num2str(i/size(moviesInFolder,1)))
end
saveNameForAllData = "spotData_allMovies";

save(saveNameForAllData, "data",  '-v7');


% cd('C:\Users\TPM Project\Chelsea\From_Pengning\20200924 Taq MutS M88C regular cell Alexa555 LD655 12mW green laser\-EP HILO');
% moviesInFolder= dir('*.pma');
% for i = 1:size(moviesInFolder,1)
%   app.filename = moviesInFolder(i).name;
%   OpenMovie(app,app.filename)
%   FindSpotsButtonPushed(app, event);
%   spotArray = findLiveCellSpotTraces(app);
%   [~, name, ~] = fileparts(app.filename);
%   saveName = name + ".traces";
%   spotArray.saveTraceFilesOfAllSpots(spotArray, saveName)
%   disp(num2str(i/80))
% end
disp('total time')
toc(t)
disp('done')

%       spotArray = findLiveCellSpotTraces(app);
%       spotMovies(app, app.analysisMode, spotArray);
      
      
      %app.analysisMode = "liveCell";
% inputs from guiForPrime: app, "liveCell", num_spots, spotCoordsLeft, spotCoordsRight,
          % regionsLeft, regionsRight, tracesLeft, tracesRight
%       save('spotArray', "spotArray");
          
      
%             if app.LiveCellModeCheckBox.Value == true
%       begin = app.GreenStartFrameEditField.Value;
%       finish = app.GreenEndFrameEditField.Value;
%       spotMovies({app, app.filename, app.cameratype, app.y_spots_leftside, app.y_spots_rightside, ...
%         app.x_spots_leftside, app.x_spots_rightside, app.edgeMask_leftside, app.edgeMask_rightside, begin, finish});
%       else
%       spotMovies({app, app.filename, app.cameratype, app.y_spots_leftside, app.y_spots_rightside, ...
%         app.x_spots_leftside, app.x_spots_rightside, [], [], 1, app.NumFrames});
%       end

      %trackMovementOfSpots_findspotsMethod(app, event);
      %trackMovementOfSpots_radialCenterMethod(app);
        end

        % Menu selected function: AllAppSettingsMenu, 
        % SettingsForThisMovieMenu
        function SaveAllAppSettingsMenuSelected(app, event)
      saveAllAppSettings(app, event);
        end

        % Menu selected function: AppSettingsMenu
        function LoadAllAppSettingsMenuSelected(app, event)
      loadAllAppSettings(app);
        end

        % Menu selected function: FixedAspectRatioMenu, RealsizeMenu
        function ImageDisplayMenuSelected(app, event)
      if event.Source.Type == "uimenu"
        event.Source.Checked = ~event.Source.Checked;
      end
      if string(event.Source.Tag) == "realsize"
        if app.RealsizeMenu.Checked == true
          app.FixedAspectRatioMenu.Checked = false;
          app.moviePanel_outer.AutoResizeChildren = 'off';
          app.moviePanel.Position(3) = app.imagesizex+3;
          app.moviePanel.Position(4) = app.imagesizey+3;
                % put movie panel in middle of screen
          app.moviePanel.Position(1) = (app.moviePanel_outer.InnerPosition(3) - app.moviePanel.OuterPosition(3))/2;
          app.moviePanel.Position(2) = (app.moviePanel_outer.InnerPosition(4) - app.moviePanel.OuterPosition(4))/2;
          app.moviePanel_outer.SizeChangedFcn = @(source,event)callback_keepRealsizeImage(app,source,event);
        else
          app.moviePanel_outer.SizeChangedFcn = [];
          app.GuiForPrimeGridLayout.RowHeight{2} = '10x';
          app.GuiForPrimeGridLayout.RowHeight{3} = '2x';
          app.GuiForPrimeGridLayout.ColumnWidth{2} = '9x';
          app.moviePanel.Position(3:4) = app.moviePanel_outer.InnerPosition(3:4);
          app.moviePanel.Position(1:2) = 1;
          app.moviePanel_outer.AutoResizeChildren = 'on';
          app.moviePanel.AutoResizeChildren = 'on';
          app.movieAxisRightside.DataAspectRatioMode = "auto";
          app.movieAxisLeftside.DataAspectRatioMode = "auto";
          app.movieAxisRightside.PlotBoxAspectRatioMode = "auto";
          app.movieAxisLeftside.PlotBoxAspectRatioMode = "auto";
        end
        
      elseif string(event.Source.Tag) == "aspectRatio"
        
        if app.FixedAspectRatioMenu.Checked == true
          app.RealsizeMenu.Checked = false;
          app.moviePanel_outer.AutoResizeChildren = 'off';
          app.movieAxisLeftside.DataAspectRatio = [1 1 1]; %relative length of data units
          app.movieAxisRightside.DataAspectRatio = [1 1 1];
          xratio = (app.imagesizex/2)/app.imagesizey;
          app.movieAxisRightside.PlotBoxAspectRatio = [xratio 1 1]; %relative length of each axis
          app.movieAxisLeftside.PlotBoxAspectRatio = [xratio 1 1];
          app.movieAxisRightside.DataAspectRatioMode = "manual";
          app.movieAxisLeftside.DataAspectRatioMode = "manual";
          app.movieAxisRightside.PlotBoxAspectRatioMode = "manual";
          app.movieAxisLeftside.PlotBoxAspectRatioMode = "manual";

          callback_keepAspectRatioConstant(app, 1, event);
          app.moviePanel_outer.SizeChangedFcn = @(source,event)callback_keepAspectRatioConstant(app,source,event);
        else
          app.moviePanel_outer.SizeChangedFcn = [];
          app.moviePanel_outer.AutoResizeChildren = 'on';
          app.moviePanel.AutoResizeChildren = 'on';
          app.moviePanel.Position(1:2) = [1,1];
          app.moviePanel.Position(3:4) = app.moviePanel_outer.InnerPosition(3:4);
          app.movieAxisRightside.DataAspectRatioMode = "auto";
          app.movieAxisLeftside.DataAspectRatioMode = "auto";
          app.movieAxisRightside.PlotBoxAspectRatioMode = "auto";
          app.movieAxisLeftside.PlotBoxAspectRatioMode = "auto";
        end
      end


        end

        % Value changed function: 
        % NumFramesToAverageForGreenLaserBackgroundSpinner, 
        % NumFramesToAvgSpotfindingBg, 
        % NumFramesToAvgSpotfindingImage, 
        % StartFrameForGreenLaserBackgroundEditField, 
        % StartFrameSpotfindingBg, StartFrameSpotfindingImage
        function framenumbersToUseChanged(app, event)
      tag = event.Source.Tag;
      autoCheckbox = findobj(app.FramenumbersTab, 'Type', 'uicheckbox', 'Tag', tag);
      autoCheckbox.Value = false;
      
        end

        % Value changed function: ThickerEdgesCheckBox, 
        % ThinnerEdgesCheckBox
        function ThinnerEdgesCheckBoxValueChanged(app, event)
      if event.Source.Tag == 'edgeMethod1'
        app.ThinnerEdgesCheckBox.Value = ~event.Source.Value;
      elseif event.Source.Tag == 'edgeMethod2'
        app.ThickerEdgesCheckBox.Value = ~event.Source.Value;
      end
        end

        % Value changed function: SplitgreenwhitemoviesCheckBox
        function SplitgreenwhitemoviesCheckBoxValueChanged(app, event)
      value = app.SplitgreenwhitemoviesCheckBox.Value;
%       if value == true
%         app.imagesizex = app.framesizex;
%       else
%         app.imagesizex = app.framesizex/2;
%       end
      [white, green] = get_avg_frames(app, app.current_frame, 1);
      app.current_leftside = green;
      app.current_rightside = white;
      app.Frameslider.Value = app.current_frame;
      %%%% written before switch back to imagesizex/2
      if app.SplitgreenwhitemoviesCheckBox.Value == true
        if app.movieAxisLeftside.XLim(2) == 0.5+app.imagesizex && app.current_frame < (app.GreenLaserStartFrameForRGWEditField.Value +1)
          app.movieAxisLeftside.XLim(2) = 0.5+app.imagesizex/2;
          app.movieAxisRightside.XLim(2) = 0.5+app.imagesizex/2;
        elseif app.movieAxisLeftside.XLim(2) == 0.5+app.imagesizex/2 && app.current_frame >= (app.GreenLaserStartFrameForRGWEditField.Value +1)
          app.movieAxisLeftside.XLim(2) = 0.5+app.imagesizex;
          app.movieAxisRightside.XLim(2) = 0.5+app.imagesizex;
        end
        if app.FixedAspectRatioMenu.Checked == true
          event.Source.Tag = "aspectRatio";
        elseif app.RealsizeMenu.Checked == true
          event.Source.Tag = "realsize";
        end
        ImageDisplayMenuSelected(app, event)
      end
%       updatedisplay(app)
%       if app.RealsizeMenu.Checked == true
%         callback_keepRealsizeImage(app,1,1)
%       elseif app.FixedAspectRatioMenu.Checked == true
%         callback_keepAspectRatioConstant(app, 1, 1);
%       end
      
        end

        % Value changed function: 
        % NumFramesToAverageForLiveCellImageEditField
        function NumFramesToAverageForLiveCellImageEditFieldValueChanged(app, event)
      value = app.NumFramesToAverageForLiveCellImageEditField.Value;
              %average the frames of the movie that show the cells in white light
        numFramesToAverage2 = app.NumFramesToAverageForLiveCellImageEditField.Value;
        [imageRight, imageLeft] = get_avg_frames(app,1,numFramesToAverage2);
        % modify image to enhance contrast

        
              app.movieAxisLeftside.Children(end).CData = imageLeft;
      app.movieAxisRightside.Children(end).CData = imageRight;
        end

        % Value changed function: SelectPointsButton
        function SelectPointsButtonValueChanged(app, event)
      value = app.SelectPointsButton.Value;
      if value == true
        delete(findobj(app.movieAxisLeftside, 'Type', 'line'));
        delete(findobj(app.movieAxisRightside, 'Type', 'line'));
        app.SelectPointsButton.Text = "Done";
        % set hit test for images to off. Otherwise the axis btn down fcn
        % won't get called
        set(app.movieAxisLeftside.Children, 'HitTest', "off", "PickableParts", 'none');
        set(app.movieAxisRightside.Children, 'HitTest', "off", "PickableParts", 'none');
        % assign button down functions to axes
        app.movieAxisLeftside.ButtonDownFcn = @(s,e)callback_selectPointsForMapping(app,s,e);
        app.movieAxisRightside.ButtonDownFcn = @(s,e)callback_selectPointsForMapping(app,s,e);
        % erase axes user data - this is where the points will be stored
%         app.movieAxisLeftside.UserData = [];
%         app.movieAxisRightside.UserData = [];
      else
        % delete the points that were selected
        
        
        % create a mapping with the points
        if app.whichside == "left"
          original_points = app.movieAxisLeftside.UserData;
          moving_points = app.movieAxisRightside.UserData;
          originalAxis = app.movieAxisLeftside;
          mappedAxis = app.movieAxisRightside;
          delete(findobj(app.movieAxisLeftside, 'Type', 'line'));
        else
          moving_points = app.movieAxisLeftside.UserData;
          original_points = app.movieAxisRightside.UserData;
          mappedAxis = app.movieAxisLeftside;
          originalAxis = app.movieAxisRightside;
          delete(findobj(app.movieAxisRightside, 'Type', 'line'));
        end
        tform = cp2tform(moving_points,original_points,'affine');  % tform = cp2tform(acceptor_points,donor_points,'affine');
        f = tform.tdata.Tinv;
        app.SaveMappingButton.Enable = true;
        app.UseCurrentMappingCheckBox.Enable = true;
        app.f_map = f;
        date = "Date of Last Mapping: " + datestr(now,2);
        side = "Whichside: " + char(app.whichside);
        offset = "Offset (x,y): n/a";
        fmaptitle = "Fmap: ";
        formatSpec = '%.4f';
        fmap1 = char(num2str(app.f_map(1,1),formatSpec)) + " " + char(num2str(app.f_map(1,2),formatSpec)) + " " + char(num2str(app.f_map(1,3),formatSpec));
        fmap2 = char(num2str(app.f_map(2,1),formatSpec)) + " " + char(num2str(app.f_map(2,2),formatSpec)) + " " + char(num2str(app.f_map(2,3),formatSpec));
        fmap3 = char(num2str(app.f_map(3,1),formatSpec)) + " " + char(num2str(app.f_map(3,2),formatSpec)) + " " + char(num2str(app.f_map(3,3),formatSpec));
        app.f_map_forDisplayInTextArea = [date , side, offset, fmaptitle, fmap1, fmap2, fmap3];
        % use the selected points from one side to show how the mapping
        % worked on the other side
        mapped_points = [original_points, ones(size(original_points,1),1)]*app.f_map;
        mapped_points = round(mapped_points);
        line(originalAxis,original_points(1,:),original_points(2,:),'linestyle','none','marker','o','color','r',"Tag",'mappingPoints');
        line(mappedAxis,mapped_points(1,:),mapped_points(2,:),'linestyle','none','marker','o','color','r',"Tag",'mappingPoints');
        % change button text back
        app.SelectPointsButton.Text = "Select Points";
        % get rid of user data and button down functions
        app.movieAxisLeftside.ButtonDownFcn = [];
        app.movieAxisRightside.ButtonDownFcn = [];
%         app.movieAxisLeftside.UserData = [];
%         app.movieAxisRightside.UserData = [];
        updateTextInfo(app);
      end
        end

        % Value changed function: AltModeEndFrameEditField, 
        % GreenLaserNumFramesOffEditField, 
        % GreenLaserNumFramesOnEditField, 
        % GreenLaserStartFrameForRGWEditField, 
        % WhiteLightNumFramesOffEditField, 
        % WhiteLightNumFramesOnEditField, 
        % WhiteLightStartFrameForRGWEditField
        function FramenumbersForRWGaltModeChanged(app, event)
      setFramenumbersForRWGaltMode(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.guiForPrimeUIFigure = uifigure('Visible', 'off');
            app.guiForPrimeUIFigure.Name = 'guiForPrime';
            app.guiForPrimeUIFigure.CloseRequestFcn = createCallbackFcn(app, @guiForPrimeUIFigureCloseRequest, true);
            app.guiForPrimeUIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @guiForPrimeUIFigureWindowButtonMotion, true);
            app.guiForPrimeUIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @guiForPrimeUIFigureWindowScrollWheel, true);
            app.guiForPrimeUIFigure.KeyPressFcn = createCallbackFcn(app, @guiForPrimeUIFigureKeyPress, true);
            app.LoadMenu = uimenu(app.guiForPrimeUIFigure);
            app.LoadMenu.Text = 'Load';
            app.MovieMenu = uimenu(app.LoadMenu);
            app.MovieMenu.Text = 'Movie';
            app.CascadeMenu_2 = uimenu(app.MovieMenu);
            app.CascadeMenu_2.MenuSelectedFcn = createCallbackFcn(app, @OpenFileMenuSelected, true);
            app.CascadeMenu_2.Text = 'Cascade';
            app.EvolveMenu_2 = uimenu(app.MovieMenu);
            app.EvolveMenu_2.MenuSelectedFcn = createCallbackFcn(app, @OpenFileMenuSelected, true);
            app.EvolveMenu_2.Text = 'Evolve';
            app.PrimeMenu_2 = uimenu(app.MovieMenu);
            app.PrimeMenu_2.MenuSelectedFcn = createCallbackFcn(app, @OpenFileMenuSelected, true);
            app.PrimeMenu_2.Text = 'Prime';
            app.TwinPrimeMenu_2 = uimenu(app.MovieMenu);
            app.TwinPrimeMenu_2.MenuSelectedFcn = createCallbackFcn(app, @OpenFileMenuSelected, true);
            app.TwinPrimeMenu_2.Text = 'TwinPrime';
            app.SettingsMenu = uimenu(app.LoadMenu);
            app.SettingsMenu.Text = 'Settings';
            app.AppSettingsMenu = uimenu(app.SettingsMenu);
            app.AppSettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadAllAppSettingsMenuSelected, true);
            app.AppSettingsMenu.Text = 'App Settings';
            app.ContrastSettingsMenu = uimenu(app.SettingsMenu);
            app.ContrastSettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadContrastSettingsButtonPushed, true);
            app.ContrastSettingsMenu.Text = 'Contrast Settings';
            app.LastMappingMenu = uimenu(app.SettingsMenu);
            app.LastMappingMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadLastMappingButtonPushed, true);
            app.LastMappingMenu.Text = 'Last Mapping';
            app.SpotsFoundMenu = uimenu(app.SettingsMenu);
            app.SpotsFoundMenu.Enable = 'off';
            app.SpotsFoundMenu.Text = 'Spots Found';
            app.Save = uimenu(app.guiForPrimeUIFigure);
            app.Save.Text = 'Save';
            app.MovieAsaviMenu = uimenu(app.Save);
            app.MovieAsaviMenu.MenuSelectedFcn = createCallbackFcn(app, @MovieAsaviMenuSelected, true);
            app.MovieAsaviMenu.Text = 'Movie As .avi';
            app.FrameasjpgMenu = uimenu(app.Save);
            app.FrameasjpgMenu.Text = 'Frame  as .jpg';
            app.AppSettingsMenu_2 = uimenu(app.Save);
            app.AppSettingsMenu_2.Text = 'App Settings';
            app.AllAppSettingsMenu = uimenu(app.AppSettingsMenu_2);
            app.AllAppSettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAllAppSettingsMenuSelected, true);
            app.AllAppSettingsMenu.Text = 'All App Settings';
            app.AllAppSettingsMenu.Tag = 'all';
            app.SettingsForThisMovieMenu = uimenu(app.AppSettingsMenu_2);
            app.SettingsForThisMovieMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAllAppSettingsMenuSelected, true);
            app.SettingsForThisMovieMenu.Text = 'Settings For This Movie';
            app.SettingsForThisMovieMenu.Tag = 'specificOnly';
            app.MappingMenu = uimenu(app.AppSettingsMenu_2);
            app.MappingMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMappingButtonPushed, true);
            app.MappingMenu.Text = 'Mapping';
            app.ContrastSettingsMenu_2 = uimenu(app.AppSettingsMenu_2);
            app.ContrastSettingsMenu_2.MenuSelectedFcn = createCallbackFcn(app, @SaveContrastSettingsButtonPushed, true);
            app.ContrastSettingsMenu_2.Text = 'Contrast Settings';
            app.DisplayMenu = uimenu(app.guiForPrimeUIFigure);
            app.DisplayMenu.Text = 'Display';
            app.ColormapMenu_2 = uimenu(app.DisplayMenu);
            app.ColormapMenu_2.Text = 'Colormap';
            app.GrayMenu = uimenu(app.ColormapMenu_2);
            app.GrayMenu.MenuSelectedFcn = createCallbackFcn(app, @ColormapMenuSelected, true);
            app.GrayMenu.Checked = 'on';
            app.GrayMenu.Text = 'Gray';
            app.BoneMenu = uimenu(app.ColormapMenu_2);
            app.BoneMenu.MenuSelectedFcn = createCallbackFcn(app, @ColormapMenuSelected, true);
            app.BoneMenu.Text = 'Bone';
            app.PinkMenu = uimenu(app.ColormapMenu_2);
            app.PinkMenu.MenuSelectedFcn = createCallbackFcn(app, @ColormapMenuSelected, true);
            app.PinkMenu.Text = 'Pink';
            app.WinterMenu = uimenu(app.ColormapMenu_2);
            app.WinterMenu.MenuSelectedFcn = createCallbackFcn(app, @ColormapMenuSelected, true);
            app.WinterMenu.Text = 'Winter';
            app.AutumnMenu = uimenu(app.ColormapMenu_2);
            app.AutumnMenu.MenuSelectedFcn = createCallbackFcn(app, @ColormapMenuSelected, true);
            app.AutumnMenu.Text = 'Autumn';
            app.HotMenu = uimenu(app.ColormapMenu_2);
            app.HotMenu.MenuSelectedFcn = createCallbackFcn(app, @ColormapMenuSelected, true);
            app.HotMenu.Text = 'Hot';
            app.CoolMenu = uimenu(app.ColormapMenu_2);
            app.CoolMenu.MenuSelectedFcn = createCallbackFcn(app, @ColormapMenuSelected, true);
            app.CoolMenu.Text = 'Cool';
            app.hsvMenu_2 = uimenu(app.ColormapMenu_2);
            app.hsvMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ColormapMenuSelected, true);
            app.hsvMenu_2.Text = 'hsv';
            app.JetMenu = uimenu(app.ColormapMenu_2);
            app.JetMenu.MenuSelectedFcn = createCallbackFcn(app, @ColormapMenuSelected, true);
            app.JetMenu.Text = 'Jet';
            app.ParulaMenu = uimenu(app.ColormapMenu_2);
            app.ParulaMenu.MenuSelectedFcn = createCallbackFcn(app, @ColormapMenuSelected, true);
            app.ParulaMenu.Text = 'Parula';
            app.LinesMenu = uimenu(app.ColormapMenu_2);
            app.LinesMenu.MenuSelectedFcn = createCallbackFcn(app, @ColormapMenuSelected, true);
            app.LinesMenu.Text = 'Lines';
            app.ColorcubeMenu = uimenu(app.ColormapMenu_2);
            app.ColorcubeMenu.MenuSelectedFcn = createCallbackFcn(app, @ColormapMenuSelected, true);
            app.ColorcubeMenu.Text = 'Colorcube';
            app.PrismMenu = uimenu(app.ColormapMenu_2);
            app.PrismMenu.MenuSelectedFcn = createCallbackFcn(app, @ColormapMenuSelected, true);
            app.PrismMenu.Text = 'Prism';
            app.FlagMenu = uimenu(app.ColormapMenu_2);
            app.FlagMenu.MenuSelectedFcn = createCallbackFcn(app, @ColormapMenuSelected, true);
            app.FlagMenu.Text = 'Flag';
            app.turboMenu = uimenu(app.ColormapMenu_2);
            app.turboMenu.MenuSelectedFcn = createCallbackFcn(app, @ColormapMenuSelected, true);
            app.turboMenu.Text = 'turbo';
            app.SpotColorsMenu_2 = uimenu(app.DisplayMenu);
            app.SpotColorsMenu_2.Text = 'SpotColors';
            app.DonorMenu_2 = uimenu(app.SpotColorsMenu_2);
            app.DonorMenu_2.MenuSelectedFcn = createCallbackFcn(app, @SpotColorMenuSelected, true);
            app.DonorMenu_2.Text = 'Donor';
            app.AcceptorMenu_2 = uimenu(app.SpotColorsMenu_2);
            app.AcceptorMenu_2.MenuSelectedFcn = createCallbackFcn(app, @SpotColorMenuSelected, true);
            app.AcceptorMenu_2.Text = 'Acceptor';
            app.ImageSizeMenu = uimenu(app.DisplayMenu);
            app.ImageSizeMenu.Text = 'ImageSize';
            app.RealsizeMenu = uimenu(app.ImageSizeMenu);
            app.RealsizeMenu.MenuSelectedFcn = createCallbackFcn(app, @ImageDisplayMenuSelected, true);
            app.RealsizeMenu.Checked = 'on';
            app.RealsizeMenu.Text = 'Realsize';
            app.RealsizeMenu.Tag = 'realsize';
            app.FixedAspectRatioMenu = uimenu(app.ImageSizeMenu);
            app.FixedAspectRatioMenu.MenuSelectedFcn = createCallbackFcn(app, @ImageDisplayMenuSelected, true);
            app.FixedAspectRatioMenu.Text = 'FixedAspectRatio';
            app.FixedAspectRatioMenu.Tag = 'aspectRatio';
            app.GuiForPrimeGridLayout = uigridlayout(app.guiForPrimeUIFigure);
            app.AnalyzeTabGroup = uitabgroup(app.GuiForPrimeGridLayout);
            app.SpotsTab = uitab(app.AnalyzeTabGroup);
            app.SpotsTab.Title = 'Spots';
            app.SpotsTabGridLayout = uigridlayout(app.SpotsTab);
            app.SpotSettingsPanel = uipanel(app.SpotsTabGridLayout);
            app.WhichSideDropDownLabel = uilabel(app.SpotSettingsPanel);
            app.WhichSideDropDownLabel.Text = 'Which Side ';
            app.WhichSideDropDown = uidropdown(app.SpotSettingsPanel);
            app.WhichSideDropDown.Items = {'Left', 'Right', 'Whole Image'};
            app.WhichSideDropDown.ItemsData = {'left', 'right', 'both'};
            app.WhichSideDropDown.ValueChangedFcn = createCallbackFcn(app, @WhichSideDropDownValueChanged, true);
            app.WhichSideDropDown.Value = 'left';
            app.SpotTypeDropDownLabel = uilabel(app.SpotSettingsPanel);
            app.SpotTypeDropDownLabel.Text = 'Spot Type';
            app.SpotTypeDropDown = uidropdown(app.SpotSettingsPanel);
            app.SpotTypeDropDown.Items = {'Single Molecule', 'Random'};
            app.SpotTypeDropDown.Value = 'Single Molecule';
            app.FindSpotsButton = uibutton(app.SpotSettingsPanel, 'push');
            app.FindSpotsButton.ButtonPushedFcn = createCallbackFcn(app, @FindSpotsButtonPushed, true);
            app.FindSpotsButton.Text = 'Find Spots';
            app.UpButton = uibutton(app.SpotSettingsPanel, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @ThresholdChanged, true);
            app.UpButton.Text = 'Up';
            app.DownButton = uibutton(app.SpotSettingsPanel, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @ThresholdChanged, true);
            app.DownButton.Text = 'Down';
            app.ThresholdEditFieldLabel = uilabel(app.SpotSettingsPanel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.SpotSettingsPanel, 'numeric');
            app.ThresholdEditField.ValueDisplayFormat = '%.2f';
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdChanged, true);
            app.RedGreenAltModeCheckBox = uicheckbox(app.SpotSettingsPanel);
            app.RedGreenAltModeCheckBox.ValueChangedFcn = createCallbackFcn(app, @ModeCheckBoxValueChanged, true);
            app.RedGreenAltModeCheckBox.Tag = 'analysisMode';
            app.RedGreenAltModeCheckBox.Text = 'Red Green Alt Mode';
            app.RedWhiteGreenAltModeCheckBox = uicheckbox(app.SpotSettingsPanel);
            app.RedWhiteGreenAltModeCheckBox.ValueChangedFcn = createCallbackFcn(app, @ModeCheckBoxValueChanged, true);
            app.RedWhiteGreenAltModeCheckBox.Tag = 'analysisMode';
            app.RedWhiteGreenAltModeCheckBox.Text = 'Red White Green Alt Mode';
            app.GreenStartFrameEditFieldLabel = uilabel(app.SpotSettingsPanel);
            app.GreenStartFrameEditFieldLabel.Tag = 'greenLaser';
            app.GreenStartFrameEditFieldLabel.Enable = 'off';
            app.GreenStartFrameEditFieldLabel.Text = 'Green Start Frame';
            app.GreenStartFrameEditField = uieditfield(app.SpotSettingsPanel, 'numeric');
            app.GreenStartFrameEditField.ValueChangedFcn = createCallbackFcn(app, @StartOrEndFrameValueChanged, true);
            app.GreenStartFrameEditField.Tag = 'greenLaser';
            app.GreenStartFrameEditField.Enable = 'off';
            app.GreenStartFrameEditField.Value = 22;
            app.GreenEndFrameEditFieldLabel = uilabel(app.SpotSettingsPanel);
            app.GreenEndFrameEditFieldLabel.Tag = 'greenLaser';
            app.GreenEndFrameEditFieldLabel.Enable = 'off';
            app.GreenEndFrameEditFieldLabel.Text = 'Green End Frame';
            app.GreenEndFrameEditField = uieditfield(app.SpotSettingsPanel, 'numeric');
            app.GreenEndFrameEditField.ValueChangedFcn = createCallbackFcn(app, @StartOrEndFrameValueChanged, true);
            app.GreenEndFrameEditField.Tag = 'greenLaser';
            app.GreenEndFrameEditField.Enable = 'off';
            app.GreenEndFrameEditField.Value = 798;
            app.RachelsBigSpotsModeCheckBox = uicheckbox(app.SpotSettingsPanel);
            app.RachelsBigSpotsModeCheckBox.ValueChangedFcn = createCallbackFcn(app, @ModeCheckBoxValueChanged, true);
            app.RachelsBigSpotsModeCheckBox.Tag = 'analysisMode';
            app.RachelsBigSpotsModeCheckBox.Text = 'Rachel''s Big Spots Mode';
            app.InvertSwitch = uiswitch(app.SpotSettingsPanel, 'slider');
            app.InvertSwitch.Items = {'Regular', 'Invert'};
            app.InvertSwitch.Value = 'Regular';
            app.LiveCellModeCheckBox = uicheckbox(app.SpotSettingsPanel);
            app.LiveCellModeCheckBox.ValueChangedFcn = createCallbackFcn(app, @ModeCheckBoxValueChanged, true);
            app.LiveCellModeCheckBox.Tag = 'analysisMode';
            app.LiveCellModeCheckBox.Text = 'Live Cell Mode';
            app.HOLDCheckBox = uicheckbox(app.SpotSettingsPanel);
            app.HOLDCheckBox.Text = 'HOLD';
            app.AnalysisSettingsTabGroup = uitabgroup(app.SpotsTabGridLayout);
            app.AnalyzeTab = uitab(app.AnalysisSettingsTabGroup);
            app.AnalyzeTab.Title = 'Analyze';
            app.ViewTracesWithMovieButton = uibutton(app.AnalyzeTab, 'push');
            app.ViewTracesWithMovieButton.ButtonPushedFcn = createCallbackFcn(app, @ViewTracesWithMovieButtonPushed, true);
            app.ViewTracesWithMovieButton.Text = 'View Traces With Movie';
            app.ViewTracesButton = uibutton(app.AnalyzeTab, 'push');
            app.ViewTracesButton.ButtonPushedFcn = createCallbackFcn(app, @ViewTracesButtonPushed, true);
            app.ViewTracesButton.Text = 'View Traces';
            app.ViewTPMButton = uibutton(app.AnalyzeTab, 'push');
            app.ViewTPMButton.ButtonPushedFcn = createCallbackFcn(app, @ViewTPMButtonPushed, true);
            app.ViewTPMButton.Enable = 'off';
            app.ViewTPMButton.Text = {'View TPM '; ''};
            app.ViewFRETTPMButton = uibutton(app.AnalyzeTab, 'push');
            app.ViewFRETTPMButton.ButtonPushedFcn = createCallbackFcn(app, @ViewFRETTPMButtonPushed, true);
            app.ViewFRETTPMButton.Enable = 'off';
            app.ViewFRETTPMButton.Text = {'View FRET/TPM '; ''};
            app.AnalysisPanel = uipanel(app.AnalyzeTab);
            app.AnalysisTypeDropDownLabel = uilabel(app.AnalysisPanel);
            app.AnalysisTypeDropDownLabel.Text = 'Analysis Type';
            app.AnalysisTypeDropDown = uidropdown(app.AnalysisPanel);
            app.AnalysisTypeDropDown.Items = {'FRET', 'TPM', 'FRET and TPM'};
            app.AnalysisTypeDropDown.Value = 'FRET';
            app.AnalyzeButton = uibutton(app.AnalysisPanel, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.BatchAnalysisButton = uibutton(app.AnalysisPanel, 'push');
            app.BatchAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @BatchAnalysisButtonPushed, true);
            app.BatchAnalysisButton.Text = 'Batch Analysis';
            app.Button = uibutton(app.AnalyzeTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.LiveCellModeTab = uitab(app.AnalysisSettingsTabGroup);
            app.LiveCellModeTab.Title = 'Live Cell Mode';
            app.clearedgesButton = uibutton(app.LiveCellModeTab, 'push');
            app.clearedgesButton.ButtonPushedFcn = createCallbackFcn(app, @clearedgesButtonPushed, true);
            app.clearedgesButton.Tag = 'livecell';
            app.clearedgesButton.Enable = 'off';
            app.clearedgesButton.Text = {'clear'; 'edges'};
            app.MinAreaofCellEditFieldLabel = uilabel(app.LiveCellModeTab);
            app.MinAreaofCellEditFieldLabel.Tag = 'livecell';
            app.MinAreaofCellEditFieldLabel.Enable = 'off';
            app.MinAreaofCellEditFieldLabel.Text = 'Min Area of Cell';
            app.MinAreaofCellEditField = uieditfield(app.LiveCellModeTab, 'numeric');
            app.MinAreaofCellEditField.Limits = [0 Inf];
            app.MinAreaofCellEditField.ValueDisplayFormat = '%11.0f';
            app.MinAreaofCellEditField.Tag = 'livecell';
            app.MinAreaofCellEditField.Enable = 'off';
            app.MinAreaofCellEditField.Value = 40;
            app.DetectCellEdgesButton = uibutton(app.LiveCellModeTab, 'state');
            app.DetectCellEdgesButton.ValueChangedFcn = createCallbackFcn(app, @DetectCellEdgesButtonValueChanged, true);
            app.DetectCellEdgesButton.Tag = 'livecell';
            app.DetectCellEdgesButton.Enable = 'off';
            app.DetectCellEdgesButton.Text = 'Detect Cell Edges';
            app.LiveCellNewFigureButton = uibutton(app.LiveCellModeTab, 'push');
            app.LiveCellNewFigureButton.ButtonPushedFcn = createCallbackFcn(app, @LiveCellNewFigureButtonPushed, true);
            app.LiveCellNewFigureButton.Tag = 'livecell';
            app.LiveCellNewFigureButton.Enable = 'off';
            app.LiveCellNewFigureButton.Text = 'Live Cell New Figure';
            app.NumFramesToAverageForLiveCellImageEditFieldLabel = uilabel(app.LiveCellModeTab);
            app.NumFramesToAverageForLiveCellImageEditFieldLabel.Tag = 'livecell';
            app.NumFramesToAverageForLiveCellImageEditFieldLabel.Enable = 'off';
            app.NumFramesToAverageForLiveCellImageEditFieldLabel.Text = {'Num Frames To Average'; 'For Live Cell Image'};
            app.NumFramesToAverageForLiveCellImageEditField = uieditfield(app.LiveCellModeTab, 'numeric');
            app.NumFramesToAverageForLiveCellImageEditField.RoundFractionalValues = 'on';
            app.NumFramesToAverageForLiveCellImageEditField.ValueChangedFcn = createCallbackFcn(app, @NumFramesToAverageForLiveCellImageEditFieldValueChanged, true);
            app.NumFramesToAverageForLiveCellImageEditField.Tag = 'livecell';
            app.NumFramesToAverageForLiveCellImageEditField.Enable = 'off';
            app.NumFramesToAverageForLiveCellImageEditField.Value = 10;
            app.ThickerEdgesCheckBox = uicheckbox(app.LiveCellModeTab);
            app.ThickerEdgesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ThinnerEdgesCheckBoxValueChanged, true);
            app.ThickerEdgesCheckBox.Tag = 'edgeMethod1';
            app.ThickerEdgesCheckBox.Text = 'Thicker Edges';
            app.ThinnerEdgesCheckBox = uicheckbox(app.LiveCellModeTab);
            app.ThinnerEdgesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ThinnerEdgesCheckBoxValueChanged, true);
            app.ThinnerEdgesCheckBox.Tag = 'edgeMethod2';
            app.ThinnerEdgesCheckBox.Text = 'Thinner Edges';
            app.ThinnerEdgesCheckBox.Value = true;
            app.InvertCheckBox = uicheckbox(app.LiveCellModeTab);
            app.InvertCheckBox.Text = 'Invert';
            app.CellBgSubtractionDropDownLabel = uilabel(app.LiveCellModeTab);
            app.CellBgSubtractionDropDownLabel.Text = 'Cell Bg Subtraction';
            app.CellBgSubtractionDropDown = uidropdown(app.LiveCellModeTab);
            app.CellBgSubtractionDropDown.Items = {'none', 'square', 'cell edges'};
            app.CellBgSubtractionDropDown.Value = 'none';
            app.ThicknessSpinnerLabel = uilabel(app.LiveCellModeTab);
            app.ThicknessSpinnerLabel.Tag = 'livecell';
            app.ThicknessSpinnerLabel.Enable = 'off';
            app.ThicknessSpinnerLabel.Text = 'Thickness';
            app.ThicknessSpinner = uispinner(app.LiveCellModeTab);
            app.ThicknessSpinner.Limits = [0 Inf];
            app.ThicknessSpinner.RoundFractionalValues = 'on';
            app.ThicknessSpinner.ValueDisplayFormat = '%11.0g';
            app.ThicknessSpinner.Tag = 'livecell';
            app.ThicknessSpinner.Enable = 'off';
            app.ThicknessSpinner.Value = 1;
            app.BigSpotsTab = uitab(app.AnalysisSettingsTabGroup);
            app.BigSpotsTab.Title = 'Big Spots';
            app.TailLengthpixelsSpinnerLabel = uilabel(app.BigSpotsTab);
            app.TailLengthpixelsSpinnerLabel.Text = 'Tail Length (pixels)';
            app.TailLengthpixelsSpinner = uispinner(app.BigSpotsTab);
            app.TailLengthpixelsSpinner.Limits = [0 200];
            app.TailLengthpixelsSpinner.RoundFractionalValues = 'on';
            app.TailLengthpixelsSpinner.Value = 10;
            app.RedWhiteGreenAltModeTab = uitab(app.AnalysisSettingsTabGroup);
            app.RedWhiteGreenAltModeTab.Title = 'Red White Green Alt Mode';
            app.GreenLaserLabel = uilabel(app.RedWhiteGreenAltModeTab);
            app.GreenLaserLabel.Tag = 'rwg';
            app.GreenLaserLabel.Enable = 'off';
            app.GreenLaserLabel.Text = 'Green Laser';
            app.FramesonLabel = uilabel(app.RedWhiteGreenAltModeTab);
            app.FramesonLabel.Tag = 'rwg';
            app.FramesonLabel.Enable = 'off';
            app.FramesonLabel.Text = 'Frames on';
            app.GreenLaserNumFramesOnEditField = uieditfield(app.RedWhiteGreenAltModeTab, 'numeric');
            app.GreenLaserNumFramesOnEditField.ValueChangedFcn = createCallbackFcn(app, @FramenumbersForRWGaltModeChanged, true);
            app.GreenLaserNumFramesOnEditField.Tag = 'rwg';
            app.GreenLaserNumFramesOnEditField.Enable = 'off';
            app.GreenLaserNumFramesOnEditField.Value = 1;
            app.FramesoffEditFieldLabel = uilabel(app.RedWhiteGreenAltModeTab);
            app.FramesoffEditFieldLabel.Tag = 'rwg';
            app.FramesoffEditFieldLabel.Enable = 'off';
            app.FramesoffEditFieldLabel.Text = 'Frames off';
            app.GreenLaserNumFramesOffEditField = uieditfield(app.RedWhiteGreenAltModeTab, 'numeric');
            app.GreenLaserNumFramesOffEditField.ValueChangedFcn = createCallbackFcn(app, @FramenumbersForRWGaltModeChanged, true);
            app.GreenLaserNumFramesOffEditField.Tag = 'rwg';
            app.GreenLaserNumFramesOffEditField.Enable = 'off';
            app.GreenLaserNumFramesOffEditField.Value = 5;
            app.StartFrameEditField_2Label = uilabel(app.RedWhiteGreenAltModeTab);
            app.StartFrameEditField_2Label.Tag = 'rwg';
            app.StartFrameEditField_2Label.Enable = 'off';
            app.StartFrameEditField_2Label.Text = 'Start Frame';
            app.GreenLaserStartFrameForRGWEditField = uieditfield(app.RedWhiteGreenAltModeTab, 'numeric');
            app.GreenLaserStartFrameForRGWEditField.ValueChangedFcn = createCallbackFcn(app, @FramenumbersForRWGaltModeChanged, true);
            app.GreenLaserStartFrameForRGWEditField.Tag = 'rwg';
            app.GreenLaserStartFrameForRGWEditField.Enable = 'off';
            app.GreenLaserStartFrameForRGWEditField.Value = 10;
            app.WhiteLightNumFramesOnEditField = uieditfield(app.RedWhiteGreenAltModeTab, 'numeric');
            app.WhiteLightNumFramesOnEditField.ValueChangedFcn = createCallbackFcn(app, @FramenumbersForRWGaltModeChanged, true);
            app.WhiteLightNumFramesOnEditField.Tag = 'rwg';
            app.WhiteLightNumFramesOnEditField.Enable = 'off';
            app.WhiteLightNumFramesOnEditField.Value = 1;
            app.WhiteLightNumFramesOffEditField = uieditfield(app.RedWhiteGreenAltModeTab, 'numeric');
            app.WhiteLightNumFramesOffEditField.ValueChangedFcn = createCallbackFcn(app, @FramenumbersForRWGaltModeChanged, true);
            app.WhiteLightNumFramesOffEditField.Tag = 'rwg';
            app.WhiteLightNumFramesOffEditField.Enable = 'off';
            app.WhiteLightNumFramesOffEditField.Value = 5;
            app.WhiteLightStartFrameForRGWEditField = uieditfield(app.RedWhiteGreenAltModeTab, 'numeric');
            app.WhiteLightStartFrameForRGWEditField.ValueChangedFcn = createCallbackFcn(app, @FramenumbersForRWGaltModeChanged, true);
            app.WhiteLightStartFrameForRGWEditField.Tag = 'rwg';
            app.WhiteLightStartFrameForRGWEditField.Enable = 'off';
            app.WhiteLightStartFrameForRGWEditField.Value = 13;
            app.WhiteLightLabel = uilabel(app.RedWhiteGreenAltModeTab);
            app.WhiteLightLabel.Tag = 'rwg';
            app.WhiteLightLabel.Enable = 'off';
            app.WhiteLightLabel.Text = 'White Light';
            app.AltModeEndFrameEditFieldLabel = uilabel(app.RedWhiteGreenAltModeTab);
            app.AltModeEndFrameEditFieldLabel.Tag = 'rwg';
            app.AltModeEndFrameEditFieldLabel.Enable = 'off';
            app.AltModeEndFrameEditFieldLabel.Text = 'Alt Mode End Frame';
            app.AltModeEndFrameEditField = uieditfield(app.RedWhiteGreenAltModeTab, 'numeric');
            app.AltModeEndFrameEditField.ValueChangedFcn = createCallbackFcn(app, @FramenumbersForRWGaltModeChanged, true);
            app.AltModeEndFrameEditField.Tag = 'rwg';
            app.AltModeEndFrameEditField.Enable = 'off';
            app.AltModeEndFrameEditField.Value = Inf;
            app.SplitgreenwhitemoviesCheckBox = uicheckbox(app.RedWhiteGreenAltModeTab);
            app.SplitgreenwhitemoviesCheckBox.ValueChangedFcn = createCallbackFcn(app, @SplitgreenwhitemoviesCheckBoxValueChanged, true);
            app.SplitgreenwhitemoviesCheckBox.Tag = 'rwg';
            app.SplitgreenwhitemoviesCheckBox.Enable = 'off';
            app.SplitgreenwhitemoviesCheckBox.Text = 'Split green white movies';
            app.AdvancedSettingsTab = uitab(app.AnalyzeTabGroup);
            app.AdvancedSettingsTab.Title = 'Advanced Settings';
            app.AdvancedSettingsGridLayout = uigridlayout(app.AdvancedSettingsTab);
            app.AdvancedSettingsTabGroup2 = uitabgroup(app.AdvancedSettingsGridLayout);
            app.SettingsTab = uitab(app.AdvancedSettingsTabGroup2);
            app.SettingsTab.Title = 'Settings';
            app.ForBackgroundCalculationLabel = uilabel(app.SettingsTab);
            app.ForBackgroundCalculationLabel.Text = 'For Background Calculation:';
            app.ForFindingSpotsLabel = uilabel(app.SettingsTab);
            app.ForFindingSpotsLabel.Text = 'For Finding Spots:';
            app.ForAnalysisLabel = uilabel(app.SettingsTab);
            app.ForAnalysisLabel.Text = 'For Analysis: ';
            app.TPMAnalysisLabel = uilabel(app.SettingsTab);
            app.TPMAnalysisLabel.Text = 'TPM Analysis:';
            app.NearestNeighborEditFieldLabel = uilabel(app.SettingsTab);
            app.NearestNeighborEditFieldLabel.Text = 'Nearest Neighbor';
            app.NearestNeighborEditField = uieditfield(app.SettingsTab, 'numeric');
            app.NearestNeighborEditField.Limits = [0 200];
            app.NearestNeighborEditField.RoundFractionalValues = 'on';
            app.NearestNeighborEditField.ValueChangedFcn = createCallbackFcn(app, @NearestNeighborEditFieldValueChanged, true);
            app.NearestNeighborEditField.Value = 5;
            app.BlockSizeEditFieldLabel = uilabel(app.SettingsTab);
            app.BlockSizeEditFieldLabel.Text = 'Block Size';
            app.BlockSizeEditField = uieditfield(app.SettingsTab, 'text');
            app.BlockSizeEditField.ValueChangedFcn = createCallbackFcn(app, @BlockSizeEditFieldValueChanged, true);
            app.DistanceAwayFromEdgesEditFieldLabel = uilabel(app.SettingsTab);
            app.DistanceAwayFromEdgesEditFieldLabel.Text = 'Distance Away From Edges';
            app.DistanceAwayFromEdgesEditField = uieditfield(app.SettingsTab, 'numeric');
            app.DistanceAwayFromEdgesEditField.Limits = [0 200];
            app.DistanceAwayFromEdgesEditField.RoundFractionalValues = 'on';
            app.DistanceAwayFromEdgesEditField.Value = 18;
            app.SquareSizeAroundEachSpotEditFieldLabel = uilabel(app.SettingsTab);
            app.SquareSizeAroundEachSpotEditFieldLabel.Text = 'Square Size Around Each Spot';
            app.SquareSizeAroundEachSpotEditField = uieditfield(app.SettingsTab, 'numeric');
            app.SquareSizeAroundEachSpotEditField.Limits = [1 50];
            app.SquareSizeAroundEachSpotEditField.RoundFractionalValues = 'on';
            app.SquareSizeAroundEachSpotEditField.Value = 3;
            app.NumPixelsForSumIntensityEditFieldLabel = uilabel(app.SettingsTab);
            app.NumPixelsForSumIntensityEditFieldLabel.Text = 'Num Pixels For Sum Intensity';
            app.NumPixelsForSumIntensityEditField = uieditfield(app.SettingsTab, 'numeric');
            app.NumPixelsForSumIntensityEditField.Limits = [1 200];
            app.NumPixelsForSumIntensityEditField.RoundFractionalValues = 'on';
            app.NumPixelsForSumIntensityEditField.Value = 4;
            app.AreatoSearchforLocalMaximumsSpinnerLabel = uilabel(app.SettingsTab);
            app.AreatoSearchforLocalMaximumsSpinnerLabel.Text = 'Area to Search for Local Maximums';
            app.AreatoSearchforLocalMaximumsSpinner = uispinner(app.SettingsTab);
            app.AreatoSearchforLocalMaximumsSpinner.Step = 2;
            app.AreatoSearchforLocalMaximumsSpinner.Limits = [0 50];
            app.AreatoSearchforLocalMaximumsSpinner.RoundFractionalValues = 'on';
            app.AreatoSearchforLocalMaximumsSpinner.Value = 3;
            app.ImageforFittingSizeSpinnerLabel = uilabel(app.SettingsTab);
            app.ImageforFittingSizeSpinnerLabel.Text = 'Image for Fitting Size';
            app.ImageforFittingSizeSpinner = uispinner(app.SettingsTab);
            app.ImageforFittingSizeSpinner.Step = 2;
            app.ImageforFittingSizeSpinner.Limits = [3 200];
            app.ImageforFittingSizeSpinner.RoundFractionalValues = 'on';
            app.ImageforFittingSizeSpinner.Value = 21;
            app.OffsetLimitSpinnerLabel = uilabel(app.SettingsTab);
            app.OffsetLimitSpinnerLabel.Text = 'Offset Limit';
            app.OffsetLimitSpinner = uispinner(app.SettingsTab);
            app.OffsetLimitSpinner.Limits = [1 Inf];
            app.OffsetLimitSpinner.RoundFractionalValues = 'on';
            app.OffsetLimitSpinner.Value = 10;
            app.FramenumbersTab = uitab(app.AdvancedSettingsTabGroup2);
            app.FramenumbersTab.Title = 'Framenumbers';
            app.SpotfindingImageLabel = uilabel(app.FramenumbersTab);
            app.SpotfindingImageLabel.Text = {'Spotfinding'; 'Image:'};
            app.NumFramesToAvgSpotfindingBg = uispinner(app.FramenumbersTab);
            app.NumFramesToAvgSpotfindingBg.RoundFractionalValues = 'on';
            app.NumFramesToAvgSpotfindingBg.ValueChangedFcn = createCallbackFcn(app, @framenumbersToUseChanged, true);
            app.NumFramesToAvgSpotfindingBg.Tag = 'bgNF';
            app.NumFramesToAvgSpotfindingBg.Value = 10;
            app.NumFramesToAvgSpotfindingImage = uispinner(app.FramenumbersTab);
            app.NumFramesToAvgSpotfindingImage.RoundFractionalValues = 'on';
            app.NumFramesToAvgSpotfindingImage.ValueChangedFcn = createCallbackFcn(app, @framenumbersToUseChanged, true);
            app.NumFramesToAvgSpotfindingImage.Tag = 'spotsNF';
            app.NumFramesToAvgSpotfindingImage.Value = 10;
            app.StartFrameSpotfindingImage = uieditfield(app.FramenumbersTab, 'numeric');
            app.StartFrameSpotfindingImage.ValueChangedFcn = createCallbackFcn(app, @framenumbersToUseChanged, true);
            app.StartFrameSpotfindingImage.Tag = 'spotsStF';
            app.StartFrameSpotfindingImage.Value = 1;
            app.StartFrameSpotfindingBg = uieditfield(app.FramenumbersTab, 'numeric');
            app.StartFrameSpotfindingBg.ValueChangedFcn = createCallbackFcn(app, @framenumbersToUseChanged, true);
            app.StartFrameSpotfindingBg.Tag = 'bgStF';
            app.StartFrameForGreenLaserBackgroundEditField = uieditfield(app.FramenumbersTab, 'numeric');
            app.StartFrameForGreenLaserBackgroundEditField.ValueChangedFcn = createCallbackFcn(app, @framenumbersToUseChanged, true);
            app.StartFrameForGreenLaserBackgroundEditField.Tag = 'greenStF';
            app.NumFramesToAverageForGreenLaserBackgroundSpinner = uispinner(app.FramenumbersTab);
            app.NumFramesToAverageForGreenLaserBackgroundSpinner.RoundFractionalValues = 'on';
            app.NumFramesToAverageForGreenLaserBackgroundSpinner.ValueChangedFcn = createCallbackFcn(app, @framenumbersToUseChanged, true);
            app.NumFramesToAverageForGreenLaserBackgroundSpinner.Tag = 'greenNF';
            app.NumFramesToAverageForGreenLaserBackgroundSpinner.Value = 10;
            app.autoStartFrameSpotsCheckbox = uicheckbox(app.FramenumbersTab);
            app.autoStartFrameSpotsCheckbox.ValueChangedFcn = createCallbackFcn(app, @autoFrameSelectionCheckboxValueChanged, true);
            app.autoStartFrameSpotsCheckbox.Tag = 'spotsStF';
            app.autoStartFrameSpotsCheckbox.Text = '';
            app.autoStartFrameSpotsCheckbox.Value = true;
            app.autoNumFramesSpotsCheckbox = uicheckbox(app.FramenumbersTab);
            app.autoNumFramesSpotsCheckbox.ValueChangedFcn = createCallbackFcn(app, @autoFrameSelectionCheckboxValueChanged, true);
            app.autoNumFramesSpotsCheckbox.Tag = 'spotsNF';
            app.autoNumFramesSpotsCheckbox.Text = '';
            app.autoNumFramesSpotsCheckbox.Value = true;
            app.autoStartFrameBgCheckbox = uicheckbox(app.FramenumbersTab);
            app.autoStartFrameBgCheckbox.ValueChangedFcn = createCallbackFcn(app, @autoFrameSelectionCheckboxValueChanged, true);
            app.autoStartFrameBgCheckbox.Tag = 'bgStF';
            app.autoStartFrameBgCheckbox.Text = '';
            app.autoStartFrameBgCheckbox.Value = true;
            app.autoNumFramesBgCheckbox = uicheckbox(app.FramenumbersTab);
            app.autoNumFramesBgCheckbox.ValueChangedFcn = createCallbackFcn(app, @autoFrameSelectionCheckboxValueChanged, true);
            app.autoNumFramesBgCheckbox.Tag = 'bgNF';
            app.autoNumFramesBgCheckbox.Text = '';
            app.autoNumFramesBgCheckbox.Value = true;
            app.autoStartFrameGreenCheckbox = uicheckbox(app.FramenumbersTab);
            app.autoStartFrameGreenCheckbox.ValueChangedFcn = createCallbackFcn(app, @autoFrameSelectionCheckboxValueChanged, true);
            app.autoStartFrameGreenCheckbox.Tag = 'greenStF';
            app.autoStartFrameGreenCheckbox.Text = '';
            app.autoStartFrameGreenCheckbox.Value = true;
            app.autoNumFramesGreenCheckbox = uicheckbox(app.FramenumbersTab);
            app.autoNumFramesGreenCheckbox.ValueChangedFcn = createCallbackFcn(app, @autoFrameSelectionCheckboxValueChanged, true);
            app.autoNumFramesGreenCheckbox.Tag = 'greenNF';
            app.autoNumFramesGreenCheckbox.Text = '';
            app.autoNumFramesGreenCheckbox.Value = true;
            app.AutoLabel = uilabel(app.FramenumbersTab);
            app.AutoLabel.Text = {'Checkboxes mean values are set to default.'; 'Left Box is start frame.'; 'Right Box is num frames to average.'};
            app.StartFrameForWhiteLightBg = uieditfield(app.FramenumbersTab, 'numeric');
            app.NumFramesToAvgForWhiteLightBg = uispinner(app.FramenumbersTab);
            app.NumFramesToAvgForWhiteLightBg.RoundFractionalValues = 'on';
            app.NumFramesToAvgForWhiteLightBg.Value = 10;
            app.SpotfindingBackgroundLabel = uilabel(app.FramenumbersTab);
            app.SpotfindingBackgroundLabel.Text = {'Spotfinding'; 'Background:'};
            app.GreenLaserBackgroundLabel = uilabel(app.FramenumbersTab);
            app.GreenLaserBackgroundLabel.Text = {'Green Laser'; 'Background:'};
            app.StartFrameLabel = uilabel(app.FramenumbersTab);
            app.StartFrameLabel.Text = {'Start'; 'Frame:'};
            app.NumberofFramestoAverageLabel = uilabel(app.FramenumbersTab);
            app.NumberofFramestoAverageLabel.Text = {'Number of '; 'Frames to '; 'Average:'};
            app.WhiteLightBackgroundLabel = uilabel(app.FramenumbersTab);
            app.WhiteLightBackgroundLabel.Text = {'White Light'; 'Background:'};
            app.autoNumFramesToAvgWhiteLightBg = uicheckbox(app.FramenumbersTab);
            app.autoNumFramesToAvgWhiteLightBg.ValueChangedFcn = createCallbackFcn(app, @autoFrameSelectionCheckboxValueChanged, true);
            app.autoNumFramesToAvgWhiteLightBg.Tag = 'whiteNF';
            app.autoNumFramesToAvgWhiteLightBg.Text = '';
            app.autoNumFramesToAvgWhiteLightBg.Value = true;
            app.autoStartFrameForWhiteLightBg = uicheckbox(app.FramenumbersTab);
            app.autoStartFrameForWhiteLightBg.ValueChangedFcn = createCallbackFcn(app, @autoFrameSelectionCheckboxValueChanged, true);
            app.autoStartFrameForWhiteLightBg.Tag = 'whiteStF';
            app.autoStartFrameForWhiteLightBg.Text = '';
            app.autoStartFrameForWhiteLightBg.Value = true;
            app.MappingTab = uitab(app.AdvancedSettingsTabGroup2);
            app.MappingTab.Title = 'Mapping';
            app.CreatemappingbyselectingpointstouseLabel = uilabel(app.MappingTab);
            app.CreatemappingbyselectingpointstouseLabel.Text = {'Create mapping by'; 'selecting points to use'};
            app.SelectPointsButton = uibutton(app.MappingTab, 'state');
            app.SelectPointsButton.ValueChangedFcn = createCallbackFcn(app, @SelectPointsButtonValueChanged, true);
            app.SelectPointsButton.Text = 'Select Points';
            app.RachelSpots = uitab(app.AnalyzeTabGroup);
            app.RachelSpots.Title = 'Tab';
            app.GridLayout = uigridlayout(app.RachelSpots);
            app.RachelSpotPanel = uipanel(app.GridLayout);
            app.Panel = uipanel(app.GridLayout);
            app.InstructionsForRoi = uitextarea(app.Panel);
            app.InstructionsForRoi.Value = {'Recomended: zoom in to area with biggest spot'; ''; 'Click button to draw rectangle around spot'};
            app.maxEditFieldLabel = uilabel(app.Panel);
            app.maxEditFieldLabel.Text = 'max';
            app.maxEditField = uieditfield(app.Panel, 'numeric');
            app.maxEditField.Value = Inf;
            app.minEditFieldLabel = uilabel(app.Panel);
            app.minEditFieldLabel.Text = 'min';
            app.minEditField = uieditfield(app.Panel, 'numeric');
            app.BigSpotIntensityEditField = uieditfield(app.GridLayout, 'numeric');
            app.roiaroundbiggestspotButton = uibutton(app.GridLayout, 'push');
            app.roiaroundbiggestspotButton.ButtonPushedFcn = createCallbackFcn(app, @roiaroundbiggestspotButtonPushed, true);
            app.roiaroundbiggestspotButton.Text = 'roi around biggest spot';
            app.InfoAndMappingPanel = uipanel(app.GuiForPrimeGridLayout);
            app.UseCurrentMappingCheckBox = uicheckbox(app.InfoAndMappingPanel);
            app.UseCurrentMappingCheckBox.Text = 'Use Current Mapping';
            app.InfoTextArea = uitextarea(app.InfoAndMappingPanel);
            app.InfoTextArea.Editable = 'off';
            app.SaveMappingButton = uibutton(app.InfoAndMappingPanel, 'push');
            app.SaveMappingButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMappingButtonPushed, true);
            app.SaveMappingButton.Enable = 'off';
            app.SaveMappingButton.Text = 'Save Mapping';
            app.LoadLastMappingButton = uibutton(app.InfoAndMappingPanel, 'push');
            app.LoadLastMappingButton.ButtonPushedFcn = createCallbackFcn(app, @LoadLastMappingButtonPushed, true);
            app.LoadLastMappingButton.Enable = 'off';
            app.LoadLastMappingButton.Text = 'Load Last Mapping';
            app.moviePanel_outer = uipanel(app.GuiForPrimeGridLayout);
            app.moviePanel = uipanel(app.moviePanel_outer);
            app.TabGroup = uitabgroup(app.GuiForPrimeGridLayout);
            app.PlaybackSettingsTab = uitab(app.TabGroup);
            app.PlaybackSettingsTab.Title = 'Playback Settings';
            app.PlayMovieButton = uibutton(app.PlaybackSettingsTab, 'state');
            app.PlayMovieButton.ValueChangedFcn = createCallbackFcn(app, @PlayMovieButtonValueChanged, true);
            app.PlayMovieButton.Text = 'Play Movie';
            app.PlaybackSpeedCheckBox = uicheckbox(app.PlaybackSettingsTab);
            app.PlaybackSpeedCheckBox.Text = '';
            app.PlaybackSpeedsframeLabel = uilabel(app.PlaybackSettingsTab);
            app.PlaybackSpeedsframeLabel.Text = 'Playback Speed (s/frame)';
            app.PlaybackSpeedEditField = uieditfield(app.PlaybackSettingsTab, 'numeric');
            app.PlaybackSpeedEditField.Limits = [0 Inf];
            app.PlaybackSpeedEditField.ValueDisplayFormat = '%1.2g';
            app.PlayonLoopCheckBox = uicheckbox(app.PlaybackSettingsTab);
            app.PlayonLoopCheckBox.Text = 'Play on Loop:';
            app.StartEditFieldLabel = uilabel(app.PlaybackSettingsTab);
            app.StartEditFieldLabel.Text = 'Start';
            app.playbackStartFrame = uieditfield(app.PlaybackSettingsTab, 'numeric');
            app.EndEditFieldLabel = uilabel(app.PlaybackSettingsTab);
            app.EndEditFieldLabel.Text = 'End';
            app.playbackEndFrame = uieditfield(app.PlaybackSettingsTab, 'numeric');
            app.ContrastSettingsTab = uitab(app.TabGroup);
            app.ContrastSettingsTab.Title = 'Contrast Settings';
            app.ToAdjustContrastclickhighorlowbuttonscrollwithmousewheelLabel = uilabel(app.ContrastSettingsTab);
            app.ToAdjustContrastclickhighorlowbuttonscrollwithmousewheelLabel.Text = {'click high or low button '; '& scroll with mouse wheel'; 'to adjust contrast.'};
            app.ResetButton = uibutton(app.ContrastSettingsTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SensitivitySpinnerLabel = uilabel(app.ContrastSettingsTab);
            app.SensitivitySpinnerLabel.Text = 'Sensitivity:';
            app.SensitivitySpinner = uispinner(app.ContrastSettingsTab);
            app.SensitivitySpinner.Step = 100;
            app.SensitivitySpinner.Limits = [0 Inf];
            app.SensitivitySpinner.Value = 500;
            app.HighContrastButton = uibutton(app.ContrastSettingsTab, 'state');
            app.HighContrastButton.ValueChangedFcn = createCallbackFcn(app, @ContrastButtonValueChanged, true);
            app.HighContrastButton.Text = 'High Contrast';
            app.LowContrastButton = uibutton(app.ContrastSettingsTab, 'state');
            app.LowContrastButton.ValueChangedFcn = createCallbackFcn(app, @ContrastButtonValueChanged, true);
            app.LowContrastButton.Text = 'Low Contrast';
            app.LeftButton = uibutton(app.ContrastSettingsTab, 'state');
            app.LeftButton.ValueChangedFcn = createCallbackFcn(app, @ContrastButtonValueChanged, true);
            app.LeftButton.Text = 'Left';
            app.LeftButton.Value = true;
            app.RightButton = uibutton(app.ContrastSettingsTab, 'state');
            app.RightButton.ValueChangedFcn = createCallbackFcn(app, @ContrastButtonValueChanged, true);
            app.RightButton.Text = 'Right';
            app.SaveContrastSettingsButton = uibutton(app.ContrastSettingsTab, 'push');
            app.SaveContrastSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveContrastSettingsButtonPushed, true);
            app.SaveContrastSettingsButton.Enable = 'off';
            app.SaveContrastSettingsButton.Text = 'Save Contrast Settings';
            app.LoadContrastSettingsButton = uibutton(app.ContrastSettingsTab, 'push');
            app.LoadContrastSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadContrastSettingsButtonPushed, true);
            app.LoadContrastSettingsButton.Enable = 'off';
            app.LoadContrastSettingsButton.Text = 'Load Contrast Settings';
            app.ContrastTextArea = uitextarea(app.ContrastSettingsTab);
            app.WhiteFramesCheckBox = uicheckbox(app.ContrastSettingsTab);
            app.WhiteFramesCheckBox.Tag = 'livecell';
            app.WhiteFramesCheckBox.Enable = 'off';
            app.WhiteFramesCheckBox.Text = 'WhiteFrames';
            app.GreenFramesCheckBox = uicheckbox(app.ContrastSettingsTab);
            app.GreenFramesCheckBox.Tag = 'livecell';
            app.GreenFramesCheckBox.Enable = 'off';
            app.GreenFramesCheckBox.Text = 'GreenFrames';
            app.OtherViewSettingsTab = uitab(app.TabGroup);
            app.OtherViewSettingsTab.Title = 'Other View Settings';
            app.ImageViewLabel = uilabel(app.OtherViewSettingsTab);
            app.ImageViewLabel.Text = 'Image View';
            app.DropDown_imagedisplay = uidropdown(app.OtherViewSettingsTab);
            app.DropDown_imagedisplay.Items = {'Original', 'Background', 'Subtract Background', ''};
            app.DropDown_imagedisplay.ValueChangedFcn = createCallbackFcn(app, @DropDown_imagedisplayValueChanged, true);
            app.DropDown_imagedisplay.Value = 'Original';
            app.OverlayImagesButton = uibutton(app.OtherViewSettingsTab, 'state');
            app.OverlayImagesButton.Enable = 'off';
            app.OverlayImagesButton.Text = {'Overlay Images'; ''};
            app.SmileButton = uibutton(app.OtherViewSettingsTab, 'push');
            app.SmileButton.ButtonPushedFcn = createCallbackFcn(app, @SmileButtonPushed, true);
            app.SmileButton.Enable = 'off';
            app.SmileButton.Text = 'Smile';
            app.TransparencySpinnerLabel = uilabel(app.OtherViewSettingsTab);
            app.TransparencySpinnerLabel.Enable = 'off';
            app.TransparencySpinnerLabel.Text = 'Transparency';
            app.TransparencySpinner = uispinner(app.OtherViewSettingsTab);
            app.TransparencySpinner.Step = 0.1;
            app.TransparencySpinner.Limits = [0 1];
            app.TransparencySpinner.Enable = 'off';
            app.TransparencySpinner.Value = 0.4;
            app.OnlykeepspotsinsidecellsCheckBox = uicheckbox(app.OtherViewSettingsTab);
            app.OnlykeepspotsinsidecellsCheckBox.Tag = 'livecell';
            app.OnlykeepspotsinsidecellsCheckBox.Enable = 'off';
            app.OnlykeepspotsinsidecellsCheckBox.Text = 'Only keep spots inside cells';
            app.FindSpotsUsingAllFramesCheckBox = uicheckbox(app.OtherViewSettingsTab);
            app.FindSpotsUsingAllFramesCheckBox.Tag = 'livecell';
            app.FindSpotsUsingAllFramesCheckBox.Enable = 'off';
            app.FindSpotsUsingAllFramesCheckBox.Text = 'Find Spots Using All Frames';
            app.NumPixelsAwayFromSpotToSubtractCellBackgroundLabel = uilabel(app.OtherViewSettingsTab);
            app.NumPixelsAwayFromSpotToSubtractCellBackgroundLabel.Tag = 'livecell';
            app.NumPixelsAwayFromSpotToSubtractCellBackgroundLabel.Enable = 'off';
            app.NumPixelsAwayFromSpotToSubtractCellBackgroundLabel.Text = {'Num Pixels Away From Spot To '; 'Subtract Cell Background'};
            app.NumPixelsAwayFromSpotToSubtractCellBackgroundSpinner = uispinner(app.OtherViewSettingsTab);
            app.NumPixelsAwayFromSpotToSubtractCellBackgroundSpinner.Limits = [1 100];
            app.NumPixelsAwayFromSpotToSubtractCellBackgroundSpinner.RoundFractionalValues = 'on';
            app.NumPixelsAwayFromSpotToSubtractCellBackgroundSpinner.Tag = 'livecell';
            app.NumPixelsAwayFromSpotToSubtractCellBackgroundSpinner.Enable = 'off';
            app.NumPixelsAwayFromSpotToSubtractCellBackgroundSpinner.Value = 1;
            app.CheckforDoubleFramesButton = uibutton(app.OtherViewSettingsTab, 'push');
            app.CheckforDoubleFramesButton.ButtonPushedFcn = createCallbackFcn(app, @CheckforDoubleFramesButtonPushed, true);
            app.CheckforDoubleFramesButton.Text = 'Check for Double Frames';
            app.MaxTravelDistanceSpinnerLabel = uilabel(app.OtherViewSettingsTab);
            app.MaxTravelDistanceSpinnerLabel.Tag = 'livecell';
            app.MaxTravelDistanceSpinnerLabel.Enable = 'off';
            app.MaxTravelDistanceSpinnerLabel.Text = 'Max Travel Distance';
            app.MaxTravelDistanceSpinner = uispinner(app.OtherViewSettingsTab);
            app.MaxTravelDistanceSpinner.Tag = 'livecell';
            app.MaxTravelDistanceSpinner.Enable = 'off';
            app.MaxTravelDistanceSpinner.Value = 10;
            app.FramestouseforSpotfindingLabel = uilabel(app.OtherViewSettingsTab);
            app.FramestouseforSpotfindingLabel.Tag = 'rwg';
            app.FramestouseforSpotfindingLabel.Enable = 'off';
            app.FramestouseforSpotfindingLabel.Text = 'Frames to use for Spotfinding:';
            app.GreenCheckBox = uicheckbox(app.OtherViewSettingsTab);
            app.GreenCheckBox.ValueChangedFcn = createCallbackFcn(app, @spotfindingFramesRWGmodeCheckboxesChanged, true);
            app.GreenCheckBox.Tag = 'rwg';
            app.GreenCheckBox.Enable = 'off';
            app.GreenCheckBox.Text = 'Green';
            app.RedCheckBox = uicheckbox(app.OtherViewSettingsTab);
            app.RedCheckBox.ValueChangedFcn = createCallbackFcn(app, @spotfindingFramesRWGmodeCheckboxesChanged, true);
            app.RedCheckBox.Tag = 'rwg';
            app.RedCheckBox.Enable = 'off';
            app.RedCheckBox.Text = 'Red';
            app.RedCheckBox.Value = true;
            app.WhiteCheckBox = uicheckbox(app.OtherViewSettingsTab);
            app.WhiteCheckBox.ValueChangedFcn = createCallbackFcn(app, @spotfindingFramesRWGmodeCheckboxesChanged, true);
            app.WhiteCheckBox.Tag = 'rwg';
            app.WhiteCheckBox.Enable = 'off';
            app.WhiteCheckBox.Text = 'White';
            app.FrameNumberEditField = uieditfield(app.GuiForPrimeGridLayout, 'text');
            app.PixelInfoRightside = uieditfield(app.GuiForPrimeGridLayout, 'text');
            app.PixelInfoLeftside = uieditfield(app.GuiForPrimeGridLayout, 'text');
            app.InstructionsTextArea = uieditfield(app.GuiForPrimeGridLayout, 'text');
            app.GridLayout2 = uigridlayout(app.GuiForPrimeGridLayout);
            app.Frameslider = uislider(app.GridLayout2);
            app.Frameslider.ValueChangedFcn = createCallbackFcn(app, @FramesliderValueChanged, true);
            app.Frameslider.ValueChangingFcn = createCallbackFcn(app, @FramesliderValueChanging, true);
            app.BackwardButton = uibutton(app.GridLayout2, 'push');
            app.BackwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.BackwardButton.Tag = '<<';
            app.BackwardButton.Text = '<<';
            app.ForwardButton = uibutton(app.GridLayout2, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Tag = '>>';
            app.ForwardButton.Text = '>>';
            % Show the figure after all components are created
            app.guiForPrimeUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = guiForPrime

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.guiForPrimeUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.guiForPrimeUIFigure)
        end
    end
end